classdef Element_Formulation_Max13_4_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Spinner_3                       matlab.ui.control.Spinner
        WhatpropertyyouwanttoplotDropDown_2  matlab.ui.control.DropDown
        Compatible_Cq                   matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        RefreshButton                   matlab.ui.control.Button
        zDropDown                       matlab.ui.control.DropDown
        yDropDown                       matlab.ui.control.DropDown
        ParametricaxiscolorDropDown     matlab.ui.control.DropDown
        ParametricaxiscolorDropDownLabel  matlab.ui.control.Label
        ParametriccoordinatesCheckBox   matlab.ui.control.CheckBox
        WeightsCheckBox_2               matlab.ui.control.CheckBox
        SurfaceIntegrationpointsCheckBox  matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        FontsizeSpinner                 matlab.ui.control.Spinner
        FontsizeSpinnerLabel            matlab.ui.control.Label
        FillSpinner                     matlab.ui.control.Spinner
        FillSpinnerLabel                matlab.ui.control.Label
        PeelsSpinner                    matlab.ui.control.Spinner
        PeelsSpinnerLabel               matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        AxisCheckBox                    matlab.ui.control.CheckBox
        CommandWindowLabel              matlab.ui.control.Label
        EditField_9                     matlab.ui.control.EditField
        ViewButton                      matlab.ui.control.Button
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        BuildFunctionFileCheckBox       matlab.ui.control.CheckBox
        DerivativesofShapematrixCheckBox  matlab.ui.control.CheckBox
        ShapeMatrixCheckBox             matlab.ui.control.CheckBox
        SavedataButton                  matlab.ui.control.Button
        FundedbytheNationalAeronauticsandSpaceAdministrationLabel  matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Spinner                         matlab.ui.control.Spinner
        GetfigureButton                 matlab.ui.control.StateButton
        WeightsCheckBox                 matlab.ui.control.CheckBox
        IntegrationpointsCheckBox       matlab.ui.control.CheckBox
        NodesCheckBox                   matlab.ui.control.CheckBox
        NodeNumbersCheckBox             matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        WhatpropertyyouwanttoplotDropDown  matlab.ui.control.DropDown
        EdgesCheckBox                   matlab.ui.control.CheckBox
        ShapeGen3DLabel                 matlab.ui.control.Label
        ChoiceofelementButtonGroup      matlab.ui.container.ButtonGroup
        ToleranceEditField_2            matlab.ui.control.NumericEditField
        RotateDegButton                 matlab.ui.control.StateButton
        SurfacenoofnewelementSpinner    matlab.ui.control.Spinner
        SurfacenoofnewelementSpinnerLabel  matlab.ui.control.Label
        SurfacenoofpreviouselementSpinner  matlab.ui.control.Spinner
        SurfacenoofpreviouselementSpinnerLabel  matlab.ui.control.Label
        ToleranceLabel                  matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        DeleteButton_2                  matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        ImportelementDropDown           matlab.ui.control.DropDown
        CalculatederivetivesCheckBox    matlab.ui.control.CheckBox
        NewelementLabel                 matlab.ui.control.Label
        PreviouselementLabel            matlab.ui.control.Label
        Spinner_2                       matlab.ui.control.Spinner
        TextArea                        matlab.ui.control.TextArea
        CheckcompatibilityButton        matlab.ui.control.Button
        DonotguessCheckBox              matlab.ui.control.CheckBox
        GenerateshapefunctionsButton    matlab.ui.control.Button
        IntegrationquadratureLabel      matlab.ui.control.Label
        IntegrationquadratureDropDown   matlab.ui.control.DropDown
        IntegrationorderforreferencetetrahedronLabel  matlab.ui.control.Label
        IntegrationorderSpinner         matlab.ui.control.Spinner
        AutofillButton                  matlab.ui.control.Button
        InputInterpolationfunctionLabel  matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        CheckButton                     matlab.ui.control.Button
        InputNodecoordinatesLabel       matlab.ui.control.Label
        ImportElementButton             matlab.ui.control.Button
        Order_Limit_Label               matlab.ui.control.Label
        OrderLimitSpinner_2             matlab.ui.control.Spinner
        NumberofNodesSpinnerLabel       matlab.ui.control.Label
        NumberofNodesSpinner            matlab.ui.control.Spinner
        NumberofSubTetrahedronsLabel    matlab.ui.control.Label
        SubTetrahedralsDropDown         matlab.ui.control.DropDown
        Element_Order                   matlab.ui.control.Slider
        LegendreGaussLobattonodesCheckBox  matlab.ui.control.CheckBox
        ElementorderLabel               matlab.ui.control.Label
        WritetheNodecoordinatesLabel_2  matlab.ui.control.Label
        WritethefunctionyouwanttoplotLabel  matlab.ui.control.Label
        nodeexampleButton               matlab.ui.control.Button
        LagrangianFamilyCheckBox        matlab.ui.control.CheckBox
        PolynomialDegreealongzLabel     matlab.ui.control.Label
        PolynomialDegreealongyLabel     matlab.ui.control.Label
        PolynomialDegreealongxLabel     matlab.ui.control.Label
        PolynomialorderalongzSlider     matlab.ui.control.Slider
        PolynomialorderalongySlider     matlab.ui.control.Slider
        PolynomialorderalongxSlider     matlab.ui.control.Slider
        TetrahedronelementButton        matlab.ui.control.RadioButton
        CheckinterelementcompatibilityButton  matlab.ui.control.RadioButton
        ArbitraryorCustomelementButton  matlab.ui.control.RadioButton
        HexahedronorBrickelementButton  matlab.ui.control.RadioButton
        EditField_5                     matlab.ui.control.EditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        EditField_6                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        N_C
        u_C
        P_C 
        P_C_Surf
        Surf_Nodes

        N_C_2
        P_C_2
        P_C_Surf_2
        V_CP_Sequence_S_2
        Surf_Nodes_2
        Type_C_2
        Sx_C_2
        Sy_C_2
        Sz_C_2
        SGP2
        N_C_2_Map
        P_C_2_Map
        
        hSlice2

        
        dNbydx
        dNbydy
        dNbydz
        
        d2Nbydx2
        d2Nbydy2
        d2Nbydz2
        d2Nbydz2C
        d2Nbydz2C2
        
        X_Order
        Y_Order
        Z_Order

        Order_Local
        
        SFm_C
        Peels_C
        SFm_C_2
        
        INTP
        W
        W_L
        INTP_Surf
        W_Surf
        gp_per_Surfs
        V_CP_Sequence_S
        INTP_Surf_2
        gp_per_Surfs_2
        
        Dom_C
        Type_C
        Sx_C
        Sy_C
        Sz_C
        hIsosurf
        hSlice
        SNP
        SGP
        SGPS
        SQUIV
        Texts
        Texts2
        Texts3
        
        SNP1
        SNP2
        Tri1
        Tri2
        Tri1_1
        Tri2_1

        Threshold=0.01;
        u_Coeff
        Der_Order
        Th=0;
        
        
       Node_Set_20N=[	-1	-1	-1
                                    	1	-1	-1
                                    	1	1	-1
                                    	-1	1	-1
                                    	-1	-1	1
                                    	1	-1	1
                                    	1	1	1
                                    	-1	1	1
                                    	0	-1	-1
                                    	1	0	-1
                                    	0	1	-1
                                    	-1	0	-1
                                    	0	-1	1
                                    	1	0	1
                                    	0	1	1
                                    	-1	0	1
                                    	-1	-1	0
                                    	1	-1	0
                                    	1	1	0
                                    	-1	1	0];
       
       
       Node_Set_20N_x1= [2 10 3 19 7 14 6 18];
       Node_Set_20N_xM1=[1 17 5 16 8 20 4 12];
       Node_Set_20N_y1= [3 11 4 20 8 15 7 19];
       Node_Set_20N_yM1=[1 9  2 18 6 13 5 17];
       Node_Set_20N_z1= [5 13 6 14 7 15 8 16];
       Node_Set_20N_zM1=[1 9  2 10 3 11 4 12];


    end
    
    methods (Access = private)


        function Quote(app)
            
            Choose=randi([1 25],1);
            
            if Choose==1 || Choose==2
            S=sprintf('"Engineering is the closest thing to magic that exists in the world." -Elon Musk');
            elseif Choose==3 || Choose==4
            S=sprintf('"The good thing about science is that its true whether or not you believe in it" -Neil deGrasse Tyson');
            elseif Choose==5           
            S=sprintf('"If you cant beat them. join them" -Jim Henson');
            elseif Choose==6 || Choose==7
            S=sprintf('"We all die. The goal is not to live forever, the goal is to create something that will." -Chuck palahniuk');
            elseif Choose==8 || Choose==9 
            S=sprintf('"History repeats but science reverberates" -Siddhartha Mukherjee');
            elseif Choose==10 || Choose==11
            S=sprintf('"When you die, you cant see sunsets" -Hayao Miyazaki');    
            elseif Choose==12 || Choose==13
            S=sprintf('"Pay yourself first!" -Robert Kiosaki');
            elseif Choose==14 || Choose==15
            S=sprintf('"There is a clock in your body that can be reset" -David Sinclair');    
            elseif Choose==16 || Choose==17
            S=sprintf('"Without data you are just another person with an opinion" -W. Edwards Deming');  
            elseif Choose==18 || Choose==19
            S=sprintf('"Be thankful for what you have; you will end up having more" -Oprah Winfrey');
            elseif Choose==20
            S=sprintf('"You have to think anyway, so why not think big?" -Donald Trump'); 
            elseif Choose==21
            S=sprintf('"We did not come to fear the future. We came here to shape it" -Barak Obama');
            elseif Choose==22 || Choose==23
            S=sprintf('"You are not perfect just the way you are. You could be better!" -Jordan B.Peterson');
            elseif Choose==24 || Choose==25
            S=sprintf('"If you will live like no one else, later you can live like no one else"-Dave Ramsey');
            elseif Choose==26
            S=sprintf('"Look for data, not opinion" - Adnan Shahriar. I also want to say one more thing: "CITE"!');
            elseif Choose==27
            S=sprintf('"Always be prepared to change yourself if evidence unfolds" - Adnan Shahriar. Hope you are enjoying the app!');
            elseif Choose==28
            S=sprintf('"Go back to 6 years old, be curious, make mistakes, and never grow up again!" - Adnan Shahriar. How did the Quote go?');    
            end
            
            app.TextArea.Value{10}=S;


        end
        
        
        function Function_Analyzer_Order_Getter(app)
            
            x=sym('x');
            y=sym('y');
            z=sym('z');
            
            u=app.u_C;
            
            ux=subs(u,[y z],[1 1]);
            uy=subs(u,[x z],[1 1]);
            uz=subs(u,[x y],[1 1]);
            
            %
            Identifier=1;
            c=0;
            while Identifier>0
                app.X_Order=c;
                
                Identifier=0;
                for i=1:length(ux)
                    if subs(ux(i),x,0.001)<0.05
                        Identifier=1;
                    end
                end


                % Identifier=sum(find(subs(ux,x,2)>2));
                ux=ux./x;
                c=c+1;
            end
            
            
            %
            Identifier=1;
            c=0;
            while Identifier>0
                app.Y_Order=c;
                
                Identifier=0;
                for i=1:length(uy)
                    if subs(uy(i),y,0.001)<0.05
                        Identifier=1;
                    end
                end               
                
                % Identifier=sum(find(subs(uy,y,2)>2));
                uy=uy./y;
                c=c+1;
            end
            
            
            Identifier=1;
            c=0;
            while Identifier>0
                app.Z_Order=c;
                
                
                Identifier=0;
                for i=1:length(uz)
                    if subs(uz(i),z,0.001)<0.05
                        Identifier=1;
                    end
                end
                
                % Identifier=sum(find(subs(uz,z,2)>2));
                uz=uz./z;
                c=c+1;
            end
            
            
        end
        %------------------------------------------------------------------------------
        function Integration_Point_Generator_Both(app)
            
            
            N=app.N_C;
            P=app.P_C;
            SFm=app.SFm_C;
            Peels=app.Peels_C;
            no=Peels;
            
            app.INTP=0;
            app.W=0;
            

                %% Surface integration points
                % Triangulate
                DTO = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                DT = DTO;
                CL=DT.ConnectivityList;
                CL=unique(CL(:,1:4),'rows');
                % DT = DTO;
                Boundary = convexHull(DT);
                % size(Boundary,1)
                % P=app.P_C;

                Area=zeros(size(Boundary,1),1);
                Centroid=zeros(size(Boundary,1),3);
                Group=zeros(7,size(Boundary,1));
                VoS=zeros(size(Boundary,1),3);
                P_C_Surface=zeros(size(Boundary,1)*3,4);
                V_CP_Sequence=zeros(3,2,7);


                V_CP_Sequence(1:3,1:2,1)=[0 1 0;0 0 1]';
                V_CP_Sequence(1:3,1:2,2)=[0 0 1;0 1 0]';
                V_CP_Sequence(1:3,1:2,3)=[0 0 1;1 0 0]';
                V_CP_Sequence(1:3,1:2,4)=[1 0 0;0 0 1]';
                V_CP_Sequence(1:3,1:2,5)=[1 0 0;0 1 0]';
                V_CP_Sequence(1:3,1:2,6)=[0 1 0;1 0 0]';
                V_CP_Sequence(1:3,1:2,7)=[-0.8165    0.4082    0.4082; 0   -0.7071    0.7071]';
                V_CP_Sequence(1:3,1:2,8)=[-1/sqrt(2) 1/sqrt(2) 0; 0 0 1]';

                % V_CP_Sequence(1:3,1:2,1)=[]

                %CP_seq=[3    2     1    3   2   1
                %        2    3     3    1   1   2];

                cxP=1;cyP=1;czP=1;
                cxN=1;cyN=1;czN=1;
                cxyz=1;
                cxy=1;

                cxyz2=9;

                for i=1:size(Boundary,1)
                   
                    a=[P(Boundary(i,1),1) P(Boundary(i,1),2) P(Boundary(i,1),3)];
                    b=[P(Boundary(i,2),1) P(Boundary(i,2),2) P(Boundary(i,2),3)];
                    c=[P(Boundary(i,3),1) P(Boundary(i,3),2) P(Boundary(i,3),3)];

                    an=Boundary(i,1);
                    bn=Boundary(i,2);
                    cn=Boundary(i,3);

                    V1=[b-a];
                    V2=[c-a];

                    Vo=cross(V1,V2);
                    V=Vo/norm(Vo);

                    VTet=[1 1 1]/norm([1 1 1]);
                    VWed=[1 1 0]/norm([1 1 0]);


                    if ([1 0 0]*V')==1
                        Group(1,cxP)=i;
                        cxP=cxP+1;
                    elseif ([0 1 0]*V')==1
                        Group(3,cyP)=i;
                        cyP=cyP+1;
                    elseif ([0 0 1]*V')==1
                        Group(5,czP)=i;
                        czP=czP+1;
                    elseif ([1 0 0]*V')==-1
                        Group(2,cxN)=i;
                        cxN=cxN+1;
                    elseif ([0 1 0]*V')==-1
                        Group(4,cyN)=i;
                        cyN=cyN+1;
                    elseif ([0 0 1]*V')==-1
                        Group(6,czN)=i;
                        czN=czN+1;
                    elseif abs(VTet*V'-1)<10^-5
                         Group(7,cxyz)=i;
                         % V_CP_Sequence(1:3,1:2,7)=[V1'/norm(V1) V2'/norm(V2)];
                         cxyz=cxyz+1;
                    elseif abs(VWed*V'-1)<10^-5
                         Group(8,cxy)=i;
                         % V_CP_Sequence(1:3,1:2,8)=[V1'/norm(V1) V2'/norm(V2)];
                         cxy=cxy+1;
                    else
                        Group(cxyz2,1)=i;
                        V22=cross(V1/norm(V1),Vo);
                        V_CP_Sequence(1:3,1:2,cxyz2)=[V22' V1'/norm(V1)];
                        cxyz2=cxyz2+1;
                    end

                    P_C_Surface(3*i-2,1:4)=[an a];
                    P_C_Surface(3*i-1,1:4)=[bn b];
                    P_C_Surface(3*i-0,1:4)=[cn c];

                    VoS(i,1:3)=V;
                    Area(i)=norm(Vo)/2;
                    Centroid(i,1:3)=[a+b+c]/3;

                end   

                % V_CP_Sequence

                INTP_LS=zeros(size(Boundary,1),7);
                W_LS=zeros(size(Boundary,1),1);
                gp_Surfs=zeros(1,cxyz2-1);
                P_C_Surface_S=zeros(size(Boundary,1)*3,8);
                count=1;
                count2=1;
                for i=1:size(Group,1)
                    if Group(i,1)>0
                        countL=1;
                        for j=1:size(Group,2)
                            if Group(i,j)>0
                                Ip_No=Group(i,j);
                                P_C_Surface_S(3*count-2,1:8)=[P_C_Surface(3*Ip_No-2,1:4) count2 VoS(Ip_No,:)];
                                P_C_Surface_S(3*count-1,1:8)=[P_C_Surface(3*Ip_No-1,1:4) count2 VoS(Ip_No,:)];
                                P_C_Surface_S(3*count-0,1:8)=[P_C_Surface(3*Ip_No-0,1:4) count2 VoS(Ip_No,:)];

                                INTP_LS(count,1:7)=[Centroid(Ip_No,:) count2 VoS(Ip_No,:)];
                                W_LS(count,1)=Area(Ip_No,:);
                                count=count+1;
                                countL=countL+1;
                            end
                        end
                        gp_Surfs(count2)=countL-1;
                    end
                    count2=count2+1;
                end
                
            % INTP_LS
            app.P_C_Surf=P_C_Surface_S;
            app.INTP_Surf=INTP_LS;
            app.W_Surf=W_LS;
            app.gp_per_Surfs=gp_Surfs;     
            app.V_CP_Sequence_S=V_CP_Sequence;
            
            %% Check what type of Element
            if  app.Type_C=='B' 
            
                 if app.IntegrationquadratureDropDown.Value=='Gauss  Lobatto'
                    [app.INTP,app.W]=Integration_Point_Generator_Lobatto(app);
                    app.TextArea.Value{8}=sprintf('Number of Integration points based on Gauss–Lobatto quadrature=%d',size(app.INTP,1));
                 elseif app.IntegrationquadratureDropDown.Value=='Gauss Legendre'
                    [app.INTP,app.W]=Integration_Point_Generator(app);
                    app.TextArea.Value{8}=sprintf('Number of Integration points based on Gauss–Legendre quadrature=%d',size(app.INTP,1));
                 end


            else % If Element Geometry is not standard, Use triangulation
                

                Dom=size(CL,1);
                
                if app.TetrahedronelementButton.Value==1 && app.DonotguessCheckBox.Value==0 && app.Spinner_2.Value<5
                    
                    app.IntegrationorderSpinner.Value=app.Spinner_2.Value;
                    
                    P=[0 0 0; 1 0 0; 0 1 0; 0 0 1];
                    DT = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                    CL=DT.ConnectivityList; 
                
                    Dom=size(CL,1);
                elseif app.TetrahedronelementButton.Value==1 && app.DonotguessCheckBox.Value==0 && app.Spinner_2.Value>4
                    
                    if app.SubTetrahedralsDropDown.Value=='08'

                     P=[0	0	0
                        0	0.500000000000000	0
                        0	1	0
                        0.500000000000000	0	0
                        0.500000000000000	0.500000000000000	0
                        1	0	0
                        0	0	0.500000000000000
                        0	0	1
                        0	0.500000000000000	0.500000000000000
                        0.500000000000000	0	0.500000000000000];

                    
                    elseif app.SubTetrahedralsDropDown.Value=='64'
                            P=[      0         0         0
                                     0         0    0.2500
                                     0         0    0.5000
                                     0         0    0.7500
                                     0         0    1.0000
                                     0    0.2500         0
                                     0    0.2500    0.2500
                                     0    0.2500    0.5000
                                     0    0.2500    0.7500
                                     0    0.5000         0
                                     0    0.5000    0.2500
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                                1.0000         0         0];
                        
                        
                    else
                        P=[0 0 0; 1 0 0; 0 1 0; 0 0 1];
                    end
                    

                    DT = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                    CL=DT.ConnectivityList;                     
                    CL=unique(CL(:,1:4),'rows'); 

                end

                
                    %% Correction
                    CL_P=CL;
                    CL=zeros(1,4);
                    V=CL_P(:,1)*0;
                    for i=1:size(CL_P,1)
                       
                        a=[P(CL_P(i,1),1) P(CL_P(i,1),2) P(CL_P(i,1),3)];
                        b=[P(CL_P(i,2),1) P(CL_P(i,2),2) P(CL_P(i,2),3)];
                        c=[P(CL_P(i,3),1) P(CL_P(i,3),2) P(CL_P(i,3),3)];
                        d=[P(CL_P(i,4),1) P(CL_P(i,4),2) P(CL_P(i,4),3)];
                        
                        V(i)=abs((a-d)*(cross((b-d),(c-d)))');
                        
                    end
                    
                    VM=mean(V);
                    count=1;
                    for i=1:length(V)
                        if V(i)>VM*app.Threshold
                           CL(count,1:4)=CL_P(i,1:4);
                           count=count+1;
                        end
                    end
                    
                   Dom=size(CL,1);
                
                
                c=1;
                for D=1:Dom
                    
                    % N_S=[(1-Y_t(i,j,k)-Z_t(i,j,k)-X_t(i,j,k)) X_t(i,j,k) Y_t(i,j,k) Z_t(i,j,k)];
                    
                    Xv=[P(CL(D,1),1) P(CL(D,2),1) P(CL(D,3),1) P(CL(D,4),1)];
                    Yv=[P(CL(D,1),2) P(CL(D,2),2) P(CL(D,3),2) P(CL(D,4),2)];
                    Zv=[P(CL(D,1),3) P(CL(D,2),3) P(CL(D,3),3) P(CL(D,4),3)];


                    if app.IntegrationorderSpinner.Value==1
                        X_t=0.25;
                        Y_t=0.25;
                        Z_t=0.25;
                        W_t=0.1667;
                        ngp=1;
                        
                    elseif app.IntegrationorderSpinner.Value==2
                        
                     Data=[ 1 0.5854101966 0.1381966011 0.1381966011 0.0416666667
                            2 0.1381966011 0.5854101966 0.1381966011 0.0416666667
                            3 0.1381966011 0.1381966011 0.5854101966 0.0416666667
                            4 0.1381966011 0.1381966011 0.1381966011 0.0416666667]; 
                        
                        X_t=Data(:,2);
                        Y_t=Data(:,3);
                        Z_t=Data(:,4);
                        W_t=Data(:,5);
                        ngp=4;
                        
                    elseif app.IntegrationorderSpinner.Value==3
                        
                     Data=[ 1 1.0000000000 0.0000000000 0.0000000000 0.0027777778
                            2 0.0000000000 1.0000000000 0.0000000000 0.0027777778
                            3 0.0000000000 0.0000000000 1.0000000000 0.0027777778
                            4 0.0000000000 0.0000000000 0.0000000000 0.0027777778
                            5 0.5000000000 0.5000000000 0.0000000000 0.0111111111
                            6 0.0000000000 0.5000000000 0.5000000000 0.0111111111
                            7 0.0000000000 0.0000000000 0.5000000000 0.0111111111
                            8 0.5000000000 0.0000000000 0.0000000000 0.0111111111
                            9 0.0000000000 0.5000000000 0.0000000000 0.0111111111
                            10 0.5000000000 0.0000000000 0.5000000000 0.0111111111
                            11 0.2500000000 0.2500000000 0.2500000000 0.0888888889];
                        
                        X_t=Data(:,2);
                        Y_t=Data(:,3);
                        Z_t=Data(:,4);
                        W_t=Data(:,5);
                        ngp=11;
                        
                     
                    elseif  app.IntegrationorderSpinner.Value==4
                        
                        
                      Data=[1 0.7716429021 0.0761190326 0.0761190326 0.0083956325
                            2 0.0761190326 0.7716429021 0.0761190326 0.0083956325
                            3 0.0761190326 0.0761190326 0.7716429021 0.0083956325
                            4 0.0761190326 0.0761190326 0.0761190326 0.0083956325
                            5 0.1197005278 0.0718316453 0.4042339135 0.0110903448
                            6 0.4042339135 0.1197005278 0.0718316453 0.0110903448
                            7 0.4042339135 0.4042339135 0.1197005278 0.0110903448
                            8 0.0718316453 0.4042339135 0.4042339135 0.0110903448
                            9 0.1197005278 0.4042339135 0.0718316453 0.0110903448
                            10 0.4042339135 0.1197005278 0.4042339135 0.0110903448
                            11 0.0718316453 0.4042339135 0.1197005278 0.0110903448
                            12 0.4042339135 0.0718316453 0.4042339135 0.0110903448
                            13 0.1197005278 0.4042339135 0.4042339135 0.0110903448
                            14 0.0718316453 0.1197005278 0.4042339135 0.0110903448
                            15 0.4042339135 0.0718316453 0.1197005278 0.0110903448
                            16 0.4042339135 0.4042339135 0.0718316453 0.0110903448];
                        
                        
                        X_t=Data(:,2);
                        Y_t=Data(:,3);
                        Z_t=Data(:,4);
                        W_t=Data(:,5);
                        ngp=16;
                        
                        
                    end
                    
                    
                    for gp=1:ngp
                        
                        N_S=[(1-Y_t(gp)-Z_t(gp)-X_t(gp)) X_t(gp) Y_t(gp) Z_t(gp)];
                        
                        X_C=N_S*Xv';
                        Y_C=N_S*Yv';
                        Z_C=N_S*Zv';
                      
                        
                        W_Tet=W_t(gp);
                        DShape=[-1 1 0 0 
                                -1 0 1 0
                                -1 0 0 1];
                      
                        J=DShape*[Xv' Yv' Zv'];
                        dJB=det(J);
                        
                        
                        app.INTP(c,1:3)=[X_C Y_C Z_C];
                        app.W(c,1)=dJB*W_Tet;
                      
                        c=c+1;
                        
                    end
                    
                    
                end


                if app.TetrahedronelementButton.Value==1 && app.DonotguessCheckBox.Value==0
                    app.TextArea.Value{8}=sprintf('Number of Integration points=%d',size(app.INTP,1));
                else
                    app.TextArea.Value{8}=sprintf('Number of Integration points using Triangulation=%d',size(app.INTP,1));
                end

                
            end
           
            
        end
        
        
        function[INTP_L,W_L]=Integration_Point_Generator(app)
            
        %nx=app.PolynomialorderalongxSlider.Value+1;
        %ny=app.PolynomialorderalongySlider.Value+1;
        %nz=app.PolynomialorderalongzSlider.Value+1;
        
        nx=app.X_Order+1;
        ny=app.Y_Order+1;
        nz=app.Z_Order+1;
        
        x=sym('x');
        y=sym('y');
        z=sym('z');  
        
        
%% IPs        
        Ppx=diff(legendreP(nx,x),x,1);
        roots = double(vpasolve(legendreP(nx,x) == 0)); 
        X=roots;
        PpDx=double(subs(Ppx,x,X));
        Wx=zeros(nx,1);
        for i=1:length(X)
            Wx(i)=2/((1-X(i)^2)*PpDx(i)^2);
        end
        

        Ppy=diff(legendreP(ny,y),y,1);
        roots = double(vpasolve(legendreP(ny,y) == 0));        
        Y=roots;
        PpDy=double(subs(Ppy,y,Y));
        Wy=zeros(ny,1);
        for i=1:length(Y)
            Wy(i)=2/((1-Y(i)^2)*PpDy(i)^2);
        end
        

        Ppz=diff(legendreP(nz,z),z,1);
        roots = double(vpasolve(legendreP(nz,z) == 0));
        Z=roots;
        PpDz=double(subs(Ppz,z,Z));
        Wz=zeros(nz,1);
        for i=1:length(Z)
            Wz(i)=2/((1-Z(i)^2)*PpDz(i)^2);
        end
        
        
        %%
        
        [X1,Y1,Z1]=meshgrid(X,Y,Z);
        
        WT=X*0;
        for j=1:nx
            for i=1:ny
                for k=1:nz
                   
                    WT(i,j,k)=Wx(j)*Wy(i)*Wz(k);
                    
                end
            end
        end
        
        
        c=1;
        for j=1:nx
            for i=1:ny
                for k=1:nz
                   
                    INTP_L(c,[1:3])=[X1(i,j,k) Y1(i,j,k) Z1(i,j,k)];
                    W_L(c,1)=WT(i,j,k);
                    c=c+1;
                    
                end
            end
        end


        %% For surfaces
        INTP_LS=zeros(1,7);
        gp_Surfs=zeros(1,6);
        c=1;
        X_Value=1;
        [Y11,Z11]=meshgrid(Y,Z);
            WTS=Y11*0;
            for i=1:ny
                for k=1:nz                   
                    WTS(k,i)=Wy(i)*Wz(k);
                end
            end
            
            for i=1:ny
                for k=1:nz
                    INTP_LS(c,:)=[X_Value Y11(k,i) Z11(k,i) 1 1 0 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                end
            end
            gp_Surfs(1)=c-1;


        X_Value=-1;
        [Y11,Z11]=meshgrid(Y,Z);
            WTS=Y11*0;
            for i=1:ny
                for k=1:nz                   
                    WTS(k,i)=Wy(i)*Wz(k);
                end
            end
            
            for i=1:ny
                for k=1:nz
                    INTP_LS(c,:)=[X_Value Y11(k,i) Z11(k,i) 2 -1 0 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                end
            end
            gp_Surfs(2)=c-1-gp_Surfs(1);


        Y_Value=1;
        [X11,Z11]=meshgrid(X,Z);
            WTS=X11*0;
            for i=1:nx
                for k=1:nz                   
                    WTS(k,i)=Wx(i)*Wz(k);
                end
            end
            
            for i=1:nx
                for k=1:nz
                    INTP_LS(c,:)=[X11(k,i) Y_Value Z11(k,i) 3 0 1 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                end
            end
            gp_Surfs(3)=c-1-sum(gp_Surfs(1:2));


        Y_Value=-1;
        [X11,Z11]=meshgrid(X,Z);
            WTS=X11*0;
            for i=1:nx
                for k=1:nz                   
                    WTS(k,i)=Wx(i)*Wz(k);
                end
            end
            
            for i=1:nx
                for k=1:nz
                    INTP_LS(c,:)=[X11(k,i) Y_Value Z11(k,i) 4 0 -1 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                end
            end
            gp_Surfs(4)=c-1-sum(gp_Surfs(1:3));       


        Z_Value=1;
        [X11,Y11]=meshgrid(X,Y);
            WTS=X11*0;
            for i=1:nx
                for k=1:ny                   
                    WTS(k,i)=Wx(i)*Wy(k);
                end
            end
            
            for i=1:nx
                for k=1:ny
                    INTP_LS(c,:)=[X11(k,i) Y11(k,i) Z_Value 5 0 0 1];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                end
            end
            gp_Surfs(5)=c-1-sum(gp_Surfs(1:4));


        Z_Value=-1;
        [X11,Y11]=meshgrid(X,Y);
            WTS=X11*0;
            for i=1:nx
                for k=1:ny                   
                    WTS(k,i)=Wx(i)*Wy(k);
                end
            end
            
            for i=1:nx
                for k=1:ny
                    INTP_LS(c,:)=[X11(k,i) Y11(k,i) Z_Value 6 0 0 -1];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                end
            end
            gp_Surfs(6)=c-1-sum(gp_Surfs(1:5));


            app.INTP_Surf=INTP_LS;
            app.W_Surf=W_LS;
            app.gp_per_Surfs=gp_Surfs;

            % INTP_L=INTP_LS;
            % W_L=W_LS;


        end
        
        function[INTP_L_Up,W_L_Up]=Integration_Point_Generator_Lobatto(app)


         % [P,W,x,y,z,Wx,Wy,Wz]=Automatic_Node_Generator_Hexahedron(app.X_Order,MaxDy,MaxDz,0);   
         % [~,~,x,y,z,Wx,Wy,Wz]=Automatic_Node_Generator_Hexahedron(app.X_Order,MaxDy,MaxDz,0);
           
         [X,Wx]=lglnodes(app,app.X_Order);
         [Y,Wy]=lglnodes(app,app.Y_Order);
         [Z,Wz]=lglnodes(app,app.Z_Order);
         
         INTP_LP=zeros(1,3);

        %nx=app.PolynomialorderalongxSlider.Value+1;
        %ny=app.PolynomialorderalongySlider.Value+1;
        %nz=app.PolynomialorderalongzSlider.Value+1;
        
        nx=app.X_Order+1;
        ny=app.Y_Order+1;
        nz=app.Z_Order+1;
         
        
        [X1,Y1,Z1]=meshgrid(X,Y,Z);
        
        WT=X1*0;
        for j=1:nx
            for i=1:ny
                for k=1:nz
                   
                    WT(i,j,k)=Wx(j)*Wy(i)*Wz(k);
                    
                end
            end
        end
        
        
        c=1;
        for j=1:nx
            for i=1:ny
                for k=1:nz
                   
                    INTP_LP(c,[1:3])=[X1(i,j,k) Y1(i,j,k) Z1(i,j,k)];
                    W_LP(c,1)=WT(i,j,k);
                    c=c+1;
                    
                end
            end
        end


            INTP_LS_RC=INTP_LP;
            W_LS_RC=W_LP;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=1:c-1
                    xyzL=INTP_LP(n,1:3);
                    wL=W_LP(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(1:c-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_L_Up=[INTP_LS_w20;INTP_no20];
            W_L_Up=[W_LS_w20; W_LS_no20];   


        %% For surfaces


        % app.Order_Local
        %app.Order_Local(i,1:3)=[X_Order_L,Y_Order_L,Z_Order_L];


        % [X,Wx]=lglnodes(app,app.X_Order);
        % [Y,Wy]=lglnodes(app,app.Y_Order);
        % [Z,Wz]=lglnodes(app,app.Z_Order);        
        
        INTP_LS=zeros(1,7);
        gp_Surfs=zeros(1,6);
        c=1;
        
        
        % Surface x=1
        X_Value=1;
        Y_Order_Max=max(app.Order_Local(app.Node_Set_20N_x1,2));
        Z_Order_Max=max(app.Order_Local(app.Node_Set_20N_x1,3));
        [Y,Wy]=lglnodes(app,Y_Order_Max);
        [Z,Wz]=lglnodes(app,Z_Order_Max);          
        [Y11,Z11]=meshgrid(Y,Z);
        ny=Y_Order_Max+1;
        nz=Z_Order_Max+1;
        cxP=c;
        
            WTS=Y11*0;
            for i=1:ny
                for k=1:nz                   
                    WTS(k,i)=Wy(i)*Wz(k);
                end
            end
            
            for i=1:ny
                for k=1:nz
                    INTP_LS(c,:)=[X_Value Y11(k,i) Z11(k,i) 1 1 0 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                    %cxP=cxP+1;
                end
            end
            gp_Surfs(1)=c-1;
            %cxP=cxP-1;


        % Surface x=-1
        X_Value=-1;
        Y_Order_Max=max(app.Order_Local(app.Node_Set_20N_xM1,2));
        Z_Order_Max=max(app.Order_Local(app.Node_Set_20N_xM1,3));
        [Y,Wy]=lglnodes(app,Y_Order_Max);
        [Z,Wz]=lglnodes(app,Z_Order_Max);         
        [Y11,Z11]=meshgrid(Y,Z);
        ny=Y_Order_Max+1;
        nz=Z_Order_Max+1;
        cxN=c;
        
            WTS=Y11*0;
            for i=1:ny
                for k=1:nz                   
                    WTS(k,i)=Wy(i)*Wz(k);
                end
            end
            
            for i=1:ny
                for k=1:nz
                    INTP_LS(c,:)=[X_Value Y11(k,i) Z11(k,i) 2 -1 0 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                    %cxN=cxN+1;
                end
            end
            gp_Surfs(2)=c-1-gp_Surfs(1);
            %cxN=cxN-1;


        % Surface y=1    
        Y_Value=1;
        X_Order_Max=max(app.Order_Local(app.Node_Set_20N_y1,1));
        Z_Order_Max=max(app.Order_Local(app.Node_Set_20N_y1,3));        
        [X,Wx]=lglnodes(app,X_Order_Max);
        [Z,Wz]=lglnodes(app,Z_Order_Max);  
        nx=X_Order_Max+1;
        nz=Z_Order_Max+1;
        cyP=c;
        
        [X11,Z11]=meshgrid(X,Z);
            WTS=X11*0;
            for i=1:nx
                for k=1:nz                   
                    WTS(k,i)=Wx(i)*Wz(k);
                end
            end
            
            for i=1:nx
                for k=1:nz
                    INTP_LS(c,:)=[X11(k,i) Y_Value Z11(k,i) 3 0 1 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                    %cyP=cyP+1;
                end
            end
            gp_Surfs(3)=c-1-sum(gp_Surfs(1:2));
            %cyP=cyP-1;


        % Surface y=-1      
        Y_Value=-1;
        X_Order_Max=max(app.Order_Local(app.Node_Set_20N_yM1,1));
        Z_Order_Max=max(app.Order_Local(app.Node_Set_20N_yM1,3));        
        [X,Wx]=lglnodes(app,X_Order_Max);
        [Z,Wz]=lglnodes(app,Z_Order_Max);      
        nx=X_Order_Max+1;
        nz=Z_Order_Max+1;
        cyN=c;
        
        [X11,Z11]=meshgrid(X,Z);
            WTS=X11*0;
            for i=1:nx
                for k=1:nz                   
                    WTS(k,i)=Wx(i)*Wz(k);
                end
            end
            
            for i=1:nx
                for k=1:nz
                    INTP_LS(c,:)=[X11(k,i) Y_Value Z11(k,i) 4 0 -1 0];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                    %cyN=cyN+1;
                end
            end
            gp_Surfs(4)=c-1-sum(gp_Surfs(1:3));
            %cyN=cyN-1;


        % Surface Z=1       
        Z_Value=1;
        X_Order_Max=max(app.Order_Local(app.Node_Set_20N_z1,1));
        Y_Order_Max=max(app.Order_Local(app.Node_Set_20N_z1,2));        
        [X,Wx]=lglnodes(app,X_Order_Max);
        [Y,Wy]=lglnodes(app,Y_Order_Max);       
        nx=X_Order_Max+1;
        ny=Y_Order_Max+1;
        czP=c;
        
        [X11,Y11]=meshgrid(X,Y);
            WTS=X11*0;
            for i=1:nx
                for k=1:ny                   
                    WTS(k,i)=Wx(i)*Wy(k);
                end
            end
            
            for i=1:nx
                for k=1:ny
                    INTP_LS(c,:)=[X11(k,i) Y11(k,i) Z_Value 5 0 0 1];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                    %czP=czP+1;
                end
            end
            gp_Surfs(5)=c-1-sum(gp_Surfs(1:4));
            %czP=czP-1;


        % Surface Z=-1  
        Z_Value=-1;
        X_Order_Max=max(app.Order_Local(app.Node_Set_20N_zM1,1));
        Y_Order_Max=max(app.Order_Local(app.Node_Set_20N_zM1,2));   
        [X,Wx]=lglnodes(app,X_Order_Max);
        [Y,Wy]=lglnodes(app,Y_Order_Max);     
        nx=X_Order_Max+1;
        ny=Y_Order_Max+1;       
        czN=c;
        
        [X11,Y11]=meshgrid(X,Y);
            WTS=X11*0;
            for i=1:nx
                for k=1:ny                   
                    WTS(k,i)=Wx(i)*Wy(k);
                end
            end
            
            for i=1:nx
                for k=1:ny
                    INTP_LS(c,:)=[X11(k,i) Y11(k,i) Z_Value 6 0 0 -1];
                    W_LS(c,1)=WTS(k,i);
                    c=c+1;
                    %czN=czN+1;
                end
            end
            gp_Surfs(6)=c-1-sum(gp_Surfs(1:5));
            %czN=czN-1;


            % INTP_L=INTP_LS;
            % W_L=W_LS;
            
            
            %% Update all shits
            % x=1
            INTP_LS_RC=INTP_LS;
            W_LS_RC=W_LS;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=cxP:cxP+gp_Surfs(1)-1
                    xyzL=INTP_LS(n,1:3);
                    wL=W_LS(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(cxP:cxP+gp_Surfs(1)-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_LS_RCxP=[INTP_LS_w20;INTP_no20];
            W_LS_RCxP=[W_LS_w20; W_LS_no20];
            

            % x=-1
            INTP_LS_RC=INTP_LS;
            W_LS_RC=W_LS;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=cxN:cxN+gp_Surfs(2)-1
                    xyzL=INTP_LS(n,1:3);
                    wL=W_LS(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(cxN:cxN+gp_Surfs(2)-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_LS_RCxN=[INTP_LS_w20;INTP_no20];
            W_LS_RCxN=[W_LS_w20; W_LS_no20];            
            
            % y= 1
            INTP_LS_RC=INTP_LS;
            W_LS_RC=W_LS;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=cyP:cyP+gp_Surfs(3)-1
                    xyzL=INTP_LS(n,1:3);
                    wL=W_LS(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(cyP:cyP+gp_Surfs(3)-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_LS_RCyP=[INTP_LS_w20;INTP_no20];
            W_LS_RCyP=[W_LS_w20; W_LS_no20];               
            
            
            % y= -1
            INTP_LS_RC=INTP_LS;
            W_LS_RC=W_LS;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=cyN:cyN+gp_Surfs(4)-1
                    xyzL=INTP_LS(n,1:3);
                    wL=W_LS(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(cyN:cyN+gp_Surfs(4)-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_LS_RCyN=[INTP_LS_w20;INTP_no20];
            W_LS_RCyN=[W_LS_w20; W_LS_no20];               
                        
            
            % z= 1
            INTP_LS_RC=INTP_LS;
            W_LS_RC=W_LS;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=czP:czP+gp_Surfs(5)-1
                    xyzL=INTP_LS(n,1:3);
                    wL=W_LS(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(czP:czP+gp_Surfs(5)-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_LS_RCzP=[INTP_LS_w20;INTP_no20];
            W_LS_RCzP=[W_LS_w20; W_LS_no20];        
            
            
            % z= -1
            INTP_LS_RC=INTP_LS;
            W_LS_RC=W_LS;
            Gp_Taken=zeros(20,1);
            INTP_LS_RC_New=INTP_LS_RC*0;
            W_LS_RC_New=W_LS_RC*0;
            
            n_Update=1;
            for n_Ref=1:20
                xyz20=app.Node_Set_20N(n_Ref,:);

                for n=czN:czN+gp_Surfs(6)-1
                    xyzL=INTP_LS(n,1:3);
                    wL=W_LS(n);
                    Diff=norm(xyz20-xyzL);
                    if Diff<10^-10
                        INTP_LS_RC_New(n_Update,:)=INTP_LS_RC(n,:);
                        W_LS_RC_New(n_Update)=wL;
                        Gp_Taken(n_Update)=n;
                        n_Update=n_Update+1;
                        break
                    end
                end
                
            end
            
            Gp_Left=setdiff(czN:czN+gp_Surfs(6)-1,Gp_Taken);                    
                    
            INTP_no20=INTP_LS_RC(Gp_Left,:);
            W_LS_no20=W_LS_RC(Gp_Left);
            
            INTP_LS_w20=INTP_LS_RC_New(1:n_Update-1,:);
            W_LS_w20=W_LS_RC_New(1:n_Update-1);
            
            INTP_LS_RCzN=[INTP_LS_w20;INTP_no20];
            W_LS_RCzN=[W_LS_w20; W_LS_no20];   
            
            
            INTP_LS_Updated=[INTP_LS_RCxP;INTP_LS_RCxN;INTP_LS_RCyP;INTP_LS_RCyN;INTP_LS_RCzP;INTP_LS_RCzN];
            W_LS_Updated=[W_LS_RCxP;W_LS_RCxN;W_LS_RCyP;W_LS_RCyN;W_LS_RCzP;W_LS_RCzN];
            
            app.INTP_Surf=INTP_LS_Updated;
            app.W_Surf=W_LS_Updated;
            app.gp_per_Surfs=gp_Surfs;


        end
        

            %% External
            function [x,w,P]=lglnodes(app,N)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            % lglnodes.m
            %
            % Computes the Legendre-Gauss-Lobatto nodes, weights and the LGL Vandermonde 
            % matrix. The LGL nodes are the zeros of (1-x^2)*P'_N(x). Useful for numerical
            % integration and spectral methods. 
            %
            % Reference on LGL nodes and weights: 
            %   C. Canuto, M. Y. Hussaini, A. Quarteroni, T. A. Tang, "Spectral Methods
            %   in Fluid Dynamics," Section 2.3. Springer-Verlag 1987
            %
            % Written by Greg von Winckel - 04/17/2004
            % Contact: gregvw@chtm.unm.edu
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Truncation + 1
            N1=N+1;
            % Use the Chebyshev-Gauss-Lobatto nodes as the first guess
            x=cos(pi*(0:N)/N)';
            % The Legendre Vandermonde Matrix
            P=zeros(N1,N1);
            % Compute P_(N) using the recursion relation
            % Compute its first and second derivatives and 
            % update x using the Newton-Raphson method.
            xold=2;
            while max(abs(x-xold))>eps
                xold=x;
                    
                P(:,1)=1;    P(:,2)=x;
                
                for k=2:N
                    P(:,k+1)=( (2*k-1)*x.*P(:,k)-(k-1)*P(:,k-1) )/k;
                end
                 
                x=xold-( x.*P(:,N1)-P(:,N) )./( N1*P(:,N1) );
                         
            end
            w=2./(N*N1*P(:,N1).^2);
            
            end


        %------------------------------------------------------------------------------
        function Type_Getter(app)
            
 
            if app.DonotguessCheckBox.Value==0
                
                P=app.P_C;
                
                DT = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                
                FB=freeBoundary(DT);
                
                V=zeros(size(FB,1),3);
                for i=1:size(FB,1)
                   
                    V1=[P(FB(i,1),1) P(FB(i,1),2) P(FB(i,1),3)]-[P(FB(i,2),1) P(FB(i,2),2) P(FB(i,2),3)];
                    V2=[P(FB(i,2),1) P(FB(i,2),2) P(FB(i,2),3)]-[P(FB(i,3),1) P(FB(i,3),2) P(FB(i,3),3)];
                    
                    
                    V(i,1:3)=cross(V1,V2);
                    V(i,1:3)=V(i,1:3)/norm(V(i,1:3));
                    
                    
                end
                
                
                UniqueS=unique(V(:,1:3),'rows');


                Rad=zeros(size(P,1),1);
                for i=1:size(P,1)
                    Rad(i)=norm(P(i,:));
                end
                
                    if  ((length(find(Rad>sqrt(3)))==0 && length(find(Rad==sqrt(3)))==8) && size(UniqueS,1)==6) || app.HexahedronorBrickelementButton.Value==1
                        app.TextArea.Value{7}=sprintf('Looks like Isoparametric Brick element!');
                        app.Type_C='B';
                        
                    elseif (size(UniqueS,1)==4 && length(find(Rad==0))==1 && length(find(Rad==1))==3) || app.TetrahedronelementButton.Value==1
                        app.TextArea.Value{7}=sprintf('Looks like Isoparametric Tetrhedral!');
                        app.Type_C='T'; 
                        
                        
                    elseif size(UniqueS,1)==6
                        app.TextArea.Value{7}=sprintf('Looks like a Brick, but not Isoparametric!');                    
                        app.Type_C='U';
                        
                    elseif size(UniqueS,1)==4
                        app.TextArea.Value{7}=sprintf('Looks like Tetrhedral, but not Isoparametric!');
                        app.Type_C='U';     
                       
                    elseif size(UniqueS,1)==5
                        app.TextArea.Value{7}=sprintf('Looks like Pyramid or Wedge!');
                        app.Type_C='U';                    
                        
                    else
                        app.TextArea.Value{7}=sprintf('Element type Unknown!');
                        app.Type_C='U';
                    end
                    
            else
                 app.TextArea.Value{6}=sprintf('Element type Unknown!');
                 app.Type_C='U';                
            end
                
            
        end
        
        
        %------------------------------------------------------------------------------
        
        function Verify_Shape_Functions(app)
            
            
            P=app.P_C;
            SFm=app.SFm_C;
            Peels=app.Peels_C;
            no=Peels;
            Type=app.Type_C;
            
            % size(app.WhatpropertyyouwanttoplotDropDown.Value)
            
            Case=0;

            if app.WhatpropertyyouwanttoplotDropDown.Value=='      Shape function, N'
                N=app.N_C;
                title(app.UIAxes,sprintf('Shape Function,N%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;


            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='Shape function diff, dN'
                N=app.N_C;
                title(app.UIAxes,sprintf('Difference of Shape Function,N%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;                

            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='                  dN/dx'
                
                N=app.dNbydx;
                title(app.UIAxes,sprintf('ddxofN%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nddxofN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;
            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='                  dN/dy'
                N=app.dNbydy;
                title(app.UIAxes,sprintf('ddyofN%d',SFm));
                app.TextArea_2.Value=sprintf('\r\ndddyofN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;
            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='                  dN/dz'
                N=app.dNbydz;
                title(app.UIAxes,sprintf('ddzofN%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nddzofdN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;
                
            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='           d^m/dx^m (N)'            
                % N=app.d2Nbydx2;
                % title(app.UIAxes,sprintf('ddx^%dofN%d',app.Order_Local(app.SFm_C,1),SFm));
                % app.TextArea_2.Value=sprintf('\r\nddx^%dofdN%d=%s\r\n',app.Order_Local(app.SFm_C,1),app.SFm_C,string(N(app.SFm_C)));   

                N=app.d2Nbydx2;
                title(app.UIAxes,sprintf('d^%dN%d/dx^%d',app.Order_Local(SFm,1),SFm,app.Order_Local(SFm,1)));
                app.TextArea_2.Value=sprintf('\r\n x Diff times=%d \r\nd^%dN%d/dy^%d=%s\r\n',app.Order_Local(SFm,1),app.Order_Local(SFm,1),SFm,app.Order_Local(SFm,1),string(N(app.SFm_C)));
                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;

            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='           d^m/dy^m (N)'
                N=app.d2Nbydy2;
                title(app.UIAxes,sprintf('d^%dN%d/dy^%d',app.Order_Local(SFm,2),SFm,app.Order_Local(SFm,2)));
                app.TextArea_2.Value=sprintf('\r\n y Diff times=%d \r\nd^%dN%d/dy^%d=%s\r\n',app.Order_Local(SFm,2),app.Order_Local(SFm,2),SFm,app.Order_Local(SFm,2),string(N(app.SFm_C)));

                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;

            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='           d^m/dz^m (N)'
                   
                N=app.d2Nbydz2;
                title(app.UIAxes,sprintf('d^%dN%d/dz^%d',app.Order_Local(SFm,3),SFm,app.Order_Local(SFm,3)));
                app.TextArea_2.Value=sprintf('\r\n Diff times=%d \r\n d^%dN%d/dy^%d=%s\r\n',app.Order_Local(SFm,3),app.Order_Local(SFm,3),SFm,app.Order_Local(SFm,3),string(N(app.SFm_C)));

                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;


            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='   d^m/dx^ndy^ndz^n (N)'

                N=app.d2Nbydz2C;
                Order=app.Order_Local(SFm,4)/3;
                title(app.UIAxes,sprintf('d^%dN%d/dx^%ddy^%ddz^%d',Order*3,SFm,Order,Order,Order));
                app.TextArea_2.Value=sprintf('\r\n Diff times=%d \r\n d^%dN%d/dx^%ddy^%ddz^%d=%s\r\n',Order*3,Order*3,SFm,Order,Order,Order,string(N(app.SFm_C)));                


            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='   d^m/dx^ady^bdz^c (N)'

                N=app.d2Nbydz2C2;
                Order=app.Order_Local(SFm,5:7);

                title(app.UIAxes,sprintf('d^%dN%d/dx^%ddy^%ddz^%d',sum(Order),SFm,Order(1),Order(2),Order(3)));
                app.TextArea_2.Value=sprintf('\r\n Diff times=%d \r\n d^%dN%d/dx^%ddy^%ddz^%d=%s\r\n',sum(Order),sum(Order),SFm,Order(1),Order(2),Order(3),string(N(app.SFm_C)));


                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;
            end


            % Correction
            for i=1:length(SFm)
                if SFm(i)>length(P)
                    SFm(i)=length(P);
                end
            end
            

            S=size(P);
            L=length(SFm);
            
            %% Check what type of Element
            if  Type=='B'            
                
                if app.Peels_C==2
                    app.PeelsSpinner.Value=15;
                    app.Peels_C=15;
                    Peels=app.Peels_C;
                    no=Peels;
                end
                
                
                X=zeros(no,no,no,1); Y=X;Z=X;
                [X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1)]=meshgrid(linspace(min(P(:,1)),max(P(:,1)),no),linspace(min(P(:,2)),max(P(:,2)),no),linspace(min(P(:,3)),max(P(:,3)),no));
                
                Dom=1;
            
            elseif Type=='T'
                
                
                Dom=1;
                
                % Get Grid for Tetragonal element
                    xlx=linspace(0,1,no);
                    XL=zeros(no,no); YL=XL;
                    for i=1:no
                        XL(:,i)=linspace(xlx(i),0,no);
                        YL(:,i)=linspace(0,xlx(i),no);
                    end
            
                    z_Cal=linspace(0,1,no);
                    X=zeros(no,no,no); Y=X;Z=X;
                    for i=1:no
                       X(:,:,i)=-z_Cal(i).*XL+XL;
                       Y(:,:,i)=-z_Cal(i).*YL+YL;
                       Z(:,:,i)=XL*0+z_Cal(i);
                    end
                

            else % If Element Geometry is not standard, Use triangulation

                % Triangulate
                DT = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                CL=DT.ConnectivityList; 
                CL=unique(CL(:,1:4),'rows');
                

                    %% Correction
                    CL_P=CL;
                    CL=zeros(1,4);
                    V=CL_P(:,1)*0;
                    for i=1:size(CL_P,1)
                       
                        a=[P(CL_P(i,1),1) P(CL_P(i,1),2) P(CL_P(i,1),3)];
                        b=[P(CL_P(i,2),1) P(CL_P(i,2),2) P(CL_P(i,2),3)];
                        c=[P(CL_P(i,3),1) P(CL_P(i,3),2) P(CL_P(i,3),3)];
                        d=[P(CL_P(i,4),1) P(CL_P(i,4),2) P(CL_P(i,4),3)];
                        
                        V(i)=abs((a-d)*(cross((b-d),(c-d)))');
                        
                    end
                    
                    VM=mean(V);
                    count=1;
                    for i=1:length(V)
                        if V(i)>VM*app.Threshold
                           CL(count,1:4)=CL_P(i,1:4);
                           count=count+1;
                        end
                    end
                    
                   Dom=size(CL,1);


                    % no=round(no/size(CL,1));
                    if no<2
                        no=2;
                    elseif no>30
                        no=50;
                        app.PeelsSpinner.Value=30;
                    end
                    
                    
                    % Get Grid for Tetragonal element
                    xlx=linspace(0,1,no);
                    XL=zeros(no,no); YL=XL;
                    for i=1:no
                        XL(:,i)=linspace(xlx(i),0,no);
                        YL(:,i)=linspace(0,xlx(i),no);
                    end
            
                    z_Cal=linspace(0,1,no);
                    X_t=zeros(no,no,no); Y_t=X_t;Z_t=X_t;
                    for i=1:no
                       X_t(:,:,i)=-z_Cal(i).*XL+XL;
                       Y_t(:,:,i)=-z_Cal(i).*YL+YL;
                       Z_t(:,:,i)=XL*0+z_Cal(i);
                    end
              
            
                Siz=size(CL);
                X=zeros(no,no,no,Siz(1));Y=X;Z=X;
                
                for D=1:Siz(1)
                    for i=1:no
                        for j=1:no
                            for k=1:no
            
                                % Tetrahedral element shape function
                                N_S=[(1-Y_t(i,j,k)-Z_t(i,j,k)-X_t(i,j,k)) X_t(i,j,k) Y_t(i,j,k) Z_t(i,j,k)];
            
                                X(i,j,k,D)=N_S*P(CL(D,:),1);
                                Y(i,j,k,D)=N_S*P(CL(D,:),2);
                                Z(i,j,k,D)=N_S*P(CL(D,:),3);
                                
                            end
                        end
                    end

                end
                
                Dom=Siz(1);

                app.TextArea.Value{7}=sprintf('Element type Unknown! Number of tetrahedrons from delaunay triangulation=%d',Dom);
                  
            end


            %% Extract Shape function data for each point
            NC=sym(zeros(length(SFm),1));
            for i=1:length(SFm)
                NC(i,1)=N(SFm(i));
            end
            
            x=sym('x');
            y=sym('y');
            z=sym('z');
            
            N_d=matlabFunction(NC,'vars',[x y z]);
            
            K=size(X);
            n=zeros(K(1),K(2),K(3),L);


            for D=1:Dom
                sn=1;
                for s=1:length(SFm) % For all shape functions
                    for i=1:K(1)
                        for j=1:K(2)
                            for k=1:K(3)
                                
                                R=N_d(X(i,j,k,D),Y(i,j,k,D),Z(i,j,k,D));
            
                                n(i,j,k,1:L,D)=R;%(s,1);
            
                            end
                        end
                    end
                    sn=sn+1;
                end
                
            end
            
            
            %% Printing all the shape functions
            if SFm>0
                List=SFm;
            else
                List=1:L;
            end
                
            
            % n=Sum;
            
            for i=1:L
            
                if length(SFm)==1 && SFm==0
                    break
                end
            
                
                hold(app.UIFigure.CurrentAxes,'on');

                    Sz=gobjects(Dom,no);
                    Sy=gobjects(Dom,no);
                    Sx=gobjects(Dom,no);
                
           
                if no*Dom>50 && no>4
                    app.FillSpinner.Value=0;
                    app.TextArea.Value{9}=sprintf('Too many surfaces (%d) to plot!\n',no*Dom);
                end
                    
                if Type=="U" || Type=='T'
                                     
                    for D=1:Dom
                        
                        if app.FillSpinner.Value>0
                        for m=1:no
                            Sz(D,m)=surf(app.UIAxes,squeeze(X(m,:,:,D)),squeeze(Y(m,:,:,D)),squeeze(Z(m,:,:,D)),squeeze(n(m,:,:,i,D)));
                            Sy(D,m)=surf(app.UIAxes,squeeze(X(:,m,:,D)),squeeze(Y(:,m,:,D)),squeeze(Z(:,m,:,D)),squeeze(n(:,m,:,i,D)));
                            Sx(D,m)=surf(app.UIAxes,squeeze(X(:,:,m,D)),squeeze(Y(:,:,m,D)),squeeze(Z(:,:,m,D)),squeeze(n(:,:,m,i,D)));
                        end
                        end
                        
                        for q=setdiff(linspace(min(min(min(n(:,:,:,i,D)))),max(max(max(n(:,:,:,i,D)))),Peels),0)
                            fv=isosurface(X(:,:,:,D),Y(:,:,:,D),Z(:,:,:,D),n(:,:,:,i,D),q);
                            hIsosurfU=patch(app.UIAxes,fv);
                            set(hIsosurfU,'CData',q,'FaceColor','flat','EdgeColor','none','FaceAlpha',0.4);
                        end

                    end
                    
                end
                
                if Type=='T'
%                     Pks=[0 0 0;0 1 0;1 0 0;0 0 0];
%                     plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
%                     
%                     Pks=[0 0 0; 0 0 1; 1 0 0];
%                     plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
%                     
%                     Pks=[0 0 1; 0 1 0];
%                     plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                end

                
                if Type=='B'
            %          hSlice=slice(X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1),n(:,:,:,i,1),[linspace(min(min(min(X))),max(max(max(X))),no)],[linspace(min(min(min(Y))),max(max(max(Y))),no)],[linspace(min(min(min(Z))),max(max(max(Z))),no)]);
            %          set(hSlice,'FaceColor','interp','FaceAlpha',0.1,'EdgeColor','none');
                    if app.FillSpinner.Value>0
                     app.hSlice=slice(app.UIAxes,X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1),n(:,:,:,i,1),[linspace(min(min(min(X))),max(max(max(X))),2)],[linspace(min(min(min(Y))),max(max(max(Y))),2)],[linspace(min(min(min(Z))),max(max(max(Z))),2)]);
                     set(app.hSlice,'FaceColor','interp','FaceAlpha',0.1,'EdgeColor','none');
                    end
                     % set(hSlice,'FaceColor','interp','FaceAlpha',0.5,'EdgeColor','none');
                     
                    for q=setdiff(linspace(min(min(min(n(:,:,:,i,1)))),max(max(max(n(:,:,:,i,1)))),Peels),0)
                        fv=isosurface(X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1),n(:,:,:,i,1),q);
                        hIsosurfU=patch(app.UIAxes,fv);
                        set(hIsosurfU,'CData',q,'FaceColor','flat','EdgeColor','none','FaceAlpha',0.4);
                    end

                    %qS=setdiff(linspace(min(min(min(n(:,:,:,i,D)))),max(max(max(n(:,:,:,i,D)))),Peels),0);
                    
                    
                end


               if Type=='B'
                    Pks=[-1 -1 -1;-1 1 -1; 1 1 -1; 1 -1 -1;-1 -1 -1];
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[-1 -1 1;-1 1 1; 1 1 1; 1 -1 1;-1 -1 1];
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[-1 -1 -1;-1 -1 1];
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[1 1 -1;1 1 1];
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[-1 1 -1;-1 1 1];                
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[1 -1 -1;1 -1 1];                  
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    
                end
                
                 
                 if app.NodesCheckBox.Value==1
                     app.SNP=scatter3(app.UIAxes,P(:,1),P(:,2),P(:,3),40,'MarkerEdgeColor','k','MarkerFaceColor','k');
                 end
                 
                 if app.IntegrationpointsCheckBox.Value==1
                     app.SGP=gobjects(size(app.INTP,1));
                     for d=1:size(app.INTP,1)
                        
                         app.SGP(d)=scatter3(app.UIAxes,app.INTP(d,1),app.INTP(d,2),app.INTP(d,3),40,'MarkerEdgeColor','r','MarkerFaceColor','r');
                        
                     end
                end
                 
                Min=min(min(min(min(min(n)))));
                Max=max(max(max(max(max(n)))));
                
                if min(min(min(min(min(n)))))>=max(max(max(max(max(n)))))
                    Min=Min-0.1;
                    Max=Max+0.1;
                end
                    
                if Case==10 || Case==11
                 %Colors=linspace(Min,Max,app.PeelsSpinner.Value);
                 c = colorbar(app.UIAxes);
                 colormap(app.UIAxes, jet);
                 caxis(app.UIAxes,[Min  Max]);
                 % c.Label.String = 'Shape function value';
                 axis(app.UIAxes, 'tight');
                else
                 % Colors=[floor(Min):1:ceil(Max)];
                 Colors=setdiff(linspace(Min,Max,app.PeelsSpinner.Value+1),0);
                 % c = colorbar(app.UIAxes,'XTick',Colors);
                 c = colorbar(app.UIAxes);
                 colormap(app.UIAxes, jet(length(Colors)))
                 % caxis(app.UIAxes,[floor(Min) ceil(Max)]);
                 caxis(app.UIAxes,[Min Max]);
                 
                 % c.Label.String = 'Shape function value';
                 axis(app.UIAxes, 'tight');

                end
                 
                % view(app.UIAxes,45,45)
                daspect(app.UIAxes,[1 1 1])
                rotate3d(app.UIFigure.CurrentAxes,'on');

                
            end
            
            app.Type_C=Type;
            app.Sx_C=Sx;
            app.Sy_C=Sy;
            app.Sz_C=Sz;
            
            app.Dom_C=Dom;
            
            % Verify_Shape_Functions_Surface(app);
            
        end
        
function Verify_Shape_Functions_2(app)
            
            % PT=Nodes_Old_T;
            P=app.P_C_2_Map;
            SFm=app.SFm_C_2;
            Peels=app.Peels_C;
            no=Peels;
            Type=app.Type_C_2;
            

            Case=0;
            %N=app.N_C_2_Map;

            if app.WhatpropertyyouwanttoplotDropDown.Value=='      Shape function, N'
                N=app.N_C_2_Map;
                title(app.UIAxes,sprintf('N%d (New element) vs N%d (Old element)',app.SFm_C,SFm));
                app.TextArea_2.Value=sprintf('\r\nN%d=%s\r\n',app.SFm_C_2,string(N(app.SFm_C_2)));
                app.Spinner.Enable=1;


            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='Shape function diff, dN'
                N=app.N_C;
                title(app.UIAxes,sprintf('Difference of Shape Function,N%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;                

            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='                  dN/dx'
                
                N=app.dNbydx;
                title(app.UIAxes,sprintf('ddxofN%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nddxofN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;
            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='                  dN/dy'
                N=app.dNbydy;
                title(app.UIAxes,sprintf('ddyofN%d',SFm));
                app.TextArea_2.Value=sprintf('\r\ndddyofN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;
            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='                  dN/dz'
                N=app.dNbydz;
                title(app.UIAxes,sprintf('ddzofN%d',SFm));
                app.TextArea_2.Value=sprintf('\r\nddzofdN%d=%s\r\n',app.SFm_C,string(N(app.SFm_C)));
                app.Spinner.Enable=1;
                
            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='           d^m/dx^m (N)'            
                % N=app.d2Nbydx2;
                % title(app.UIAxes,sprintf('ddx^%dofN%d',app.Order_Local(app.SFm_C,1),SFm));
                % app.TextArea_2.Value=sprintf('\r\nddx^%dofdN%d=%s\r\n',app.Order_Local(app.SFm_C,1),app.SFm_C,string(N(app.SFm_C)));   

                N=app.d2Nbydx2;
                title(app.UIAxes,sprintf('d^%dN%d/dx^%d',app.Order_Local(SFm,1),SFm,app.Order_Local(SFm,1)));
                app.TextArea_2.Value=sprintf('\r\n x Diff times=%d \r\nd^%dN%d/dy^%d=%s\r\n',app.Order_Local(SFm,1),app.Order_Local(SFm,1),SFm,app.Order_Local(SFm,1),string(N(app.SFm_C)));
                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;

            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='           d^m/dy^m (N)'
                N=app.d2Nbydy2;
                title(app.UIAxes,sprintf('d^%dN%d/dy^%d',app.Order_Local(SFm,2),SFm,app.Order_Local(SFm,2)));
                app.TextArea_2.Value=sprintf('\r\n y Diff times=%d \r\nd^%dN%d/dy^%d=%s\r\n',app.Order_Local(SFm,2),app.Order_Local(SFm,2),SFm,app.Order_Local(SFm,2),string(N(app.SFm_C)));

                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;

            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='           d^m/dz^m (N)'
                   
                N=app.d2Nbydz2;
                title(app.UIAxes,sprintf('d^%dN%d/dz^%d',app.Order_Local(SFm,3),SFm,app.Order_Local(SFm,3)));
                app.TextArea_2.Value=sprintf('\r\n Diff times=%d \r\n d^%dN%d/dy^%d=%s\r\n',app.Order_Local(SFm,3),app.Order_Local(SFm,3),SFm,app.Order_Local(SFm,3),string(N(app.SFm_C)));

                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;


            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='   d^m/dx^ndy^ndz^n (N)'

                N=app.d2Nbydz2C;
                Order=app.Order_Local(SFm,4)/3;
                title(app.UIAxes,sprintf('d^%dN%d/dx^%ddy^%ddz^%d',Order*3,SFm,Order,Order,Order));
                app.TextArea_2.Value=sprintf('\r\n Diff times=%d \r\n d^%dN%d/dx^%ddy^%ddz^%d=%s\r\n',Order*3,Order*3,SFm,Order,Order,Order,string(N(app.SFm_C)));                


            elseif app.WhatpropertyyouwanttoplotDropDown.Value=='   d^m/dx^ady^bdz^c (N)'

                N=app.d2Nbydz2C2;
                Order=app.Order_Local(SFm,5:7);

                title(app.UIAxes,sprintf('d^%dN%d/dx^%ddy^%ddz^%d',sum(Order),SFm,Order(1),Order(2),Order(3)));
                app.TextArea_2.Value=sprintf('\r\n Diff times=%d \r\n d^%dN%d/dx^%ddy^%ddz^%d=%s\r\n',sum(Order),sum(Order),SFm,Order(1),Order(2),Order(3),string(N(app.SFm_C)));


                % app.SFm_C=1;
                % app.Spinner.Value=1;
                % app.Spinner.Enable=0;
                Case=1;
            end


            % Correction
            for i=1:length(SFm)
                if SFm(i)>length(P)
                    SFm(i)=length(P);
                end
            end
            

            S=size(P);
            L=length(SFm);
            
            %% Check what type of Element
            if  Type=='B'            
                
                if app.Peels_C==2
                    app.PeelsSpinner.Value=15;
                    app.Peels_C=15;
                    Peels=app.Peels_C;
                    no=Peels;
                end
                
                
                X=zeros(no,no,no,1); Y=X;Z=X;
                [X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1)]=meshgrid(linspace(min(P(:,1)),max(P(:,1)),no),linspace(min(P(:,2)),max(P(:,2)),no),linspace(min(P(:,3)),max(P(:,3)),no));
                
                Dom=1;
            
            elseif Type=='T'
                
                
                Dom=1;
                
                % Get Grid for Tetragonal element
                    xlx=linspace(0,1,no);
                    XL=zeros(no,no); YL=XL;
                    for i=1:no
                        XL(:,i)=linspace(xlx(i),0,no);
                        YL(:,i)=linspace(0,xlx(i),no);
                    end
            
                    z_Cal=linspace(0,1,no);
                    X=zeros(no,no,no); Y=X;Z=X;
                    for i=1:no
                       X(:,:,i)=-z_Cal(i).*XL+XL;
                       Y(:,:,i)=-z_Cal(i).*YL+YL;
                       Z(:,:,i)=XL*0+z_Cal(i);
                    end
                

            else % If Element Geometry is not standard, Use triangulation

                % Triangulate
                DT = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                CL=DT.ConnectivityList; 
                CL=unique(CL(:,1:4),'rows');
                

                    %% Correction
                    CL_P=CL;
                    CL=zeros(1,4);
                    V=CL_P(:,1)*0;
                    for i=1:size(CL_P,1)
                       
                        a=[P(CL_P(i,1),1) P(CL_P(i,1),2) P(CL_P(i,1),3)];
                        b=[P(CL_P(i,2),1) P(CL_P(i,2),2) P(CL_P(i,2),3)];
                        c=[P(CL_P(i,3),1) P(CL_P(i,3),2) P(CL_P(i,3),3)];
                        d=[P(CL_P(i,4),1) P(CL_P(i,4),2) P(CL_P(i,4),3)];
                        
                        V(i)=abs((a-d)*(cross((b-d),(c-d)))');
                        
                    end
                    
                    VM=mean(V);
                    count=1;
                    for i=1:length(V)
                        if V(i)>VM*app.Threshold
                           CL(count,1:4)=CL_P(i,1:4);
                           count=count+1;
                        end
                    end
                    
                   Dom=size(CL,1);


                    % no=round(no/size(CL,1));
                    if no<2
                        no=2;
                    elseif no>30
                        no=50;
                        app.PeelsSpinner.Value=30;
                    end
                    
                    
                    % Get Grid for Tetragonal element
                    xlx=linspace(0,1,no);
                    XL=zeros(no,no); YL=XL;
                    for i=1:no
                        XL(:,i)=linspace(xlx(i),0,no);
                        YL(:,i)=linspace(0,xlx(i),no);
                    end
            
                    z_Cal=linspace(0,1,no);
                    X_t=zeros(no,no,no); Y_t=X_t;Z_t=X_t;
                    for i=1:no
                       X_t(:,:,i)=-z_Cal(i).*XL+XL;
                       Y_t(:,:,i)=-z_Cal(i).*YL+YL;
                       Z_t(:,:,i)=XL*0+z_Cal(i);
                    end
              
            
                Siz=size(CL);
                X=zeros(no,no,no,Siz(1));Y=X;Z=X;
                
                for D=1:Siz(1)
                    for i=1:no
                        for j=1:no
                            for k=1:no
            
                                % Tetrahedral element shape function
                                N_S=[(1-Y_t(i,j,k)-Z_t(i,j,k)-X_t(i,j,k)) X_t(i,j,k) Y_t(i,j,k) Z_t(i,j,k)];
            
                                X(i,j,k,D)=N_S*P(CL(D,:),1);
                                Y(i,j,k,D)=N_S*P(CL(D,:),2);
                                Z(i,j,k,D)=N_S*P(CL(D,:),3);
                                
                            end
                        end
                    end

                end
                
                Dom=Siz(1);

                app.TextArea.Value{7}=sprintf('Element type Unknown! Number of tetrahedrons from delaunay triangulation=%d',Dom);
                  
            end


            %% Extract Shape function data for each point
            NC=sym(zeros(length(SFm),1));
            for i=1:length(SFm)
                NC(i,1)=N(SFm(i));
            end
            
            x=sym('x');
            y=sym('y');
            z=sym('z');
            
            N_d=matlabFunction(NC,'vars',[x y z]);
            
            K=size(X);
            n=zeros(K(1),K(2),K(3),L);


            for D=1:Dom
                sn=1;
                for s=1:length(SFm) % For all shape functions
                    for i=1:K(1)
                        for j=1:K(2)
                            for k=1:K(3)
                                
                                R=N_d(X(i,j,k,D),Y(i,j,k,D),Z(i,j,k,D));
            
                                n(i,j,k,1:L,D)=R;%(s,1);
            
                            end
                        end
                    end
                    sn=sn+1;
                end
                
            end
            
            
            %% Printing all the shape functions
            if SFm>0
                List=SFm;
            else
                List=1:L;
            end
                
            
            % n=Sum;
            
            for i=1:L
            
                if length(SFm)==1 && SFm==0
                    break
                end
            
                
                hold(app.UIFigure.CurrentAxes,'on');

                    Sz=gobjects(Dom,no);
                    Sy=gobjects(Dom,no);
                    Sx=gobjects(Dom,no);
                
           
                if no*Dom>50 && no>4
                    app.FillSpinner.Value=0;
                    app.TextArea.Value{9}=sprintf('Too many surfaces (%d) to plot!\n',no*Dom);
                end
                    
                if Type=="U" || Type=='T'
                                     
                    for D=1:Dom
                        
                        if app.FillSpinner.Value>0
                        for m=1:no
                            Sz(D,m)=surf(app.UIAxes,squeeze(X(m,:,:,D)),squeeze(Y(m,:,:,D)),squeeze(Z(m,:,:,D)),squeeze(n(m,:,:,i,D)));
                            Sy(D,m)=surf(app.UIAxes,squeeze(X(:,m,:,D)),squeeze(Y(:,m,:,D)),squeeze(Z(:,m,:,D)),squeeze(n(:,m,:,i,D)));
                            Sx(D,m)=surf(app.UIAxes,squeeze(X(:,:,m,D)),squeeze(Y(:,:,m,D)),squeeze(Z(:,:,m,D)),squeeze(n(:,:,m,i,D)));
                        end
                        end
                        
                        for q=setdiff(linspace(min(min(min(n(:,:,:,i,D)))),max(max(max(n(:,:,:,i,D)))),Peels),0)
                            fv=isosurface(X(:,:,:,D),Y(:,:,:,D),Z(:,:,:,D),n(:,:,:,i,D),q);
                            hIsosurfU=patch(app.UIAxes,fv);
                            set(hIsosurfU,'CData',q,'FaceColor','flat','EdgeColor','none','FaceAlpha',0.4);
                        end

                    end
                    
                end
                
                if Type=='T'
%                     Pks=[0 0 0;0 1 0;1 0 0;0 0 0];
%                     plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
%                     
%                     Pks=[0 0 0; 0 0 1; 1 0 0];
%                     plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
%                     
%                     Pks=[0 0 1; 0 1 0];
%                     plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                end

                
                if Type=='B'
            %          hSlice=slice(X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1),n(:,:,:,i,1),[linspace(min(min(min(X))),max(max(max(X))),no)],[linspace(min(min(min(Y))),max(max(max(Y))),no)],[linspace(min(min(min(Z))),max(max(max(Z))),no)]);
            %          set(hSlice,'FaceColor','interp','FaceAlpha',0.1,'EdgeColor','none');
                    if app.FillSpinner.Value>0
                     app.hSlice=slice(app.UIAxes,X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1),n(:,:,:,i,1),[linspace(min(min(min(X))),max(max(max(X))),2)],[linspace(min(min(min(Y))),max(max(max(Y))),2)],[linspace(min(min(min(Z))),max(max(max(Z))),2)]);
                     set(app.hSlice,'FaceColor','interp','FaceAlpha',0.1,'EdgeColor','none');
                    end
                     % set(hSlice,'FaceColor','interp','FaceAlpha',0.5,'EdgeColor','none');
                     
                    for q=setdiff(linspace(min(min(min(n(:,:,:,i,1)))),max(max(max(n(:,:,:,i,1)))),Peels),0)
                        fv=isosurface(X(:,:,:,1),Y(:,:,:,1),Z(:,:,:,1),n(:,:,:,i,1),q);
                        hIsosurfU=patch(app.UIAxes,fv);
                        set(hIsosurfU,'CData',q,'FaceColor','flat','EdgeColor','none','FaceAlpha',0.4);
                    end

                    %qS=setdiff(linspace(min(min(min(n(:,:,:,i,D)))),max(max(max(n(:,:,:,i,D)))),Peels),0);
                    
                    
                end


               if Type=='B'

                    Translation=[mean(P(:,1)) mean(P(:,2)) mean(P(:,3))];

                    Pks=[-1 -1 -1;-1 1 -1; 1 1 -1; 1 -1 -1;-1 -1 -1]+Translation;
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[-1 -1 1;-1 1 1; 1 1 1; 1 -1 1;-1 -1 1]+Translation;
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[-1 -1 -1;-1 -1 1]+Translation;
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[1 1 -1;1 1 1]+Translation;
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[-1 1 -1;-1 1 1]+Translation;                
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')
                    
                    Pks=[1 -1 -1;1 -1 1]+Translation;                  
                    plot3(app.UIAxes,Pks(:,1),Pks(:,2),Pks(:,3),'Linewidth',1,'Color','k')


                end
                
                 
                 if app.NodesCheckBox.Value==1
                     app.SNP=scatter3(app.UIAxes,P(:,1),P(:,2),P(:,3),'d','MarkerEdgeColor','r','LineWidth',1);
                 end
                 
%                  if app.IntegrationpointsCheckBox.Value==1
%                      app.SGP=gobjects(size(app.INTP,1));
%                      for d=1:size(app.INTP,1)
%                         
%                          app.SGP(d)=scatter3(app.UIAxes,app.INTP(d,1),app.INTP(d,2),app.INTP(d,3),'d','MarkerEdgeColor','r','LineWidth',1);
%                         
%                      end
%                 end
                 
                Min=min(min(min(min(min(n)))));
                Max=max(max(max(max(max(n)))));
                
                if min(min(min(min(min(n)))))>=max(max(max(max(max(n)))))
                    Min=Min-0.1;
                    Max=Max+0.1;
                end
                    
                if Case==10 || Case==11
                 %Colors=linspace(Min,Max,app.PeelsSpinner.Value);
                 c = colorbar(app.UIAxes);
                 colormap(app.UIAxes, jet);
                 caxis(app.UIAxes,[Min  Max]);
                 % c.Label.String = 'Shape function value';
                 axis(app.UIAxes, 'tight');
                else
                 % Colors=[floor(Min):1:ceil(Max)];
                 Colors=setdiff(linspace(Min,Max,app.PeelsSpinner.Value+1),0);
                 % c = colorbar(app.UIAxes,'XTick',Colors);
                 c = colorbar(app.UIAxes);
                 colormap(app.UIAxes, jet(length(Colors)))
                 % caxis(app.UIAxes,[floor(Min) ceil(Max)]);
                 caxis(app.UIAxes,[Min Max]);
                 
                 % c.Label.String = 'Shape function value';
                 axis(app.UIAxes, 'tight');

                end
                 
                % view(app.UIAxes,45,45)
                daspect(app.UIAxes,[1 1 1])
                rotate3d(app.UIFigure.CurrentAxes,'on');

                
            end
            
            app.Type_C=Type;
            app.Sx_C=Sx;
            app.Sy_C=Sy;
            app.Sz_C=Sz;
            
            app.Dom_C=Dom;
            
            % Verify_Shape_Functions_Surface(app);


        end

        
        function Graphics_Manipulator(app)
                          
                % size(app.Sz_C)

                hold(app.UIFigure.CurrentAxes,'on');

                FaceValue=app.FillSpinner.Value;
                
                
                if app.Type_C=='U' || app.Type_C=='T'
                    
                    % if FaceValue>0
                        
                    try
                        S=size(app.Sz_C);
                        
                        for D=1:S(1)
                            for m=1:S(2)
     
                                set(app.Sz_C(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sz_C(D,m).EdgeColor='none';
                                else
                                    app.Sz_C(D,m).EdgeColor='k';
                                end
                                
                                
                                set(app.Sy_C(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sy_C(D,m).EdgeColor='none';
                                else
                                    app.Sy_C(D,m).EdgeColor='k';
                                end
                                
                                set(app.Sx_C(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sx_C(D,m).EdgeColor='none';
                                else
                                    app.Sx_C(D,m).EdgeColor='k';
                                end
 
                            end
                        end

                    catch
                        
                        % FaceValue=1;
                        Verify_Shape_Functions(app);
                        S=size(app.Sz_C);
                        
                        % size(app.Sz_C)

                        try
                        for D=1:S(1)
                            for m=1:S(2)
     
                                set(app.Sz_C(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sz_C(D,m).EdgeColor='none';
                                else
                                    app.Sz_C(D,m).EdgeColor='k';
                                end
                                
                                
                                set(app.Sy_C(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sy_C(D,m).EdgeColor='none';
                                else
                                    app.Sy_C(D,m).EdgeColor='k';
                                end
                                
                                set(app.Sx_C(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sx_C(D,m).EdgeColor='none';
                                else
                                    app.Sx_C(D,m).EdgeColor='k';
                                end
 
                            end
                        end
                        catch
                        end
                        
                    end
                    % end
                    
%                     if FaceValue==0
%                         
%                         try 
%                             delete(app.Sx_C)
%                             delete(app.Sy_C)
%                             delete(app.Sz_C)
%                         catch 
%                             app.Sx_C=[];
%                             app.Sy_C=[];
%                             app.Sz_C=[];
%                         end
%                         
%                         
%                     end
                    
                end
                
                if app.Type_C=='B'
                    
                    if FaceValue==0
                        try 
                            delete(app.hSlice)
                        catch 
                            app.hSlice=[];
                        end
                    else
                        try
                            set(app.hSlice,'FaceColor','interp','FaceAlpha',app.FillSpinner.Value);
                        catch
                            Verify_Shape_Functions(app);
                            set(app.hSlice,'FaceColor','interp','FaceAlpha',app.FillSpinner.Value);
                        end
                        
                    end
                        
                    
                    if app.EdgesCheckBox.Value==0 && FaceValue>0              
                        set(app.hSlice,'FaceColor','interp','EdgeColor','none');
                    elseif app.EdgesCheckBox.Value==1 && FaceValue>0
                        set(app.hSlice,'FaceColor','interp','EdgeColor','k');
                    end
                end
                
                
                app.TextArea.Value{9}=sprintf('Done! Enjoy the plot!\n');

                daspect(app.UIAxes,[1 1 1]);
                % daspect([1 2 2])
                
            end
            

        function Graphics_Manipulator_2(app)
                          
                % size(app.Sz_C)

                hold(app.UIFigure.CurrentAxes,'on');

                FaceValue=app.FillSpinner.Value;
                
                
                if app.Type_C_2=='U' || app.Type_C_2=='T'
                    
                    % if FaceValue>0
                        
                    try
                        S=size(app.Sz_C_2);
                        
                        for D=1:S(1)
                            for m=1:S(2)
     
                                set(app.Sz_C_2(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sz_C_2(D,m).EdgeColor='none';
                                else
                                    app.Sz_C_2(D,m).EdgeColor='k';
                                end
                                
                                
                                set(app.Sy_C_2(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sy_C_2(D,m).EdgeColor='none';
                                else
                                    app.Sy_C_2(D,m).EdgeColor='k';
                                end
                                
                                set(app.Sx_C_2(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sx_C_2(D,m).EdgeColor='none';
                                else
                                    app.Sx_C_2(D,m).EdgeColor='k';
                                end
 
                            end
                        end

                    catch
                        
                        % FaceValue=1;
                        Verify_Shape_Functions_2(app);
                        S=size(app.Sz_C_2);
                        
                        % size(app.Sz_C)

                        try
                        for D=1:S(1)
                            for m=1:S(2)
     
                                set(app.Sz_C_2(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sz_C_2(D,m).EdgeColor='none';
                                else
                                    app.Sz_C_2(D,m).EdgeColor='k';
                                end
                                
                                
                                set(app.Sy_C_2(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sy_C_2(D,m).EdgeColor='none';
                                else
                                    app.Sy_C_2(D,m).EdgeColor='k';
                                end
                                
                                set(app.Sx_C_2(D,m),'FaceColor','interp','FaceAlpha',FaceValue);
                                if app.EdgesCheckBox.Value==0
                                    app.Sx_C_2(D,m).EdgeColor='none';
                                else
                                    app.Sx_C_2(D,m).EdgeColor='k';
                                end
 
                            end
                        end
                        catch
                        end
                        
                    end
                    % end
                    
%                     if FaceValue==0
%                         
%                         try 
%                             delete(app.Sx_C)
%                             delete(app.Sy_C)
%                             delete(app.Sz_C)
%                         catch 
%                             app.Sx_C=[];
%                             app.Sy_C=[];
%                             app.Sz_C=[];
%                         end
%                         
%                         
%                     end
                    
                end
                
                if app.Type_C_2=='B'
                    
                    if FaceValue==0
                        try 
                            delete(app.hSlice2)
                        catch 
                            app.hSlice2=[];
                        end
                    else
                        try
                            set(app.hSlice2,'FaceColor','interp','FaceAlpha',app.FillSpinner.Value);
                        catch
                            Verify_Shape_Functions_2(app);
                            set(app.hSlice2,'FaceColor','interp','FaceAlpha',app.FillSpinner.Value);
                        end
                        
                    end
                        
                    
                    if app.EdgesCheckBox.Value==0 && FaceValue>0              
                        set(app.hSlice2,'FaceColor','interp','EdgeColor','none');
                    elseif app.EdgesCheckBox.Value==1 && FaceValue>0
                        set(app.hSlice2,'FaceColor','interp','EdgeColor','k');
                    end
                end
                
                
                app.TextArea.Value{9}=sprintf('Done! Enjoy the plot!\n');

                daspect(app.UIAxes,[1 1 1]);
                % daspect([1 2 2])
                
            end

        %------------------------------------------------------------------------------
        function[app]=Shape_Function_Formulation_3D(app)
            
        u=app.u_C;
        P=app.P_C;
            

        x=sym('x');
        y=sym('y');
        z=sym('z');
        
        Coeff=zeros(1,length(u));
        Coeff=sym(Coeff);
        for i=1:length(Coeff)
            Coeff(i)=sym (sprintf('c%d',i));
        end
        u=Coeff*transpose(u);
        
        uO=u;
        

        Vec=zeros(1,length(u));
        Vec=sym(Vec);
        for i=1:length(Coeff)
            Vec(i)=sym (sprintf('U%d\n',i));
        end
             
        
        S=size(P);
        if length(Coeff)~=S(1)
            app.TextArea.Value{5}=sprintf('Size Mismatch! There are %d Coefficients in interpolation function and %d Node coordinates!\n',length(Coeff),S(1));
            app.TextArea.FontColor='r';
            error
        end
        
        %% Substitute x y z with coordinates
        
        Uall=transpose(Coeff);
        for i=1:length(Coeff)   % For all Input coefficients 
            
            % Substitute the input Equation with input Coordinates  
                uD=subs(u,[x,y,z],P(i,:));  
            
            % Store all the substituted Equations
                Uall(i)=uD;
        end
        
        
        %% Isolate the Coefficients to obtain a Matrix "M"
            L=size(Uall);
            M=zeros(L(1),L(1));
            for i=1:L(1)
                try
                    eq=Uall(i,:);
            
                    [CiN, Ci] = coeffs(eq, Coeff);
                    Ivar = setdiff(Coeff, Ci);
                    Ci2 = [CiN, zeros(1, length(Ivar))];
                    CiN2 = [Ci, Ivar];
                    [~, mS] = sort(CiN2);
                    RowV = Ci2(mS);
                    M(i,:)=RowV;
                catch
                    app.TextArea.Value{5}=sprintf('Hmm... Something is wrong! Check the function (x*y, not xy. Do not use any variable except x y z) and node coordinates\n');
                    app.TextArea.FontColor='r';
                    error
                end
            end
          
        
        if length(M)~=length(Coeff)
            %fprintf('Displaying the Matrix...\n');
            app.TextArea.Value{5}=sprintf('The interpolation function is not properly defined, Check\n');
            app.TextArea.FontColor='r';
            error
        end
            
            
        % Check for Sigularity
        if abs(det(M))<10^-10 && rank(M)==length(M)
            app.TextArea.Value{5}=sprintf('Warning! Close to singular Matrix! Determinant=%f,  Rank=%d out of %d\n',det(M),rank(M),length(M));
        end    
        if rank(M)~=length(M)
            app.TextArea.Value{5}=sprintf('Singular Matrix! The given interpolation function can not generate Unique result. Change interpolation function or nodal coordinates!');
            app.TextArea.Value{6}=sprintf('Determinant=%f,  Rank=%d out of %d\n',det(M),rank(M),length(M));
            app.TextArea.FontColor='r';
            error
        else 
            app.TextArea.Value{5}=sprintf('The interpolation equation can generate Unique result');
            app.TextArea.Value{6}=sprintf('Successful! Determinant=%f,  Rank=%d out of %d.\n',det(M),rank(M),length(M));
        end
        
        
        %% Determine the Coefficients in terms of unknown vector Uv
        
            IM=inv(M);
            Uv=transpose(Vec);
         
            C=IM*Uv;    
        
            
        %% Get the Shape Functions by isolating the Uv vector components    
        
            U=subs(u,Coeff,transpose(C));
        
            [CiM, Ci] = coeffs(U, Vec);
            Ivar = setdiff(Vec, Ci);
            Ci2 = [CiM, zeros(1, length(Ivar))];
            Ti2 = [Ci, Ivar];
            [~, mS] = sort(Ti2);
            scxy = Ci2(mS);
            
               
        N=transpose(scxy);
        fprintf('Shape Functions-----------------------\n N=');
        
        if length(N)<=30
            fprintf('\n');
            disp(N);
        else
            fprintf('.....\nToo many shape functions. Check for the saved data\n')
        end
        

        %% First derivatives
        app.dNbydx=diff(N,x,1);
        app.dNbydy=diff(N,y,1);
        app.dNbydz=diff(N,z,1);
        
        % fprintf('First derivatives----------------------\n dN/dx=\n');
        % disp(dNbydx);
        % 
        % fprintf('dN/dy=\n');
        % disp(dNbydy);
        % 
        % fprintf('dN/dz=\n');
        % disp(dNbydz);
        
        
        % Function_Analyzer_Order_Getter(app);
        
%         app.X_Order=2;
%         app.Y_Order=2;_or
%         app.Z_Order=2;
        
        %% Second derivatives
        app.d2Nbydx2=N;
        app.d2Nbydy2=N;
        app.d2Nbydz2=N;
        app.d2Nbydz2C=N;
        app.d2Nbydz2C2=N;

        if length(Coeff)>20
            app.Order_Local=zeros(length(Coeff),7);
        else
            app.Order_Local=zeros(20,7);
        end


            for i=1:length(Coeff)
    
                S5=sprintf('Generating shape functions......Getting differentiability up to 0 of %d no Shape function',i);
                app.TextArea.Value{5}=S5;
                pause (0.0001)
    
                uL=N(i);
                PL=P(i,:);

                [X_Order_L,Y_Order_L,Z_Order_L]=Function_Analyzer_Order_Getter_Local_Basic(app,uL,PL);
                app.Order_Local(i,1:3)=[X_Order_L,Y_Order_L,Z_Order_L];

                if app.CalculatederivetivesCheckBox.Value==1
                     % [Total_Order_a1a2a3,Total_Order_Max]=Function_Analyzer_Order_Getter_Local(app,uL,PL);
                     [Total_Order_a1a2a3,Total_Order_Max]=Function_Analyzer_Order_Getter_Local(app,u,PL,X_Order_L,Y_Order_L,Z_Order_L);
    
                    app.Order_Local(i,4:7)=[Total_Order_a1a2a3,Total_Order_Max];
                    app.d2Nbydx2(i)=diff(app.d2Nbydx2(i),x,X_Order_L);        
                    app.d2Nbydy2(i)=diff(app.d2Nbydy2(i),y,Y_Order_L);
                    app.d2Nbydz2(i)=diff(app.d2Nbydz2(i),z,Z_Order_L);
                    app.d2Nbydz2C(i)=diff(diff(diff(app.d2Nbydz2C(i),x,Total_Order_a1a2a3/3),y,Total_Order_a1a2a3/3),z,Total_Order_a1a2a3/3);
                    app.d2Nbydz2C2(i)=diff(diff(diff(app.d2Nbydz2C2(i),x,Total_Order_Max(1)),y,Total_Order_Max(2)),z,Total_Order_Max(3));
                end
    
            end

            app.X_Order=max(app.Order_Local(:,1));
            app.Y_Order=max(app.Order_Local(:,2));
            app.Z_Order=max(app.Order_Local(:,3));


        % app.Order_Local

        % app.d2Nbydz2C2
        
        app.N_C=N;
        app.u_Coeff=u;
        
        end


        function[X_Order_L,Y_Order_L,Z_Order_L]=Function_Analyzer_Order_Getter_Local_Basic(app,u,PL)

            x=sym('x');
            y=sym('y');
            z=sym('z');

            ux=subs(u,[y z],[PL(2) PL(3)]);
            uy=subs(u,[x z],[PL(1) PL(3)]);
            uz=subs(u,[x y],[PL(1) PL(2)]);
            
            
            Identifier=1;
            c=0;
            while Identifier>0
                X_Order_L=c;
                
                ux=diff(ux,x,1);
                if ux==0
                    Identifier=0;
                else
                    Identifier=1;
                end
                
                c=c+1;
            end
            
            
            %
            Identifier=1;
            c=0;
            while Identifier>0
                Y_Order_L=c;
                
                uy=diff(uy,y,1);
                if uy==0
                    Identifier=0;
                else
                    Identifier=1;
                end
                
                c=c+1;
            end
            
            
            Identifier=1;
            c=0;
            while Identifier>0
                Z_Order_L=c;
                
                uz=diff(uz,z,1);
                if uz==0
                    Identifier=0;
                else
                    Identifier=1;
                end
                
                c=c+1;
            end
        end

            
        function[Total_Order_a1a2a3,Total_Order_Max]=Function_Analyzer_Order_Getter_Local(app,u,PL,X_Order_L,Y_Order_L,Z_Order_L)
            
            x=sym('x');
            y=sym('y');
            z=sym('z');

            count=1;
            c=0;
            Tot_S=[0 0 0];
            Prev_K=0;
            for xd=X_Order_L:-1:0
                for yd=Y_Order_L:-1:0
                    for zd=Z_Order_L:-1:0

                            if xd+yd+zd>Prev_K && (((xd==yd) && (xd==zd) && (yd==zd)))==0
                                uD=diff(diff(diff(u,x,xd),y,yd),z,zd);
                                count=count+1;
    
                            try 
                                Dbl=double(uD);
                                if Dbl~=0
                                    Prev_K=xd+yd+zd;
                                    Tot_S=[xd yd zd];
                                    c=c+1;
                                end
                            catch
                                Prev_K=xd+yd+zd;
                                Tot_S=[xd yd zd];
                                c=c+1;
                            end  
                            end
                        
                    end
                end
            end
            Total_Order_Max=Tot_S;%Prev_K;


            count=1;
            c=0;
            Prev_K_Equal=0;
            for xd=X_Order_L:-1:0
                for yd=Y_Order_L:-1:0
                    for zd=Z_Order_L:-1:0

                        if (xd+yd+zd>Prev_K_Equal) && (xd==yd) && (xd==zd) && (yd==zd)
                            uD=diff(diff(diff(u,x,xd),y,yd),z,zd);
                            count=count+1;

                        try 
                            Dbl=double(uD);
                            if Dbl~=0
                                Prev_K_Equal=xd+yd+zd;
                                c=c+1;
                            end
                        catch
                            Prev_K_Equal=xd+yd+zd;
                            c=c+1;
                        end  
                        end
                        
                    end
                end
            end
            Total_Order_a1a2a3=Prev_K_Equal;
            
            
        end


        function Comparer(app)

            S5=sprintf('Comparing......');
            app.TextArea.Value{5}=S5;
            pause (0.01)

                % Clean up text box
                app.TextArea.Value{1}='';
                app.TextArea.Value{2}='';
                app.TextArea.Value{3}='';
                app.TextArea.Value{4}='';
                app.TextArea.Value{5}='';
                app.TextArea.Value{6}='';
                app.TextArea.Value{7}='';
                app.TextArea.Value{8}='';
                app.TextArea.Value{9}='';
                app.TextArea.Value{10}='';
                app.TextArea.Value{11}='';
                app.TextArea.Value{12}='';
                app.TextArea.Value{13}='';
                app.TextArea.Value{14}='';    
                app.TextArea_2.Value='';
                cla(app.UIAxes);
                app.IntegrationpointsCheckBox.Value=0;    


            S_Old = app.SurfacenoofpreviouselementSpinner.Value;
            S_New = app.SurfacenoofnewelementSpinner.Value;
          
            PC_New=app.V_CP_Sequence_S(:,:,S_New);
            PC_Old=app.V_CP_Sequence_S_2(:,:,S_Old);

            Vector_New=cross(PC_New(:,1),PC_New(:,2));
            Vector_Old=cross(PC_Old(:,1),PC_Old(:,2));

            Nodes_New=app.P_C;
            Nodes_Old=app.P_C_2;
            Nodes_Old_T=Nodes_Old*0;
            Nodes_Old_R=Nodes_Old*0;    

            Node_no_S_Old=setdiff(app.Surf_Nodes_2(:,S_Old),0);
            Node_no_S_New=setdiff(app.Surf_Nodes(:,S_New),0);

            % S_Nodes_Old=(Nodes_Old(Node_no_S_Old,:));
            S_Nodes_New=(Nodes_New(Node_no_S_New,:));


            S_Nodes_Centroid=[mean(S_Nodes_New(:,1)) mean(S_Nodes_New(:,2)) mean(S_Nodes_New(:,3))];

            if S_Old>1
                StartIP=sum(app.gp_per_Surfs_2(1:S_Old-1))+1;
            else
                StartIP=1;
            end
            RangeIP=app.gp_per_Surfs_2(S_Old);
            EndIP=StartIP+RangeIP-1;
            IPold=app.INTP_Surf_2(StartIP:EndIP,1:3);


            if S_New>1
                StartIP=sum(app.gp_per_Surfs(1:S_New-1))+1;
            else
                StartIP=1;
            end
            RangeIP=app.gp_per_Surfs(S_New);
            EndIP=StartIP+RangeIP-1;
            IPnew=app.INTP_Surf(StartIP:EndIP,1:3);


            Got_One=0;

            % while Got_One==0

            % Th=Th+2*dTh;
            
            % PC_New=app.V_CP_Sequence_S(:,:,S_New);
            % PC_Old=app.V_CP_Sequence_S_2(:,:,S_Old);

            % Rx=[1 0 0; 0 cosd(Th) -sind(Th); 0 sind(Th) cosd(Th)];

            Rz=[cosd(app.Th) -sind(app.Th) 0; sind(app.Th) cosd(app.Th) 0; 0 0 1];

            % PC_New(:,1)=Rx*PC_New(:,1);
            % PC_New(:,2)=Rx*PC_New(:,2);

            % V_N=-[Vector_New PC_New];            
            % V_O=[Vector_Old PC_Old];


            V_N=[PC_New(:,2) PC_New(:,1) -Vector_New ];
            V_O=[PC_Old Vector_Old];            

            % disp(Vector_New)
            % disp(Vector_Old)

            % TM=[PC_New(:,1) PC_New(:,2) Vector_New];

            %V_N
            %V_O
            TM=zeros(3);
            for i=1:3
                for j=1:3
                    TM(i,j)=V_N(:,i)'*V_O(:,j);
                end
            end
            
            TM=TM';


            Centroid_New=[0 0 0];
            for n=1:length(S_Nodes_New)
                Centroid_New=Centroid_New+S_Nodes_New(n,:);
            end
            Centroid_New=Centroid_New/length(S_Nodes_New);
            

            % Transformation
            for n=1:size(Nodes_Old,1)
                Nodes_Old_R(n,:)=(V_O'*(Nodes_Old(n,:)'))';
            end

            for n=1:size(Nodes_Old,1)
                Nodes_Old_R(n,:)=(Rz*(Nodes_Old_R(n,:)'))';
            end


            % Transformation
            for n=1:size(Nodes_Old,1)
                Nodes_Old_R(n,:)=(V_N*(Nodes_Old_R(n,:)'))';
            end

            
%             Rz=-V_N*Rz*V_N'
%             % Transformation
%             for n=1:size(Nodes_Old,1)
%                 Nodes_Old_R(n,:)=(Rz*(Nodes_Old_R(n,:)'))';
%             end

%             for n=1:size(Nodes_Old,1)
%                 Nodes_Old_R(n,:)=(TM'*(Nodes_Old(n,:)'))';
%             end


            % S_Nodes_Old=Nodes_Old_R(Node_no_S_Old,:);

%             V_N
%             V_O
%             TM
% 
%             Nodes_Old
%             Nodes_Old_R
%             Node_no_S_Old

            S_Nodes_Old=(Nodes_Old_R(Node_no_S_Old,:));
            %Nodes_Old_R
            Centroid_Old=[0 0 0];
            for n=1:length(S_Nodes_Old)
                Centroid_Old=Centroid_Old+S_Nodes_Old(n,:);
            end
            Centroid_Old=Centroid_Old/length(S_Nodes_Old);


            dCentroid=[Centroid_New-Centroid_Old];
            for n=1:size(Nodes_Old,1)
                Nodes_Old_T(n,:)=Nodes_Old_R(n,:)+dCentroid;           
            end
            S_Nodes_Old_T=(Nodes_Old_T(Node_no_S_Old,:));


            IPold_T=IPold*0;
            for n=1:size(IPold,1)
                IPold_T(n,:)=(TM*(IPold(n,:)'))';
                IPold_T(n,:)=IPold_T(n,:)+dCentroid;
            end

            %IPnew
            %IPold_T

            %disp(Nodes_Old)
            %disp(Nodes_Old_T)


            % Get common nodes

                % length(app.N_C_2)
                
                x=sym('x');
                y=sym('y');
                z=sym('z');
                
                Tol=app.ToleranceEditField.Value;

                % [Size,Seq]=min([length(app.N_C) length(app.N_C_2)]);

                % [Size,Seq]=min([length(S_Nodes_New) length(S_Nodes_Old)]);

                    %dCentroid
                    % TMxyz=TM*[x;y;z];

                    % TMxyz=V_O'*Rz*V_N*[x;y;z];

                    TMxyz=(V_N*Rz*V_O')'*[x;y;z];
                    
                    % TMxyz=V_O'*[x;y;z]
                    % TMxyz=Rz*TMxyz
                    % TMxyz=V_N*TMxyz

                    TMxyz=subs(TMxyz,[x,y,z],[x y z]-dCentroid);


                    N_C_2_Mapped=subs(app.N_C_2,[x,y,z],transpose(TMxyz));

                    %X=transpose(app.N_C_2)*Nodes_Old_T(:,1)
                    %Y=transpose(app.N_C_2)*Nodes_Old_T(:,2)
                    %Z=transpose(app.N_C_2)*Nodes_Old_T(:,3)
                    %N_C_2_Mapped=subs(app.N_C_2,[x,y,z],[X,Y,Z])

                    % For e1
                    DP=999;%zeros(length(S_Nodes_New),length(S_Nodes_Old_T));
                    N_C_31=app.N_C;

                    % S_Nodes_Old_T
                    for i=1:length(S_Nodes_New)
                        
                        P1=S_Nodes_New(i,:);

                        n1=app.Surf_Nodes(i,S_New);

                        for j=1:length(S_Nodes_Old_T)
    
                            P2=S_Nodes_Old_T(j,:);

                            n2=app.Surf_Nodes_2(j,S_Old);
    
                            % P1V=S_Nodes_Centroid-P1;
                            % P2V=S_Nodes_Centroid-P2;
                            % if DP>norm(P1-P2) && abs(norm(P1V)-norm(P2V))<Tol
                            %     DP=norm(P1-P2);
                            %     CPP1P2=cross(P1V,P2V);
                            %     Sign=sign(CPP1P2*V_N(:,1));
                            %     Th=asind(norm(CPP1P2)/(norm(P1V)*norm(P2V)))*Sign;
                                 %%DPV=P1-P2;
                            % end

                            
                            if norm(P1-P2)<Tol
                                N_C_31(n1)=app.N_C(n1)-N_C_2_Mapped(n2);%app.N_C_2(n2);
                                Got_One=1;
                                break
                            % else
                                % N_C_3(i)=app.N_C(n1);
                            end
                        end
                    end  


                    % 455

                    % DPMin=min(min(DP));

            % end


                   % For e2
                    N_C_32=N_C_2_Mapped;
                    for i=1:length(S_Nodes_Old_T)
                        
                        % P1=S_Nodes_Old_T(i,:);

                        P1=S_Nodes_Old_T(i,:);

                        n1=app.Surf_Nodes_2(i,S_Old);

                        for j=1:length(S_Nodes_New)
    
                            P2=S_Nodes_New(j,:);
                            n2=app.Surf_Nodes(j,S_New);
    
                            if norm(P1-P2)<Tol
                                N_C_32(n1)=N_C_2_Mapped(n1)-app.N_C(n2);
                                break
                            end
                        end
                    end  


                    N_C_2_Mapped
                    % N_C_32
                    % N_C_31


                    % Check at all IP

                        LN=size(IPnew,1);
                        LO=size(IPold_T,1);

                        SUM1=0;
                        SUM2=0;

                        %if LN>LO

                            for i=1:LN
                                xd=IPnew(i,1);
                                yd=IPnew(i,2);
                                zd=IPnew(i,3);
                                Shape_Det1=double(subs(N_C_31,[x,y,z],[xd,yd,zd]));
                                Shape_Det2=double(subs(N_C_32,[x,y,z],[xd,yd,zd]));


                                % N_C_32

                                SUM1=SUM1+sum(Shape_Det1.^2);
                                SUM2=SUM2+sum(Shape_Det2.^2);

                            end


                        if abs(SUM1)+abs(SUM2)<10^-5
                            fprintf('Compatible!')
                            app.Compatible_Cq.Text='  Compatible';
                            app.Lamp.Color=[0 1 0];

                        else

                            app.Compatible_Cq.Text='Incompatible';
                            app.Lamp.Color=[1 0 0];                            
                            N_C_2_Mapped

                            SUM1
                            SUM2
                        end


                        %end


%             app.INTP_Surf=INTP_LS;
%             app.W_Surf=W_LS;
%             app.gp_per_Surfs=gp_Surfs;                      

                Verify_Shape_Functions(app);
                Graphics_Manipulator(app);

                if mod(app.Th,90)~=0
                    app.Type_C_2='U';
                end
                app.N_C_2_Map=N_C_2_Mapped;
                app.P_C_2_Map=Nodes_Old_T;
                Verify_Shape_Functions_2(app);
                Graphics_Manipulator_2(app);


                % app.WhatpropertyyouwanttoplotDropDown.Items = {'Shape function diff, dN'};
                                                               
            S5=sprintf('Comparing...... Done!');
            app.TextArea.Value{5}=S5;
            pause (0.01)


        end      


        function Extra_Plotter(app)


                try 
                    delete(app.SNP1);
                    delete(app.SNP2);
                    delete(app.Tri1);
                    delete(app.Tri2);
                    delete(app.Tri1_1);
                    delete(app.Tri2_1);
                catch
                end
            
            S_Old = app.SurfacenoofpreviouselementSpinner.Value;
            S_New = app.SurfacenoofnewelementSpinner.Value;
          
            PC_New=app.V_CP_Sequence_S(:,:,S_New);
            PC_Old=app.V_CP_Sequence_S_2(:,:,S_Old);

            Vector_New=cross(PC_New(:,1),PC_New(:,2));
            Vector_Old=cross(PC_Old(:,1),PC_Old(:,2));

            Nodes_New=app.P_C;
            Nodes_Old=app.P_C_2;
            Nodes_Old_T=Nodes_Old*0;
            Nodes_Old_R=Nodes_Old*0;    

            Node_no_S_Old=setdiff(app.Surf_Nodes_2(:,S_Old),0);
            Node_no_S_New=setdiff(app.Surf_Nodes(:,S_New),0);

            % S_Nodes_Old=(Nodes_Old(Node_no_S_Old,:));
            S_Nodes_New=(Nodes_New(Node_no_S_New,:));


            cla(app.UIAxes2_2);
            cla(app.UIAxes2);
                 

            NLO=Node_no_S_Old;
            NLN=Node_no_S_New;

            
            S_Nodes_Old=(Nodes_Old(Node_no_S_Old,:));
            %S_Nodes_New=(Nodes_New(Node_no_S_New,:));


                 hold(app.UIAxes2_2,'on')
                 app.SNP1=scatter3(app.UIAxes2_2,app.P_C_2(:,1),app.P_C_2(:,2),app.P_C_2(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');                    
                 DT = delaunayTriangulation(app.P_C_2(:,1),app.P_C_2(:,2),app.P_C_2(:,3));
                 [K] = convexHull(DT);
                 app.Tri2=trisurf(K,DT.Points(:,1),DT.Points(:,2),DT.Points(:,3),'EdgeColor','k','FaceColor','c','FaceAlpha',0.1,'Parent', app.UIAxes2_2);


                 L=length(K(:,1));
                 for SN=1:L
                     Y=0;
                     xS=[0;0;0]; yS=[0;0;0]; zS=[0;0;0]; 
                     for n=1:3
                         for i=1:length(NLO)
                             if NLO(i)==K(SN,n)
                                Y=Y+1;

                                xS(n)=Nodes_Old(NLO(i),1);
                                yS(n)=Nodes_Old(NLO(i),2);
                                zS(n)=Nodes_Old(NLO(i),3);

                                break
                             end
                         end
                     end
                     % Y
                     if Y==3
                        app.Tri2_1(SN)=patch(app.UIAxes2_2,xS,yS,zS,zS*0,'EdgeColor','k','FaceColor','c','FaceAlpha',0.8);
                     end
                 end
                 daspect(app.UIAxes2_2,[1 1 1])
                 view(app.UIAxes2_2,45,45)
                 % rotate3d(app.UIAxes2_2,'on');
                 title(app.UIAxes2_2,sprintf('Nodes=%d',length(app.P_C_2)));


                 hold(app.UIAxes2,'on')
                 app.SNP2=scatter3(app.UIAxes2,app.P_C(:,1),app.P_C(:,2),app.P_C(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');
                 DT = delaunayTriangulation(app.P_C(:,1),app.P_C(:,2),app.P_C(:,3));
                 [K] = convexHull(DT);
                 app.Tri1=trisurf(K,DT.Points(:,1),DT.Points(:,2),DT.Points(:,3),'EdgeColor','k','FaceColor','c','FaceAlpha',0.1,'Parent', app.UIAxes2);
                 daspect(app.UIAxes2,[1 1 1])
                 view(app.UIAxes2,45,45)
                 title(app.UIAxes2,sprintf('Nodes=%d',length(app.P_C))); 


                 L=length(K(:,1));
                 for SN=1:L
                     Y=0;
                     xS=[0;0;0]; yS=[0;0;0]; zS=[0;0;0]; 
                     for n=1:3
                         for i=1:length(NLN)
                             if NLN(i)==K(SN,n)
                                Y=Y+1;

                                xS(n)=Nodes_New(NLN(i),1);
                                yS(n)=Nodes_New(NLN(i),2);
                                zS(n)=Nodes_New(NLN(i),3);

                                break
                             end
                         end
                     end
                     % Y
                     if Y==3
                        app.Tri1_1(SN)=patch(app.UIAxes2,xS,yS,zS,zS*0,'EdgeColor','k','FaceColor','c','FaceAlpha',0.8);
                     end
                 end    
            
        end


        function Pre_Comparer(app)

                % E1 ------------------------------- 
                Surf_NodesT=zeros(1,6); % Prealocate
                
                s_prev=1;
                for s=2:size(app.P_C_Surf,1)
                    if app.P_C_Surf(s,5)>app.P_C_Surf(s-1,5) || s==size(app.P_C_Surf,1)

                        if s==size(app.P_C_Surf,1)
                            s_min=s_prev;
                            s_max=s;
                        else
                            s_min=s_prev;
                            s_max=s-1;
                        end


                        NdS=app.P_C_Surf(s_min:s_max,1);
                        NdSS=unique(NdS);
                        
                        Surf_Nodes_L=zeros(length(NdSS),1);
                        for c=1:length(NdSS)
                           Surf_Nodes_L(c,1)=NdSS(c);   
                           
                        end   
                        Surf_NodesT(1:c,app.P_C_Surf(s_max,5))=Surf_Nodes_L; %\r\n',c,app.P_C_Surf(s_max,5));
                        s_prev=s_max+1;

                    end
                end

                app.Surf_Nodes=Surf_NodesT;
                
                % E2-------------------------------------
                Surf_Nodes_2T=zeros(1,6); % Prealocate
                
                s_prev=1;
                for s=2:size(app.P_C_Surf_2,1)
                    if app.P_C_Surf_2(s,5)>app.P_C_Surf_2(s-1,5) || s==size(app.P_C_Surf_2,1)

                        if s==size(app.P_C_Surf_2,1)
                            s_min=s_prev;
                            s_max=s;
                        else
                            s_min=s_prev;
                            s_max=s-1;
                        end


                        NdS=app.P_C_Surf_2(s_min:s_max,1);
                        NdSS=unique(NdS);
                        
                        Surf_Nodes_L=zeros(length(NdSS),1);
                        for c=1:length(NdSS)
                           Surf_Nodes_L(c,1)=NdSS(c);   
                           
                        end   
                        Surf_Nodes_2T(1:c,app.P_C_Surf_2(s_max,5))=Surf_Nodes_L; %\r\n',c,app.P_C_Surf(s_max,5));
                        s_prev=s_max+1;

                    end
                end

                app.Surf_Nodes_2=Surf_Nodes_2T;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PolynomialorderalongxSlider
        function PolynomialorderalongxSliderValueChanged(app, event)
            value = app.PolynomialorderalongxSlider.Value;
            app.PolynomialorderalongxSlider.Value=round(value);    
        end

        % Value changed function: PolynomialorderalongySlider
        function PolynomialorderalongySliderValueChanged(app, event)
            value = app.PolynomialorderalongySlider.Value;
            app.PolynomialorderalongySlider.Value=round(value);
        end

        % Value changed function: PolynomialorderalongzSlider
        function PolynomialorderalongzSliderValueChanged(app, event)
            value = app.PolynomialorderalongzSlider.Value;
            app.PolynomialorderalongzSlider.Value=round(value);
        end

        % Selection changed function: ChoiceofelementButtonGroup
        function ChoiceofelementButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoiceofelementButtonGroup.SelectedObject;

            app.DonotguessCheckBox.Value=0;
            % Set for hexahedron
            if app.HexahedronorBrickelementButton.Value==1
                app.PolynomialorderalongxSlider.Visible=1;
                app.PolynomialorderalongySlider.Visible=1;
                app.PolynomialorderalongzSlider.Visible=1;
                app.PolynomialDegreealongxLabel.Visible=1;
                app.PolynomialDegreealongyLabel.Visible=1;
                app.PolynomialDegreealongzLabel.Visible=1;
                app.LagrangianFamilyCheckBox.Visible=1;
                % app.HowtouseHexelementButton.Visible=1;
                app.PeelsSpinner.Value=15;
                app.OrderLimitSpinner_2.Visible=1;
                app.Order_Limit_Label.Visible=1;
                
                % app.DonotguessCheckBox.Visible=0;
                
                app.IntegrationorderSpinner.Visible=0;
                app.IntegrationorderforreferencetetrahedronLabel.Visible=0;

                app.DonotguessCheckBox.Value=0;

                %app.IntegrationorderforreferencetetrahedronLabel.Visible=1;
                app.IntegrationquadratureDropDown.Visible=1;
                app.IntegrationquadratureLabel.Visible=1;

            else
                app.PolynomialorderalongxSlider.Visible=0;
                app.PolynomialorderalongySlider.Visible=0;
                app.PolynomialorderalongzSlider.Visible=0;
                app.PolynomialDegreealongxLabel.Visible=0;
                app.PolynomialDegreealongyLabel.Visible=0;
                app.PolynomialDegreealongzLabel.Visible=0;
                app.LagrangianFamilyCheckBox.Visible=0;
                % app.HowtouseHexelementButton.Visible=0;
                % app.IntegrationorderforreferencetetrahedronLabel.Visible=0;

            end
            
            
            if app.TetrahedronelementButton.Value==1

                app.ElementorderLabel.Visible=1;
                % app.Spinner_2.Visible=1;
                app.Element_Order.Visible=1;
                
                app.PeelsSpinner.Value=15;
                app.IntegrationorderSpinner.Visible=1;
                app.IntegrationorderforreferencetetrahedronLabel.Visible=1;
                
                app.DonotguessCheckBox.Value=0;
                
                app.LegendreGaussLobattonodesCheckBox.Visible=1;
                
                app.SubTetrahedralsDropDown.Visible=1;
                app.NumberofSubTetrahedronsLabel.Visible=1;
                app.OrderLimitSpinner_2.Visible=1;
                app.Order_Limit_Label.Visible=1;
                app.IntegrationquadratureDropDown.Visible=0;
                app.IntegrationquadratureLabel.Visible=0;
                
            else
                app.Element_Order.Visible=0;
                app.ElementorderLabel.Visible=0;
                app.Spinner_2.Visible=0;                
                app.LegendreGaussLobattonodesCheckBox.Visible=0;
                app.SubTetrahedralsDropDown.Visible=0;
                app.NumberofSubTetrahedronsLabel.Visible=0;
            end
            
            
            % Set for custom element
            if app.ArbitraryorCustomelementButton.Value==1
                % app.EditField.Visible=0;
                % app.EditField_2.Visible=0;
                app.InputNodecoordinatesLabel.Visible=1;
                app.InputInterpolationfunctionLabel.Visible=1;
                app.nodeexampleButton.Visible=0;

                app.PeelsSpinner.Value=2;
                app.IntegrationorderSpinner.Visible=1;
                app.IntegrationorderforreferencetetrahedronLabel.Visible=1;
                
                 app.IntegrationorderSpinner.Enable=1;
                 
                 app.CheckButton.Visible=1;
                 app.DeleteButton.Visible=1;
                 app.DeleteButton_2.Visible=1;
                 % app.RefreshButton.Visible=0;

                 app.NumberofNodesSpinner.Visible=1;
                 app.UITable.Visible=1;
                 app.UITable2.Visible=1;   
                 app.AutofillButton.Visible=1;
                 app.NumberofNodesSpinnerLabel.Visible=1;
                 app.OrderLimitSpinner_2.Visible=0;
                 app.Order_Limit_Label.Visible=0;
                 app.ImportElementButton.Visible=1;
                 app.ImportelementDropDown.Visible=1;
                 
                 app.IntegrationquadratureDropDown.Visible=1;
                 app.IntegrationquadratureLabel.Visible=1;
            else
                % app.EditField.Visible=0;
                % app.EditField_2.Visible=0;
                app.InputNodecoordinatesLabel.Visible=0;
                app.InputInterpolationfunctionLabel.Visible=0;
                app.nodeexampleButton.Visible=0;
                
                app.IntegrationorderSpinner.Enable=0;

                app.CheckButton.Visible=0;
                app.DeleteButton.Visible=0;
                app.DeleteButton_2.Visible=0;
                
                %app.CheckButton.Visible=0;
                %app.RefreshButton.Visible=0;

                app.NumberofNodesSpinner.Visible=0;
                app.UITable.Visible=0;
                app.UITable2.Visible=0;
                app.AutofillButton.Visible=0;
                app.NumberofNodesSpinnerLabel.Visible=0;
                app.ImportElementButton.Visible=0;
                app.ImportelementDropDown.Visible=0;
            end
            
            if app.CheckinterelementcompatibilityButton.Value==1
               % app.EditField_3.Visible=1;
               % app.EditField_4.Visible=1; 
               % app.WritethefunctionyouwanttoplotLabel.Visible=1;
               % app.WritetheNodecoordinatesLabel_2.Visible=1;
               % app.PeelsSpinner.Value=2;


                Pre_Comparer(app);

               app.GenerateshapefunctionsButton.Visible=0;
               app.CheckcompatibilityButton.Visible=1;
               
                app.IntegrationorderSpinner.Visible=0;
                app.IntegrationorderforreferencetetrahedronLabel.Visible=0;
                
                % app.WhatpropertyyouwanttoplotDropDown.Visible=1;
                % app.WhichshapefunctionyouwanttoplotLabel.Visible=0;
                % app.Spinner.Visible=0;
                app.OrderLimitSpinner_2.Visible=0;
                app.Order_Limit_Label.Visible=0;
                % NumberofNodesSpinnerValueChanged(app, 1);
                app.IntegrationquadratureDropDown.Visible=0;
                app.IntegrationquadratureLabel.Visible=0;

                app.CalculatederivetivesCheckBox.Visible=0;
                app.DonotguessCheckBox.Visible=0;

                app.UIAxes2.Visible=1;
                app.UIAxes2_2.Visible=1;
                app.NewelementLabel.Visible=1;
                app.PreviouselementLabel.Visible=1;

                app.SurfacenoofnewelementSpinner.Visible=1;
                app.SurfacenoofnewelementSpinnerLabel.Visible=1;
                app.SurfacenoofpreviouselementSpinner.Visible=1;
                app.SurfacenoofpreviouselementSpinnerLabel.Visible=1;
                app.RotateDegButton.Visible=1;

                app.Spinner_3.Visible=1;
                app.WhatpropertyyouwanttoplotDropDown_2.Visible=1;


                            % fprintf('Compatible!')
                            app.Compatible_Cq.Text='  Compatible';
                            app.Compatible_Cq.Text='          NA';

                            app.Lamp.Color=[1 1 1];

                            app.Lamp.Visible=1;
                            app.Compatible_Cq.Visible=1;

                Extra_Plotter(app);

%                  hold(app.UIAxes2_2,'on')
%                  app.SNP1=scatter3(app.UIAxes2_2,app.P_C_2(:,1),app.P_C_2(:,2),app.P_C_2(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');                    
%                  DT = delaunayTriangulation(app.P_C_2(:,1),app.P_C_2(:,2),app.P_C_2(:,3));
%                  [K] = convexHull(DT);
%                  app.Tri1=trisurf(K,DT.Points(:,1),DT.Points(:,2),DT.Points(:,3),'EdgeColor','k','FaceColor','c','FaceAlpha',0.1,'Parent', app.UIAxes2_2);
%                  daspect(app.UIAxes2_2,[1 1 1])
%                  view(app.UIAxes2_2,45,45)
%                  % rotate3d(app.UIAxes2_2,'on');
%                  title(app.UIAxes2_2,sprintf('Nodes=%d',length(app.P_C_2)));
% 
%                  hold(app.UIAxes2,'on')
%                  app.SNP2=scatter3(app.UIAxes2,app.P_C(:,1),app.P_C(:,2),app.P_C(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');
%                  DT = delaunayTriangulation(app.P_C(:,1),app.P_C(:,2),app.P_C(:,3));
%                  [K] = convexHull(DT);
%                  app.Tri2=trisurf(K,DT.Points(:,1),DT.Points(:,2),DT.Points(:,3),'EdgeColor','k','FaceColor','c','FaceAlpha',0.1,'Parent', app.UIAxes2);
%                  daspect(app.UIAxes2,[1 1 1])
%                  view(app.UIAxes2,45,45)
%                  title(app.UIAxes2,sprintf('Nodes=%d',length(app.P_C)));


                 % rotate3d(app.UIAxes2,'on');
                 
                % rotate3d(app.UIFigure2.CurrentAxes,'on');
                % rotate3d(app.UIFigure2_2.CurrentAxes,'on');
                
                app.ToleranceEditField.Visible=1;
                app.ToleranceLabel.Visible=1;

                app.ToleranceEditField_2.Visible=1;

                app.FillSpinner.Enable=0;
                app.FillSpinner.Value=0;


            else
               % app.EditField_3.Visible=0;
               % app.EditField_4.Visible=0; 
               app.WritethefunctionyouwanttoplotLabel.Visible=0;
               app.WritetheNodecoordinatesLabel_2.Visible=0;                

               app.GenerateshapefunctionsButton.Visible=1;
               app.CheckcompatibilityButton.Visible=0;

               app.SurfacenoofnewelementSpinner.Visible=0;
               app.SurfacenoofpreviouselementSpinner.Visible=0;
               
                app.SurfacenoofnewelementSpinner.Visible=0;
                app.SurfacenoofnewelementSpinnerLabel.Visible=0;
                app.SurfacenoofpreviouselementSpinner.Visible=0;
                app.SurfacenoofpreviouselementSpinnerLabel.Visible=0;
                app.RotateDegButton.Visible=0;

                try
                delete(app.SNP1);
                delete(app.SNP2);
                delete(app.Tri1);
                delete(app.Tri2);
                delete(app.Tri1_1);
                delete(app.Tri2_1);
                catch
                end

                app.CalculatederivetivesCheckBox.Visible=1;
                app.DonotguessCheckBox.Visible=1;

                % app.WhatpropertyyouwanttoplotDropDown.Enable=1;
                % app.WhatpropertyyouwanttoplotDropDown.Visible=1;
                % app.WhichshapefunctionyouwanttoplotLabel.Visible=1;
                % app.Spinner.Visible=1;
               
                app.ToleranceEditField.Visible=0;
                app.ToleranceLabel.Visible=0;

                            app.Lamp.Visible=0;
                            app.Compatible_Cq.Visible=0;

                app.Spinner_3.Visible=0;
                app.WhatpropertyyouwanttoplotDropDown_2.Visible=0;

                app.ToleranceEditField_2.Visible=0;

                app.UIAxes2.Visible=0;
                app.UIAxes2_2.Visible=0;
                app.NewelementLabel.Visible=0;
                app.PreviouselementLabel.Visible=0;

                app.FillSpinner.Enable=1;


            end
            
            
        end

        % Button pushed function: GenerateshapefunctionsButton
        function GenerateshapefunctionsButtonPushed(app, event)
            
            app.N_C_2=app.N_C;
            app.P_C_2=app.P_C;
            app.P_C_Surf_2=app.P_C_Surf;
            app.V_CP_Sequence_S_2=app.V_CP_Sequence_S;
            app.Type_C_2=app.Type_C;
            app.INTP_Surf_2=app.INTP_Surf;
            app.gp_per_Surfs_2=app.gp_per_Surfs;

            % disp(app.P_C_Surf_2)


            % Execute the main code 
            app.N_C=[];
            app.Dom_C=[];
            app.Type_C='';
            app.Sx_C=[];
            app.Sy_C=[];
            app.Sz_C=[];  
            app.u_C=[];
            app.P_C=[]; 
            app.dNbydx=[];
            app.dNbydy=[];
            app.dNbydz=[];
            app.d2Nbydx2=[];
            app.d2Nbydy2=[];
            app.d2Nbydz2=[];
            app.X_Order=[];
            app.Y_Order=[];
            app.Z_Order=[];
            app.SFm_C=[];
            app.SFm_C_2=[];
            app.Peels_C=[];
            app.INTP=[];
            app.W=[];
            app.W_L=[];
            app.hIsosurf=[];
            app.hSlice=[];
            app.SNP=[];
            app.SGP=[];
            app.Texts=[];
            app.Texts2=[];
            app.u_Coeff=[];
            app.Der_Order=[];
            app.Texts3=[];
            app.SNP1=[];
            app.SNP2=[];
            app.INTP_Surf=[];
            app.W_Surf=[];
            app.gp_per_Surfs=[];
            app.SGPS=[];
            app.d2Nbydz2C=[];
            app.d2Nbydz2C2=[];
            app.Order_Local=[];
                
            
            % Clean up text box
            app.TextArea.FontColor='k';
            
            app.TextArea.Value{1}='';
            app.TextArea.Value{2}='';
            app.TextArea.Value{3}='';
            app.TextArea.Value{4}='';
            app.TextArea.Value{5}='';
            app.TextArea.Value{6}='';
            app.TextArea.Value{7}='';
            app.TextArea.Value{8}='';
            app.TextArea.Value{9}='';
            app.TextArea.Value{10}='';
            app.TextArea.Value{11}='';
            app.TextArea.Value{12}='';
            app.TextArea.Value{13}='';
            app.TextArea.Value{14}='';    
            app.TextArea_2.Value='';
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            app.IntegrationpointsCheckBox.Value=0;
            MaxDx=0;
            MaxDy=0;
            MaxDz=0;
            
            
            S1=sprintf('Working...');
            app.TextArea.Value{2}=S1;
            pause (0.01)
            
                if app.FillSpinner.Value==0
                    app.FillSpinner.Value=0.2;
                end

                if app.CalculatederivetivesCheckBox.Value==1
                    app.WhatpropertyyouwanttoplotDropDown.Items = {'      Shape function, N', '           d^m/dx^m (N)', '           d^m/dy^m (N)', '           d^m/dz^m (N)','   d^m/dx^ndy^ndz^n (N)','   d^m/dx^ady^bdz^c (N)'};
                    
                    % app.WhatpropertyyouwanttoplotDropDown.Items = {'      Shape function, N', '           d^m/dx^m (N)', '           d^m/dy^m (N)', '           d^m/dz^m (N)','   d^m/dx^ndy^ndz^n (N)'};

                else
                    app.WhatpropertyyouwanttoplotDropDown.Items = {'      Shape function, N'};
                end
            %% ------------------Hex code------------
            
            if app.HexahedronorBrickelementButton.Value==1
            
                MaxDx=app.PolynomialorderalongxSlider.Value;
                MaxDy=app.PolynomialorderalongySlider.Value;
                MaxDz=app.PolynomialorderalongzSlider.Value;
                
                if (MaxDx+MaxDy+MaxDz)>100
                    app.LagrangianFamilyCheckBox.Value=0;
                    pause (0.1)
                end
                
                
                if app.LagrangianFamilyCheckBox.Value>0 % Activate lagrange
                    Serendipity=0;
                    S2=sprintf('Element type: hexahedron/ Brick. Element family: Lagrange');
                    app.TextArea.Value{2}=S2;
                    pause (0.01)
                else
                    Serendipity=1;
                    S2=sprintf('Element type: hexahedron/ Brick. Element family: Serendipity');
                    app.TextArea.Value{2}=S2;
                    pause (0.01)
                end
                 
    
                %% Obtain nodes
                [P,app.W_L]=Automatic_Node_Generator_Hexahedron(MaxDx,MaxDy,MaxDz,Serendipity);
                S3=sprintf('Node generation Done! Number of nodes=%d',length(P)');
                app.TextArea.Value{3}=S3;
                app.P_C=P;
                pause (0.01)
                
                S4=sprintf('......');
                app.TextArea.Value{4}=S4;
                pause (0.01)
                
                %% Generate function
                [u]=Automatic_Function_Generator_L(MaxDx,MaxDy,MaxDz,length(P));
                S4=sprintf('Interpolation function generation Done!');
                app.TextArea.Value{4}=S4;
                app.u_C=u;
                pause (0.01)
            
            
            end
            
            if app.TetrahedronelementButton.Value==1
                
                S2=sprintf('Element type: tetrahedron');
                app.TextArea.Value{2}=S2;
                
                Order=app.Spinner_2.Value;
                [P]=Automatic_Node_Generator_Tetrahedron(Order);
                S3=sprintf('Node generation Done! Number of nodes=%d',length(P)');
                app.TextArea.Value{3}=S3;
                app.P_C=P;
                pause (0.01)
                
                S4=sprintf('......');
                app.TextArea.Value{4}=S4;
                pause (0.01)
                
                
                [u]=Automatic_Function_Generator_Tet(Order,P);
                S4=sprintf('Interpolation function generation Done!');
                app.TextArea.Value{4}=S4;
                app.u_C=u;
                pause (0.01)
                
            end


            if app.ArbitraryorCustomelementButton.Value==1
                
                S2=sprintf('Element type: Custom');
                app.TextArea.Value{2}=S2;
                
                x=sym('x');
                y=sym('y');
                z=sym('z');
                
                % app.P_C=str2num(app.EditField_2.Value);
                % app.u_C=str2sym(app.EditField.Value);


                PT=table2array(app.UITable.Data);
                app.P_C=PT(:,2:4);


                uT=table2array(app.UITable2.Data);
                uTV=uT(:,2);
                app.u_C=transpose(str2sym(uTV));
                
            end
            
            % Get the orders
            % Function_Analyzer_Order_Getter(app);
            
            
            S5=sprintf('Generating shape functions......');
            app.TextArea.Value{5}=S5;
            pause (0.01)

            
            %% Obtain the Shape functions N
            Shape_Function_Formulation_3D(app);
            S5=sprintf('Shape function generation Done!');
            app.TextArea.Value{5}=S5;
            N=app.N_C;
            pause (0.01)
            

            %% ---------------------- One time run----------------------
            app.SFm_C=1;
            app.SFm_C_2=1;
            app.Peels_C=app.PeelsSpinner.Value;
            % no=app.ResolutionSpinner.Value;
            S6=sprintf('Getting element type...');
            pause (0.01)
            app.TextArea.Value{6}=S6;
            Type_Getter(app);


            S6=sprintf('Generating graphics and Integration quadrature...');
            app.TextArea.Value{6}=S6;            
            pause (0.01)
            Verify_Shape_Functions(app);
            Graphics_Manipulator(app);
            S6=sprintf('Generating graphics and Integration quadrature...Done');
            app.TextArea.Value{6}=S6;                        
            

            %%----------------------- Integration points-----------
                Integration_Point_Generator_Both(app);
            
            %% ---------------- Activation---------------
            
            if size(app.INTP,1)<=500
                app.IntegrationpointsCheckBox.Enable=1;
                app.WeightsCheckBox.Enable=1;
            else
                app.IntegrationpointsCheckBox.Enable=0;
                app.WeightsCheckBox.Enable=0;
                S6=sprintf('Too many Integration points to plot! Option disabled');
                app.TextArea.Value{8}=S6; 
            end
            
            app.NodesCheckBox.Enable=1;
            app.NodeNumbersCheckBox.Enable=1;
            
            
             app.WeightsCheckBox.Value=0;
             app.NodeNumbersCheckBox.Value=0;
            
            app.Spinner.Enable=1;
            app.Spinner.Value=1;
            app.PeelsSpinner.Enable=1;
            
            app.GetfigureButton.Enable=1;
            
            app.WhatpropertyyouwanttoplotDropDown.Enable=1;
            app.SurfaceIntegrationpointsCheckBox.Enable=1;
            app.ParametriccoordinatesCheckBox.Enable=1;
            app.WeightsCheckBox_2.Enable=1;
            
            app.ParametricaxiscolorDropDown.Enable=1;
            app.yDropDown.Enable=1;
            app.zDropDown.Enable=1;


            app.FontsizeSpinner.Enable=1;

            %if app.Type_C=='U' || app.Type_C=='T'
                app.EdgesCheckBox.Enable=1;
                app.FillSpinner.Enable=1;
                
            %else
                %app.EdgesCheckBox.Enable=0;
                %app.FillSpinner.Enable=0;
                
            %end            

            
            app.SavedataButton.Enable=1;
            app.ShapeMatrixCheckBox.Enable=1; 
            app.BuildFunctionFileCheckBox.Enable=1;
            app.DerivativesofShapematrixCheckBox.Enable=1;


            %% -------------------------Writing and saving--------------------------------
            
            % filename=(sprintf('Results_XO%d_YO%d_ZO%d_N%d',MaxDx,MaxDy,MaxDz,size(P,1)));
            
            % INTP_L=app.INTP;
            % W_L=app.W;
            % save(filename,"P","N","INTP_L","W_L");
            
            %%-------------------------------------------------------------------
                        
            % app.EditField_2.Value=[{app.EditField_2.Value}; {sprintf('Done! Results saved in %s.txt file',filename)}];
            % app.EditField_2.Value=sprintf('Working...Done!');


            Quote(app);
            
            if length(app.N_C_2)>0
                app.CheckinterelementcompatibilityButton.Enable=1;
            else
                app.CheckinterelementcompatibilityButton.Enable=0;
            end


            %% Hexahedral series
            function[P,W]=Automatic_Node_Generator_Hexahedron(MaxDx,MaxDy,MaxDz,Serendipity)

                % app.EditField_2.Value=sprintf('Generating nodes...');
                    W=0;
                
                    [x,Wx]=lglnodes(app,MaxDx);
                    [y,Wy]=lglnodes(app,MaxDy);
                    [z,Wz]=lglnodes(app,MaxDz);
                    
                    [X,Y,Z]=meshgrid(x,y,z);
                    
                    
                    ZM=X*0;
                    
                    if Serendipity==1
                        ZM(:,1,1)=1;
                        ZM(1,:,1)=1;
                        ZM(end,:,1)=1;
                        ZM(:,end,1)=1;
                    
                        ZM(:,1,end)=1;
                        ZM(1,:,end)=1;
                        ZM(end,:,end)=1;
                        ZM(:,end,end)=1;
                    
                        ZM(1,1,:)=1;
                        ZM(end,1,:)=1;
                        ZM(1,end,:)=1;
                        ZM(end,end,:)=1;
                        
                    else
                        ZM=ZM+1;   
                          
                    end
                    

                    Length=length(x)*length(y)*length(z);
                    P_I=zeros(Length,3);
                    

                    c=1;
                    for j=1:length(x)
                       for i=1:length(y)
                           for k=1:length(z)
                               if ZM(i,j,k)==1
                                   % Points:   x        y        z
                                   P_I(c,:)=[X(i,j,k) Y(i,j,k) Z(i,j,k)];
                                   c=c+1;
                               end
                           end
                       end
                    end
                    
                    P_New=P_I(1:c-1,:);
                    P_Iso=P_New*0;
                    N_Taken=zeros(20,1);
                 

                    c_Update=1;
                    for n_Ref=1:20
                        for n=1:c-1
                            if norm([app.Node_Set_20N(n_Ref,:)]-P_New(n,:))<10^-10
                                P_Iso(c_Update,:)=P_New(n,:);
                                N_Taken(c_Update)=n;
                                c_Update=c_Update+1;
                                break
                            end
                        end
                    end

                    N_Left=setdiff(1:c-1,N_Taken);                    
                    P_Iso_no20=P_New(N_Left,:);

                    P_ISo_w20=P_Iso(1:c_Update-1,:);
                    P=[P_ISo_w20;P_Iso_no20];
                    
                    
                    end
                    
            function[Series]=Automatic_Function_Generator_L(MaxDx,MaxDy,MaxDz,Terms)
            %%
            x=sym('x');
            y=sym('y');
            z=sym('z');
            
            Length=(MaxDx+1)*(MaxDy+1)*(MaxDz+1);
            
            Series_Cal=zeros(Length,6);
            Series_Cal=sym(Series_Cal);
            
            if Terms<Length
                %fprintf('Generating function assuming Serendipity element...')
                Serendipity=1;
            elseif Terms==Length
                %fprintf('Generating function assuming Lagrange element...')
                Serendipity=0;
            end
            
            
            %%
            c=1;
            n=0;
            while c<=Length
                for m=0:n
                    for k=0:m
                        % Coeff=factorial(n)/(factorial(n-m)*factorial(m-k)*factorial(k));
                        if (n-m)<=MaxDx && (m-k)<=MaxDy && k<=MaxDz
                            Exp=x^(n-m)*y^(m-k)*z^k;
                            Series_Cal(c,1)=Exp;
                            
                            Series_Cal(c,2)=max([n-m m-k k]);
                            
                            Series_Cal(c,3)=sum([n-m]);
                            Series_Cal(c,4)=sum([m-k]);
                            Series_Cal(c,5)=sum([k]);
                            Series_Cal(c,6)=sum([n-m m-k k]);
                            c=c+1;
                        end
                        if c>=Length+1
                            break
                        end
                    end
                end
                n=n+1;
                
                if n>100
                    %fprintf('Too many iterations...Breaking the loop')
                    break
                end
            end
            
            %%
            
            % Series_Cal=sortrows(Series_Cal,2);
            
            if Serendipity==1
                
                Series_Set=sym(zeros(1,length(Series_Cal)));
                c=1;
                for i=1:length(Series_Cal)
            
                    LogicX=double(Series_Cal(i,3))>1 && (double(Series_Cal(i,4))>1 || double(Series_Cal(i,5))>1);
                    LogicY=double(Series_Cal(i,4))>1 && (double(Series_Cal(i,3))>1 || double(Series_Cal(i,5))>1);
                    LogicZ=double(Series_Cal(i,5))>1 && (double(Series_Cal(i,3))>1 || double(Series_Cal(i,4))>1);
            
            
                    if LogicX==0 && LogicY==0 && LogicZ==0
                        Series_Set(1,c)=Series_Cal(i,1);
            
                        % SeriesP(c,1:6)=Series_Cal(i,1:6);
            
                        c=c+1;
                    end
            
            
                end
                
                Series=Series_Set(1,1:c-1);
                
            
            else
                Series=transpose(Series_Cal(:,1));
            end


            if length(Series)==Terms
                %fprintf('Number of terms matches\n');
            else
                %error('Number of terms didnt matchsdfsdfsdf\n');
            end
            
            
            % Series_Cal=sortrows(Series_Cal,2);


            % Series=Series(1:Terms);
            
            end
            
            
            %% Tetragonal series
            function[P_Extracted]=Automatic_Node_Generator_Tetrahedron(Order)
                
                    m=Order;
                    [Zeros]=lglnodes(app,m);
                    v=(1/2*Zeros'+1/2);
                    v=flip(v);
                    
                    P=zeros((m+1)^3,3);

                    c=1;
                    
                    %xy face
                    for i=1:m+1
                        for j=1:m+2-i
                            l=m+3-i-j;
                            P(c,1)=1/3*(1+2*v(i)-v(j)-v(l));
                            P(c,2)=1/3*(1-v(i)+2*v(j)-v(l));
                            P(c,3)=0;
                            c=c+1;
                        end
                    end
                    
                    % yz face
                    for j=1:m
                        for k=2:m+2-j
                            l=m+3-j-k;
                            P(c,1)=0;
                            P(c,2)=1/3*(1+2*v(j)-v(k)-v(l));
                            P(c,3)=1/3*(1-v(j)+2*v(k)-v(l));
                            c=c+1;
                        end
                    end
                        
                    %xz face
                    for i=2:m
                        for k=2:m+2-i
                            l=m+3-i-k;
                            
                            P(c,1)=1/3*(1+2*v(i)-v(k)-v(l));
                            P(c,2)=0;
                            P(c,3)=1/3*(1-v(i)+2*v(k)-v(l));
                            c=c+1;
                            
                        end
                    end
                    
                    % Slanted face
                    for i=2:m
                        for j=2:m+1-i
                            l=m+3-i-j;
                            P(c,1)=1/3*(1+2*v(i)-v(j)-v(l));
                            P(c,2)=1/3*(1-v(i)+2*v(j)-v(l));
                            P(c,3)=1-P(c,1)-P(c,2);
                            c=c+1;
                        end
                    end
                    
                    % Interior nodes
                    for i=2:m
                        for j=2:m+1-i
                            for k=2:m+2-i-j
                                l=m+4-i-j-k;
                                
                                P(c,1)=1/4*(1+3*v(i)-v(j)-v(k)-v(l));
                                P(c,2)=1/4*(1-v(i)+3*v(j)-v(k)-v(l));
                                P(c,3)=1/4*(1-v(i)-v(j)+3*v(k)-v(l));
                                c=c+1;
                            end
                        end
                    end


                    P_Extracted=P(1:c-1,:);
                    
            end
            
            function[Series]=Automatic_Function_Generator_Tet(Order,P)
                %%
                syms x y z
                
                % Order=Order-1;
                
                Terms=size(P,1);
                Length=Terms;
                
                Series_Cal=zeros(Length,6);
                Series_Cal=sym(Series_Cal);


                %%
                c=1;
                n=0;
                while c<=Length
                    for m=0:n
                        for k=0:m
                            % Coeff=factorial(n)/(factorial(n-m)*factorial(m-k)*factorial(k));
                            if (n-m)<=Order && (m-k)<=Order && k<=Order
                                Exp=x^(n-m)*y^(m-k)*z^k;
                                Series_Cal(c,1)=Exp;
                                
                                Series_Cal(c,2)=max([n-m m-k k]);
                                
                                Series_Cal(c,3)=sum([n-m]);
                                Series_Cal(c,4)=sum([m-k]);
                                Series_Cal(c,5)=sum([k]);
                                Series_Cal(c,6)=sum([n-m m-k k]);
                                c=c+1;
                            end
                            if c>=Length+1
                                break
                            end
                        end
                    end
                    n=n+1;
                    
                    if n>20
                        fprintf('Too many iterations...Breaking the loop')
                        break
                    end
                end
                
                %%
                
                % Series_Cal=sortrows(Series_Cal,2);
                
                 Series=transpose(Series_Cal(:,1));
                 
                % Series_Cal=sortrows(Series_Cal,2);
                
                % Series=Series(1:Terms);
                
            end
            
            %% Helper function generator
            % Function_Analyzer_Order_Getter(app);
            
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            
            
            if app.Spinner.Value>length(app.N_C)
                app.Spinner.Value=length(app.N_C);
            end
            
            app.SFm_C = app.Spinner.Value;
            app.Peels_C=app.PeelsSpinner.Value;
            
            
            % no=app.ResolutionSpinner.Value;
            
            if app.CheckinterelementcompatibilityButton.Value==1
            try 
                
                   Comparer(app);
                
            catch
                cla(app.UIAxes);    
                app.TextArea_2.Value='';
                
                Verify_Shape_Functions(app);
                Graphics_Manipulator(app);
                
                if app.NodeNumbersCheckBox.Value==1
                    NodeNumbersCheckBoxValueChanged(app);
                end
                if app.WeightsCheckBox.Value==1
                    WeightsCheckBoxValueChanged(app);
                end
            end
            else
                cla(app.UIAxes);    
                app.TextArea_2.Value='';
                
                Verify_Shape_Functions(app);
                Graphics_Manipulator(app);
                
                if app.NodeNumbersCheckBox.Value==1
                    NodeNumbersCheckBoxValueChanged(app);
                end
                if app.WeightsCheckBox.Value==1
                    WeightsCheckBoxValueChanged(app);
                end                
            end
            
   
        end

        % Value changed function: PeelsSpinner
        function PeelsSpinnerValueChanged(app, event)
              
            app.SFm_C = app.Spinner.Value;
            app.Peels_C=app.PeelsSpinner.Value;
            
            
            % no=app.ResolutionSpinner.Value;
            
            cla(app.UIAxes);    
            app.TextArea_2.Value='';
            
            Verify_Shape_Functions(app);
            Graphics_Manipulator(app);
            
            if app.NodeNumbersCheckBox.Value==1
                NodeNumbersCheckBoxValueChanged(app);
            end
            if app.WeightsCheckBox.Value==1
                WeightsCheckBoxValueChanged(app);
            end


        end

        % Button pushed function: nodeexampleButton
        function nodeexampleButtonPushed2(app, event)
                            
%             S1=sprintf('Step 1: Input nodal coordinate');
%             app.TextArea.Value{2}=S1;
%             S2=sprintf('    Nodal coordinate input method: If xi yi zi are x y z coordinate of node i then Input method is: (Put them into bracket "[]")');
%             app.TextArea.Value{3}=S2;
%             S3=sprintf('    [x1 y1 z1; x2 y2 z2; x3 y3 z3;...]');
%             app.TextArea.Value{4}=S3;            
%             
%             app.TextArea.Value{5}='';
%             
%             S4=sprintf('Step 2: Input Interpolation function');
%             app.TextArea.Value{6}=S4;            
%             S5=sprintf('    Interpolation function input method: Use space between terms. Do not use "+" or add them. Put the terms within brackets "[]". Use x*y for multiplication, not xy. Example:');
%             app.TextArea.Value{7}=S5;
%             S6=sprintf('    [1 x y z x*y*z x^2 y^2 z^2 x^2*y^2...]');
%             app.TextArea.Value{8}=S6;
%             
%             app.TextArea.Value{9}='';
%             
%             
%             S2=sprintf('Step 3: Choose integration order using the spinner');
%             app.TextArea.Value{10}=S2;
%             
%             app.TextArea.Value{11}='';
%             
%             S2=sprintf('Step 4: HIT "Generate shape functions!" BUTTON');
%             app.TextArea.Value{12}=S2;


            value = app.NumberofNodesSpinner.Value;
            if value~=21
                value=21;
                app.NumberofNodesSpinner.Value=value;
            end

            % Initial_Data=app.UITable.Data;

            Tab_tot=table('Size',[value 4],'VariableTypes',{'double','double','double','double'});
            for i=1:value
                Tab_tot(i,1)={i};
            end

          Tab_tot(1:21,2:4)=num2cell([-1 -1 -1;
                              1 -1 -1;
                              1  1 -1;
                             -1  1 -1;
                             -1 -1  1;
                              1 -1  1;
                              1  1  1;
                             -1  1  1;
                              0 -1 -1;
                              1  0 -1;
                              0  1 -1;
                             -1  0 -1
                              0 -1  1;
                              1  0  1;
                              0  1  1;
                             -1  0  1;
                             -1 -1  0;
                              1 -1  0;
                              1  1  0;
                             -1  1  0;
                              0  0  1]);

            
            app.UITable.Data=Tab_tot;


            Initial_Data2=app.UITable2.Data;
            Initial_Length2=size(Initial_Data2,1);


            syms x y z
            u=[1; x; y; z; x*y; y*z; x*z; x^2;y^2;z^2; x*y*z; x^2*y; x^2*z; y^2*x; y^2*z; z^2*x; z^2*y; x^2*y*z; x*y^2*z; x*y*z^2; x^2*y^2*(z+1)];
            

            Tab_tot2=table('Size',[value,2],'VariableTypes',{'string','string'});
            for i=1:value
                String=sprintf('c%d',i);
                Tab_tot2(i,1)={String};
                Tab_tot2(i,2)={string(u(i))};
            end
            app.UITable2.Data=Tab_tot2;
            app.UITable2.Data=Tab_tot2;


        end

        % Value changed function: EdgesCheckBox
        function EdgesCheckBoxValueChanged(app, event)
            
            
            % cla(app.UIAxes);    
            % app.TextArea_2.Value='';
            
            Graphics_Manipulator(app);
                      
        end

        % Callback function
        function HowtouseHexelementButtonPushed(app, event)
            
            app.TextArea.Value{2}='';
            app.TextArea.Value{3}='';
            app.TextArea.Value{4}='';
            app.TextArea.Value{5}='';
            app.TextArea.Value{6}='';
            app.TextArea.Value{7}='';
            app.TextArea.Value{8}='';
            app.TextArea.Value{9}='';
            app.TextArea.Value{10}='';
            app.TextArea.Value{11}='';
            app.TextArea.Value{12}='';
            app.TextArea.Value{13}='';
            app.TextArea.Value{14}='';
            
            S0=sprintf('Step 1: Choose polynomial orders from Slidebars');
            app.TextArea.Value{2}=S0;
            
            app.TextArea.Value{3}='';
            
            S1=sprintf('Step 2: Tick Lagrange family block if you want. Default is Serendipity family');
            app.TextArea.Value{4}=S1;
            
            app.TextArea.Value{5}='';
            
            S2=sprintf('Step 3: HIT "GENERATE SHAPE FUNCTIONS!" BUTTON');
            app.TextArea.Value{6}=S2;

            app.TextArea.Value{7}='';
            
            S3=sprintf('Step 4: Use spinner to see all of them!');
            app.TextArea.Value{8}=S3;
            
            
        end

        % Value changed function: GetfigureButton
        function GetfigureButtonValueChanged(app, event)
            
                app.TextArea.Value{10}='';
            
                 %app.UIFigure.WindowState = 'normal';
                 %pause(1)
                
                % S1=sprintf('Printing... Note: May not produce good result if window Maximized');
                % app.TextArea.Value{10}=S1;

                % Name='Printed_File';
                %try
                %    Name=sprintf('Shape_Function_%d',app.SFm_C);
                %catch
                %    Name=sprintf('Shape_Function');
                %end
                    
            % Define figure and axes to specifications.
                f = figure('Visible', true);
                axNew = axes(f);
                % Copy.
                myCopyObj(app.UIAxes, axNew);
                % Print!
                % 
                %

                % f.Position=[500,100,1800,1000];
                f.Position=get(0,'ScreenSize');
                
                %f.Position=[500,500,1142,714];

                % Quality=sprintf('-r%d',app.ToleranceEditField.Value);
                % print(f,Name,'-dpng',Quality);
                % delete(f);
                
                % S1=sprintf('Printing Done and saved as %s in %s',Name,pwd);
                % app.TextArea.Value{10}=S1;
                   
            
            function myCopyObj(axFrom, axTo)
                % Custom version of copyobj() which works to copy a UIAxes object to
                % an old-school axes object.
                
                % Copy children (lines).
                copyobj(axFrom.Children, axTo);
                % Copy titles and labels.
                copyobj([axFrom.Title axFrom.XLabel axFrom.YLabel], axTo)
                % copyobj(axFrom.Colormap, axTo)
                
                % Transfer other properties.
                uiAxParams = get(axFrom);
                uiAxParamNames = fieldnames(uiAxParams);
                % Get list of editable params in new axis
                editableParams = fieldnames(set(axTo));
                % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
                badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
                badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; ...
                'Position'; 'OuterPosition'; 'XLabel'; 'YLabel'; 'ZLabel'; 'Title'];
                uiAxGoodParams = rmfield(uiAxParams,badFields);
                % set editable params on new axes
                set(axTo, uiAxGoodParams)

            end


        end

        % Value changed function: FillSpinner
        function FillSpinnerValueChanged(app, event)
            % value = app.FillSpinner.Value;
          
            Graphics_Manipulator(app);

            try 
                Graphics_Manipulator_2(app);
            catch
            end
            
        end

        % Value changed function: NodesCheckBox
        function NodesCheckBoxValueChanged(app, event)
            
            try 
                P=app.P_C;app.P_C_2;
            catch
                P=app.P_C;
            end
                
                % Plot nodes
                if app.NodesCheckBox.Value==1
                    app.SNP=scatter3(app.UIAxes,P(:,1),P(:,2),P(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');
                else
                    delete(app.SNP);%scatter3(app.UIAxes,P(:,1),P(:,2),P(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');
                end
                
                
        end

        % Value changed function: IntegrationpointsCheckBox
        function IntegrationpointsCheckBoxValueChanged(app, event)
            
            
                % Plot Gauss points
                
                if app.IntegrationpointsCheckBox.Value==1
                    app.SGP=gobjects(size(app.INTP,1));
                    for d=1:size(app.INTP,1)
                        
                        app.SGP(d)=scatter3(app.UIAxes,app.INTP(d,1),app.INTP(d,2),app.INTP(d,3),30,'MarkerEdgeColor','r','MarkerFaceColor','r');
                        
                    end
                    
                else
                    delete(app.SGP);
                end
            
            
        end

        % Value changed function: IntegrationorderSpinner
        function IntegrationorderSpinnerValueChanged(app, event)
            
        end

        % Callback function
        function EditField_4ValueChanged(app, event)
            
%                 P=str2num(app.EditField_2.Value);
%                 
%                if size(P,1)==0
%                     
%                    S1=sprintf('Invalid Nodal coordinates input! Change');
%                    app.TextArea.Value{2}=S1;
%                    % app.GenerateshapefunctionsButton.Enable=0;
%                    app.EditField_4.BackgroundColor='r';
%                 elseif size(P,2)<3
%                    S1=sprintf('Invalid Nodal coordinates input! Input for 3 dimension, [x1 y1 z1; x2 y2 z2;...]');
%                    app.TextArea.Value{2}=S1;
%                    % app.EditField_4.BackgroundColor='r';
%                 else
%                     S1=sprintf('Valid Nodal coordinates input! Number of nodes=%d',size(P,1));
%                     app.TextArea.Value{2}=S1;
%                     % app.EditField.Enable=1;
%                     % app.EditField_4.BackgroundColor='w';
%                 end 
            
        end

        % Button pushed function: CheckcompatibilityButton
        function CheckcompatibilityButtonPushed(app, event)
                
%                 app.N_C=[];
%                 app.Dom_C=[];
%                 app.Type_C='';
%                 app.Sx_C=[];
%                 app.Sy_C=[];
%                 app.Sz_C=[];
                
                
            S5=sprintf('Comparing......');
            app.TextArea.Value{5}=S5;
            pause (0.01)


                % Clean up text box
                app.TextArea.Value{1}='';
                app.TextArea.Value{2}='';
                app.TextArea.Value{3}='';
                app.TextArea.Value{4}='';
                app.TextArea.Value{5}='';
                app.TextArea.Value{6}='';
                app.TextArea.Value{7}='';
                app.TextArea.Value{8}='';
                app.TextArea.Value{9}='';
                app.TextArea.Value{10}='';
                app.TextArea.Value{11}='';
                app.TextArea.Value{12}='';
                app.TextArea.Value{13}='';
                app.TextArea.Value{14}='';    
                app.TextArea_2.Value='';
                cla(app.UIAxes);
                app.IntegrationpointsCheckBox.Value=0;    
            
                % length(app.N_C_2)
                
%                 x=sym('x');
%                 y=sym('y');
%                 z=sym('z');
%                 
% 
%                 Tol=app.ToleranceEditField.Value;
% 
%                 [Size,Seq]=min([length(app.N_C) length(app.N_C_2)]);
% 
%                 if Seq==2
%                     N_C_3=app.N_C;
%         
%                     for i=1:length(app.N_C)
%                         P1=app.P_C(i,:);
%                         for j=1:length(app.N_C_2)
%     
%                             P2=app.P_C_2(j,:);
%     
%                             if norm(P1-P2)<Tol
%                                 N_C_3(i)=app.N_C(i)-app.N_C_2(j);
%                                 break
%                             end
%                         end
%                     end
% 
%                     
%                 else
%                     N_C_3=app.N_C_2;
%                     for i=1:length(app.N_C_2)
%                         P1=app.P_C_2(i,:);
%                         for j=1:length(app.N_C)
%     
%                             P2=app.P_C(j,:);
%     
%                             if norm(P1-P2)<Tol
%                                 N_C_3(i)=app.N_C_2(i)-app.N_C(j);
%                                 break
%                             end
%                         end
%                     end
%                     app.P_C=app.P_C_2;
% 
%                 end
% 
%                 app.N_C=N_C_3;
% 
% 
%                 app.WhatpropertyyouwanttoplotDropDown.Items = {'Shape function diff, dN'};
%                                                                
%                 Verify_Shape_Functions(app);
%                 Graphics_Manipulator(app);
% 
%                 
%             S5=sprintf('Comparing...... Done!');
%             app.TextArea.Value{5}=S5;
%             pause (0.01)
% 
%                 Quote(app);


%                 % E1 ------------------------------- 
%                 Surf_NodesT=zeros(1,6); % Prealocate
%                 
%                 s_prev=1;
%                 for s=2:size(app.P_C_Surf,1)
%                     if app.P_C_Surf(s,5)>app.P_C_Surf(s-1,5) || s==size(app.P_C_Surf,1)
% 
%                         if s==size(app.P_C_Surf,1)
%                             s_min=s_prev;
%                             s_max=s;
%                         else
%                             s_min=s_prev;
%                             s_max=s-1;
%                         end
% 
% 
%                         NdS=app.P_C_Surf(s_min:s_max,1);
%                         NdSS=unique(NdS);
%                         
%                         Surf_Nodes_L=zeros(length(NdSS),1);
%                         for c=1:length(NdSS)
%                            Surf_Nodes_L(c,1)=NdSS(c);   
%                            
%                         end   
%                         Surf_NodesT(1:c,app.P_C_Surf(s_max,5))=Surf_Nodes_L; %\r\n',c,app.P_C_Surf(s_max,5));
%                         s_prev=s_max+1;
% 
%                     end
%                 end
% 
%                 app.Surf_Nodes=Surf_NodesT;
%                 
%                 % E2-------------------------------------
%                 Surf_Nodes_2T=zeros(1,6); % Prealocate
%                 
%                 s_prev=1;
%                 for s=2:size(app.P_C_Surf_2,1)
%                     if app.P_C_Surf_2(s,5)>app.P_C_Surf_2(s-1,5) || s==size(app.P_C_Surf_2,1)
% 
%                         if s==size(app.P_C_Surf_2,1)
%                             s_min=s_prev;
%                             s_max=s;
%                         else
%                             s_min=s_prev;
%                             s_max=s-1;
%                         end
% 
% 
%                         NdS=app.P_C_Surf_2(s_min:s_max,1);
%                         NdSS=unique(NdS);
%                         
%                         Surf_Nodes_L=zeros(length(NdSS),1);
%                         for c=1:length(NdSS)
%                            Surf_Nodes_L(c,1)=NdSS(c);   
%                            
%                         end   
%                         Surf_Nodes_2T(1:c,app.P_C_Surf_2(s_max,5))=Surf_Nodes_L; %\r\n',c,app.P_C_Surf(s_max,5));
%                         s_prev=s_max+1;
% 
%                     end
%                 end
% 
%                 app.Surf_Nodes_2=Surf_Nodes_2T;

                Comparer(app);


                app.Spinner_3.Enable=1;
                app.WhatpropertyyouwanttoplotDropDown_2.Enable=1;   

                app.RotateDegButton.Enable=1;


        end

        % Value changed function: WhatpropertyyouwanttoplotDropDown
        function WhatpropertyyouwanttoplotDropDownValueChanged(app, event)
            
            cla(app.UIAxes);
            app.TextArea_2.Value='';


            if app.WhatpropertyyouwanttoplotDropDown.Value=='Differentiability class'
                app.SFm_C=1;
                app.Spinner.Value=1;
                app.Spinner.Enable=0;   
                if app.CalculatederivetivesCheckBox.Value==0
                    app.WhatpropertyyouwanttoplotDropDown.Value='      Shape function, N';
                end

            else
                app.Spinner.Enable=1;  
            end


            Verify_Shape_Functions(app);
            Graphics_Manipulator(app);
            
            if app.NodeNumbersCheckBox.Value==1
                NodeNumbersCheckBoxValueChanged(app);
            end
            if app.WeightsCheckBox.Value==1
                WeightsCheckBoxValueChanged(app);
            end
            
            
        end

        % Value changed function: NodeNumbersCheckBox
        function NodeNumbersCheckBoxValueChanged(app, event)
            value = app.NodeNumbersCheckBox.Value;
                       
            if value==1
                try
                    delete(app.Texts);
                catch
                   app.Texts=[];
                end
                
                xm=mean(app.P_C(:,1));
                ym=mean(app.P_C(:,2)) ;               
                zm=mean(app.P_C(:,3));


                for i=1:size(app.P_C,1)
                    x=app.P_C(i,1);
                    y=app.P_C(i,2);
                    z=app.P_C(i,3);
                    
                    if x==0 && y==0 && z==0
                        x=x-0.001;
                        y=y-0.001;
                        z=z-0.001;                        
                    else
                        x=x;%+0.001;
                        y=y;%+0.001;
                        z=z;%+0.001;
                    end
                    
                    NV=[x-xm y-ym z-zm]/(norm([x-xm y-ym z-zm]))*0.05;
                    x=x+NV(1);
                    y=y+NV(2);
                    z=z+NV(3);
                    
                    %for n=1:i-1
                        %Diff=norm([app.P_C(n,:)-app.P_C(i,:)]);
                        %if Diff<10^-10
                        %    app.Texts(i)=text(app.UIAxes,x+0.1,y+0.1,z+0.1,sprintf('%d',i),'Color','c','FontSize',app.FontsizeSpinner.Value+2);
                        %    break
                        %else
                            app.Texts(i)=text(app.UIAxes,x,y,z,sprintf('%d',i),'Color','blue','FontSize',app.FontsizeSpinner.Value+2);
                        %end
                    %end

                    
                end


                con=i;

                try
                
                    xm=mean(app.P_C_2_Map(:,1));
                    ym=mean(app.P_C_2_Map(:,2)) ;               
                    zm=mean(app.P_C_2_Map(:,3));
    
    
                    for i=1:size(app.P_C_2_Map,1)

                        j=i+con;

                        x=app.P_C_2_Map(i,1);
                        y=app.P_C_2_Map(i,2);
                        z=app.P_C_2_Map(i,3);
                        
                        if x==0 && y==0 && z==0
                            x=x-0.01;
                            y=y-0.01;
                            z=z-0.01;                        
                        else
                            x=x+0.05;
                            y=y+0.05;
                            z=z+0.05;
                        end
                        
                        NV=[x-xm y-ym z-zm]/(norm([x-xm y-ym z-zm]))*0.05;
                        x=x+NV(1);
                        y=y+NV(2);
                        z=z+NV(3);
                        
                        %for n=1:i-1
                            % Diff=norm([app.P_C_2_Map(n,:)-app.P_C_2_Map(i,:)]);
                            %if Diff<10^-10
                            %    app.Texts(j)=text(app.UIAxes,x+0.1,y+0.1,z+0.1,sprintf('%d',i),'Color','c','FontSize',app.FontsizeSpinner.Value+2);
                            %    break
                            %else
                                app.Texts(j)=text(app.UIAxes,x,y,z,sprintf('%d',i),'Color','r','FontSize',app.FontsizeSpinner.Value+2);
                            %end
                        %end
    
                        
                    end    
                
                
                catch
                
                end


            else
                try
                   delete(app.Texts); 
                catch
                   app.Texts=[];
                end
            end
            
            
        end

        % Value changed function: WeightsCheckBox
        function WeightsCheckBoxValueChanged(app, event)
            value = app.WeightsCheckBox.Value;


            if value==1
                try
                    delete(app.Texts2);
                catch
                    app.Texts2=[];
                end
                for i=1:size(app.W,1)
                    x=app.INTP(i,1)+0.02;
                    y=app.INTP(i,2)+0.02;
                    z=app.INTP(i,3)+0.02;
                    
                    app.Texts2(i)=text(app.UIAxes,x,y,z,sprintf('%2.3f',app.W(i)),'Color','red','FontSize',app.FontsizeSpinner.Value);
                end
            else
                try
                   delete(app.Texts2); 
                catch
                   app.Texts2=[];
                end
            end
            
            
        end

        % Value changed function: DonotguessCheckBox
        function DonotguessCheckBoxValueChanged(app, event)
            value = app.DonotguessCheckBox.Value;
            
            if value==1
                
                app.PeelsSpinner.Value=2;
                
                app.IntegrationorderSpinner.Enable=1;
                
                % app.SubTetrahedralsDropDown.Enable=0;
            end
                
            if value ==0 && app.TetrahedronelementButton.Value==1
                app.IntegrationorderSpinner.Enable=0;
                
            end
            
        end

        % Value changed function: FontsizeSpinner
        function FontsizeSpinnerValueChanged(app, event)
            value = app.FontsizeSpinner.Value;
            
            
            set(app.UIAxes,'fontsize',value);
            
            if app.NodeNumbersCheckBox.Value==1
                NodeNumbersCheckBoxValueChanged(app);
            end
            if app.WeightsCheckBox.Value==1
                WeightsCheckBoxValueChanged(app);
            end
            
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.TextArea.Value{1}='Website: https://www.purdue.edu/rethi/';
        end

        % Callback function
        function AxisCheckBoxValueChanged(app, event)
            value = app.AxisCheckBox.Value;
            
            hold(app.UIFigure.CurrentAxes,'on');
            if value==1
                axis(app.UIAxes,'on')
            else
                axis(app.UIAxes,'off')
            end
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
             
        end

        % Value changed function: Element_Order
        function Element_OrderValueChanged(app, event)
            value = round(app.Element_Order.Value);
            
            app.Element_Order.Value=value;
            
            app.Spinner_2.Value=value;
            
            if value==5
                app.IntegrationorderSpinner.Value=1;
            end
            
            if value>4.5
                app.IntegrationorderSpinner.Enable=1;
                app.SubTetrahedralsDropDown.Enable=1;
            else
                app.IntegrationorderSpinner.Enable=0;
                app.SubTetrahedralsDropDown.Enable=0;
            end
            
        end

        % Value changing function: Element_Order
        function Element_OrderValueChanging(app, event)
%             changingValue = app.PolynomialorderalongxSlider.Value+app.PolynomialorderalongySlider.Value+app.PolynomialorderalongzSlider.Value;
%           
                changingValue = event.Value;

            if changingValue>4.9
                app.ElementorderLabel.FontColor='r';
            else
                app.ElementorderLabel.FontColor='k';
            end
        end

        % Value changing function: PolynomialorderalongxSlider
        function PolynomialorderalongxSliderValueChanging(app, event)
%             changingValue = event.Value;
%             
%             if changingValue>6
%                 app.ElementorderLabel.FontColor='r';
%             else
%                 app.ElementorderLabel.FontColor='k';
%             end
            
            
        end

        % Image clicked function: Image2
        function Image3Clicked(app, event)
            app.TextArea.Value{1}='My personal website: https://www.adnansh.com';

            exportapp(app.UIFigure,'appcontent.png')

        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            S5=sprintf('Saving...');
            app.TextArea.Value{9}=S5;
            pause(0.01)
            
            % Extract some info
                Nodes_Surf=zeros(1,5);
                s_prev=1;
                SegAdd=0;
                for s=2:size(app.P_C_Surf,1)
                    if app.P_C_Surf(s,5)>app.P_C_Surf(s-1,5) || s==size(app.P_C_Surf,1)

                        if s==size(app.P_C_Surf,1)
                            s_min=s_prev;
                            s_max=s;
                        else
                            s_min=s_prev;
                            s_max=s-1;
                        end
                        % Range=s_min:s_max;
                        % Surf_Shape_P_S(1:Number_of_IPs,:,app.INTP_Surf(s_max,4))=Shape_Matrix_Surface(Range,:);
                        NdS=app.P_C_Surf(s_min:s_max,1);
                        NdSS=unique(NdS);  

                        Nodes_Surf(SegAdd+1:SegAdd+length(NdSS),1:5)=[NdSS ones(length(NdSS),1)*app.P_C_Surf(s_max,5:8)];
                        SegAdd=size(Nodes_Surf,1);
                        s_prev=s_max+1;
                    end
                end

            assignin('base','Interpolation_Function',app.u_C);
            assignin('base','Node_Coordinates',app.P_C);
            assignin('base','Shape_Functions',app.N_C);
            assignin('base','Shape_Functions_by_dx',app.dNbydx);
            assignin('base','Shape_Functions_by_dy',app.dNbydy);
            assignin('base','Shape_Functions_by_dz',app.dNbydz);
            assignin('base','Integration_Points_Coordinates_Weights',[app.INTP app.W]);
            assignin('base','Parametric_Coordinates',app.V_CP_Sequence_S);
            % assignin('base','Differentiability_up_to_0',app.Order_Local);

            % assignin('base','Surface_Nodes_and_vectors',app.P_C_Surf);
            assignin('base','Surface_Nodes_and_vectors',Nodes_Surf);
            assignin('base','Integration_Points_Coordinates_Weights_Vectors_on_Surface',[app.INTP_Surf(:,1:3) app.W_Surf app.INTP_Surf(:,4:7)]);
            % assignin('base','Integration_Points_Weights_on_Surface',app.W_Surf);
            % assignin('base','Integration_Points_Vectors_on_Surface',app.INTP_Surf(:,5:7));
            
            if app.ShapeMatrixCheckBox.Value==1 || app.DerivativesofShapematrixCheckBox.Value==1
            x=sym('x');
            y=sym('y');
            z=sym('z');
            
            Shape_Matrix=zeros(size(app.INTP,1),size(app.P_C,1));
            dx_Shape_Matrix=zeros(size(app.INTP,1),size(app.P_C,1));
            dy_Shape_Matrix=zeros(size(app.INTP,1),size(app.P_C,1));
            dz_Shape_Matrix=zeros(size(app.INTP,1),size(app.P_C,1));


            Shape_Matrix_Surface=zeros(size(app.INTP_Surf,1),size(app.P_C,1));
            dx_Shape_Matrix_Surface=zeros(size(app.INTP_Surf,1),size(app.P_C,1));
            dy_Shape_Matrix_Surface=zeros(size(app.INTP_Surf,1),size(app.P_C,1));
            dz_Shape_Matrix_Surface=zeros(size(app.INTP_Surf,1),size(app.P_C,1));            

            for i=1:size(app.P_C,1)
                
                N_d=matlabFunction(app.N_C(i),'vars',[x y z]);
                N_d_x=matlabFunction(app.dNbydx(i),'vars',[x y z]);
                N_d_y=matlabFunction(app.dNbydy(i),'vars',[x y z]);
                N_d_z=matlabFunction(app.dNbydz(i),'vars',[x y z]);
                
                for gp=1:size(app.INTP,1)
                    if app.ShapeMatrixCheckBox.Value==1
                        Shape_Matrix(gp,i)=N_d(app.INTP(gp,1),app.INTP(gp,2),app.INTP(gp,3));
                    end
                        
                    if app.DerivativesofShapematrixCheckBox.Value==1    
                        dx_Shape_Matrix(gp,i)=N_d_x(app.INTP(gp,1),app.INTP(gp,2),app.INTP(gp,3));
                        dy_Shape_Matrix(gp,i)=N_d_y(app.INTP(gp,1),app.INTP(gp,2),app.INTP(gp,3));
                        dz_Shape_Matrix(gp,i)=N_d_z(app.INTP(gp,1),app.INTP(gp,2),app.INTP(gp,3));
                    end
                end

                for gp=1:size(app.INTP_Surf,1)
                    if app.ShapeMatrixCheckBox.Value==1
                        Shape_Matrix_Surface(gp,i)=N_d(app.INTP_Surf(gp,1),app.INTP_Surf(gp,2),app.INTP_Surf(gp,3));
                    end
                        
                    if app.DerivativesofShapematrixCheckBox.Value==1    
                        dx_Shape_Matrix_Surface(gp,i)=N_d_x(app.INTP_Surf(gp,1),app.INTP_Surf(gp,2),app.INTP_Surf(gp,3));
                        dy_Shape_Matrix_Surface(gp,i)=N_d_y(app.INTP_Surf(gp,1),app.INTP_Surf(gp,2),app.INTP_Surf(gp,3));
                        dz_Shape_Matrix_Surface(gp,i)=N_d_z(app.INTP_Surf(gp,1),app.INTP_Surf(gp,2),app.INTP_Surf(gp,3));
                    end
                end


            end
            assignin('base','Shape_Matrix',Shape_Matrix);
            assignin('base','Shape_Matrix_Surface',Shape_Matrix_Surface);
            assignin('base','dx_Shape_Matrix',dx_Shape_Matrix);
            assignin('base','dy_Shape_Matrix',dy_Shape_Matrix);
            assignin('base','dz_Shape_Matrix',dz_Shape_Matrix);
            assignin('base','dx_Shape_Matrix_Surface',dx_Shape_Matrix_Surface);
            assignin('base','dy_Shape_Matrix_Surface',dy_Shape_Matrix_Surface);
            assignin('base','dz_Shape_Matrix_Surface',dz_Shape_Matrix_Surface);
            
            end
            
            % Write in txt
            filename=(sprintf('Results'));
            TName=sprintf('%s.txt',filename);
            fileID = fopen(TName,'w');
            % fprintf(fileID,'Chosen order for x=%d \r\nChosen order for y=%d \r\nChosen order for z=%d \r\n',MaxDx,MaxDy,MaxDz);            
            fprintf(fileID,'\r\nInterpolation function, u=, \r\n      %s\r\n',app.u_Coeff);


%             if app.LagrangianFamilyCheckBox.Value==1 && app.HexahedronorBrickelementButton.Value==1
%                 fprintf(fileID,'\r\nNumber of nodes=%d',length(app.P_C));
%                 fprintf(fileID,'\r\nNode coordinates and Weights\r\n');
%                 fprintf(fileID,'     x       y       z       Weight\r\n');
%                 c=1;
%                 P=app.P_C;
%                 for ii=1:size(P,1)
%                     fprintf(fileID,'% 2.3f  % 2.3f  % 2.3f      % 2.5f\r\n',P(c,1),P(c,2),P(c,3),app.W_L(c,1));
%                     c=c+1;
%                 end            
% 
%                 assignin('base','Legendre_Gauss_Lobatto_Weights',app.W_L);
%                 
%             else            

                fprintf(fileID,'\r\nNumber of nodes=%d',length(app.P_C));
                fprintf(fileID,'\r\nNode coordinates\r\n');
                fprintf(fileID,'     x       y       z      Cx     Cy     Cz     \r\n');
                c=1;
                P=app.P_C;
                for ii=1:size(P,1)
                    fprintf(fileID,'% 2.3f  % 2.3f  % 2.3f      %d      %d      %d      \r\n',P(c,1),P(c,2),P(c,3),app.Order_Local(c,1),app.Order_Local(c,2),app.Order_Local(c,3));
                    c=c+1;
                end
            
%            end            


            fprintf(fileID,'\r\nShape Functions-----------------------\r\n\n');
            % fprintf(fileID,'\r\nN=\r\n');
            for ii=1:length(app.N_C)
                fprintf(fileID,'\r\nN%d=%s\r\n',ii,string(app.N_C(ii)));
                % fprintf(fileID,'    %s\r\n',string(N(i)));
            end
            
            fprintf(fileID,'\r\nIntegration points-----------------------\r\n\n');
            fprintf(fileID,'     x         y         z         w\r\n');
            c=1;
            for ii=1:size(app.INTP,1)
                fprintf(fileID,'% 2.5f  % 2.5f  % 2.5f  % 2.8f\r\n',app.INTP(c,1),app.INTP(c,2),app.INTP(c,3),app.W(c,1));
                c=c+1;
            end
            
            fclose(fileID);

            S5=sprintf('Results saved in Workspace and %s.txt file.',filename);
            app.TextArea.Value{9}=S5;
            pause(0.01)


            %% Build function file
            if  app.BuildFunctionFileCheckBox.Value==1

                S5=sprintf('Saving...');
                app.TextArea.Value{9}=S5;
                pause(0.01)


                list=dir('*.m');
                ElL=length(list);
                El_No=1;
                    try
                        for i=1:ElL
                            fcnName=list(i).name;
                            fcnName=erase(fcnName,'.m');
                            ElNum=str2num(fcnName(end-1:end));
                            if size(ElNum)==0
                                ElNum=str2num(fcnName(end));
                            end
                            if ElNum>=El_No
                                El_No=ElNum+1;
                            end
                        end
                    catch
                    end

                    
                if app.Type_C=='B'
                    filename=(sprintf('Integration_Points_Data_3D_Hexahedron_Node_%d_Type_%d',length(app.N_C),El_No));
                elseif app.Type_C=='T'
                    filename=(sprintf('Integration_Points_Data_3D_Tetrahedron_Node_%d_Type_%d',length(app.N_C),El_No));
                %elseif norm(app.V_CP_Sequence_S(:,:,8))>0 && norm(app.V_CP_Sequence_S(:,:,9))==0
                %    filename=(sprintf('Integration_Points_Data_3D_Wedge_Node_%d_Type_%d',length(app.N_C),El_No));
                else
                    filename=(sprintf('Integration_Points_Data_3D_Custom_Node_%d_Type_%d',length(app.N_C),El_No));
                end

                TName=sprintf('%s.m',filename);
                fileID = fopen(TName,'w');

                fprintf(fileID,'function[Nodes,INTP,W,Shape,Dshape,Surf_Nodes,Surf_INTP,Surf_W,Surf_Shape,Surf_Dshape,Parametric_Coordinates]=%s\r\n\n',filename);
               
                fprintf(fileID,'Nodes=[\r\n');
                c=1;
                P=app.P_C;
                for ii=1:size(P,1)
                    fprintf(fileID,'% 2.5f  % 2.5f  % 2.5f\r\n',P(c,1),P(c,2),P(c,3));
                    c=c+1;
                end
                fprintf(fileID,'];\r\n');

                % Integration points and weights
                fprintf(fileID,'INTP=[\r\n');
                c=1;
                for ii=1:size(app.INTP,1)
                    fprintf(fileID,'    % 2.5f  % 2.5f  % 2.5f\r\n',app.INTP(c,1),app.INTP(c,2),app.INTP(c,3));
                    c=c+1;
                end
                fprintf(fileID,'];\r\n');

                fprintf(fileID,'W=[\r\n');
                c=1;
                for ii=1:size(app.W,1)
                    fprintf(fileID,'    % 2.10f\r\n',app.W(c,1));
                    c=c+1;
                end
                fprintf(fileID,'];\r\n');
                fprintf(fileID,'\r\n');

                fprintf(fileID,'ngp=%d;\r\n',size(app.W,1));
                fprintf(fileID,'nnp=%d;\r\n',size(P,1));

                fprintf(fileID,'Shape=zeros(ngp,nnp);\r\n');
                fprintf(fileID,'Dshape=zeros(3,nnp,ngp);\r\n');
                   
                fprintf(fileID,'\r\n');
                %% Shape matrix

                fprintf(fileID,'for i=1:ngp\r\n');

                fprintf(fileID,'     x=INTP(i,1);\r\n');
                fprintf(fileID,'     y=INTP(i,2);\r\n');
                fprintf(fileID,'     z=INTP(i,3);\r\n');

                fprintf(fileID,'     Shape(i,1:nnp)=Shape_Functions(x,y,z);\r\n');

                fprintf(fileID,'\r\n');

                fprintf(fileID,'     [dNbydx,dNbydy,dNbydz]=Shape_Functions_Derivetives(x,y,z);\r\n');

                fprintf(fileID,'     Dshape(1,1:nnp,i)=dNbydx;\r\n');
                fprintf(fileID,'     Dshape(2,1:nnp,i)=dNbydy;\r\n');
                fprintf(fileID,'     Dshape(3,1:nnp,i)=dNbydz;\r\n');

                fprintf(fileID,'end\r\n');
                fprintf(fileID,'\r\n');


                %% Surface part

                gp_per_S=app.gp_per_Surfs;
                fprintf(fileID,'Surf_Nodes=zeros(%d,6);\r\n',1);
                
                s_prev=1;
                for s=2:size(app.P_C_Surf,1)
                    if app.P_C_Surf(s,5)>app.P_C_Surf(s-1,5) || s==size(app.P_C_Surf,1)

                        if s==size(app.P_C_Surf,1)
                            s_min=s_prev;
                            s_max=s;
                        else
                            s_min=s_prev;
                            s_max=s-1;
                        end
                        % Range=s_min:s_max;

                        % Surf_Shape_P_S(1:Number_of_IPs,:,app.INTP_Surf(s_max,4))=Shape_Matrix_Surface(Range,:);

                        NdS=app.P_C_Surf(s_min:s_max,1);
                        NdSS=unique(NdS);
                        fprintf(fileID,'    Surf_Nodes_L=[\r\n');
                        for c=1:length(NdSS)
                           fprintf(fileID,'     %d\r\n',NdSS(c));   
                           % fprintf(fileID,'\r\n');
                        end
                        fprintf(fileID,'    ];\r\n');   
                        fprintf(fileID,'Surf_Nodes(1:%d,%d)=Surf_Nodes_L;\r\n',c,app.P_C_Surf(s_max,5));
                        s_prev=s_max+1;

                    end
                end


                % Integration points and weights
%                 fprintf(fileID,'Surf_INTP=[\r\n');
%                 c=1;
%                 for ii=1:size(app.INTP_Surf,1)
%                     fprintf(fileID,'    % 2.5f  % 2.5f  % 2.5f\r\n',app.INTP_Surf(c,1),app.INTP_Surf(c,2),app.INTP_Surf(c,3));
%                     c=c+1;
%                 end
%                 fprintf(fileID,'];\r\n');


                %% Shape matrix

                % Surf_Shape_P_S=zeros(1,size(Shape_Matrix,2),6);
                W_Surf_Tab=zeros(1,max(app.INTP_Surf(:,4)));
                INTP_Surf_Tab=zeros(1,3,max(app.INTP_Surf(:,4)));
                Surf_ngp=zeros(1,max(app.INTP_Surf(:,4)));
                s_prev=1;
                for s=2:size(app.INTP_Surf,1)

                    if app.INTP_Surf(s,4)>app.INTP_Surf(s-1,4)

                        s_min=s_prev;
                        s_max=s-1;
                        Number_of_IPs=s-s_prev;
                       
                        Surf_ngp(1,app.INTP_Surf(s_max,4))=Number_of_IPs;
                        W_Surf_Tab(1:Number_of_IPs,app.INTP_Surf(s_max,4))=app.W_Surf(s_min:s_max);
                        INTP_Surf_Tab(1:Number_of_IPs,1:3,app.INTP_Surf(s_max,4))=app.INTP_Surf(s_min:s_max,1:3);
                        s_prev=s;
                    end

                    if s==size(app.INTP_Surf,1)
                        s_min=s_prev;
                        s_max=s;
                        Number_of_IPs=s-s_prev+1;
                        Surf_ngp(1,app.INTP_Surf(s_max,4))=Number_of_IPs;
                        W_Surf_Tab(1:Number_of_IPs,app.INTP_Surf(s_max,4))=app.W_Surf(s_min:s_max);
                        INTP_Surf_Tab(1:Number_of_IPs,1:3,app.INTP_Surf(s_max,4))=app.INTP_Surf(s_min:s_max,1:3);
                    end


                end


                fprintf(fileID,'Surf_W=[\r\n');
                for ii=1:size(W_Surf_Tab,1)
                    for jj=1:size(W_Surf_Tab,2)
                        fprintf(fileID,'      %f',W_Surf_Tab(ii,jj));
                    end
                   fprintf(fileID,'\r\n');
                end
                fprintf(fileID,'    ];\r\n');   

                fprintf(fileID,'Surf_ngp=[\r\n');
                for ii=1:size(W_Surf_Tab,2)
                   fprintf(fileID,'     %d',Surf_ngp(ii));
                end
                fprintf(fileID,'    ];\r\n');   

                fprintf(fileID,'Surf_INTP=zeros(max(Surf_ngp),3,length(Surf_ngp));\r\n');
                for ss=1:size(W_Surf_Tab,2)
                    fprintf(fileID,'INTP_Surf_L=[\r\n');
                    for jj=1:size(W_Surf_Tab,1)
                        fprintf(fileID,'      %f    %f      %f\r\n',INTP_Surf_Tab(jj,1,ss),INTP_Surf_Tab(jj,2,ss),INTP_Surf_Tab(jj,3,ss));
                    end
                    fprintf(fileID,'    ];\r\n'); 
                    fprintf(fileID,'Surf_INTP(1:%d,1:3,%d)=INTP_Surf_L;\r\n',jj,ss);
                end

                fprintf(fileID,'Surf_Shape=zeros(max(Surf_ngp),%d,length(Surf_ngp));\r\n',length(app.N_C));
                fprintf(fileID,'Surf_Dshape=zeros(3,%d,max(Surf_ngp),length(Surf_ngp));\r\n',length(app.N_C));

                fprintf(fileID,'\r\n'); 


                fprintf(fileID,'for ss=1:length(Surf_ngp)\r\n');
                fprintf(fileID,'    for gp=1:Surf_ngp(ss)\r\n');

                fprintf(fileID,'         x=Surf_INTP(gp,1,ss);\r\n');
                fprintf(fileID,'         y=Surf_INTP(gp,2,ss);\r\n');
                fprintf(fileID,'         z=Surf_INTP(gp,3,ss);\r\n');

                fprintf(fileID,'         Surf_Shape(gp,1:nnp,ss)=Shape_Functions(x,y,z);\r\n');

                fprintf(fileID,'\r\n');

                fprintf(fileID,'     [dNbydx,dNbydy,dNbydz]=Shape_Functions_Derivetives(x,y,z);\r\n');

                fprintf(fileID,'     Surf_Dshape(1,1:nnp,gp,ss)=dNbydx;\r\n');
                fprintf(fileID,'     Surf_Dshape(2,1:nnp,gp,ss)=dNbydy;\r\n');
                fprintf(fileID,'     Surf_Dshape(3,1:nnp,gp,ss)=dNbydz;\r\n');


                fprintf(fileID,'    end\r\n');
                fprintf(fileID,'end\r\n');
                fprintf(fileID,'\r\n');


                fprintf(fileID,'Parametric_Coordinates=zeros(3,2,length(Surf_ngp));\r\n');                
                
                for ss=1:size(W_Surf_Tab,2)
                    
                    fprintf(fileID,'P_C_L=[\r\n');
                    for ii=1:3
                        for jj=1:2
                            fprintf(fileID,'    %f',app.V_CP_Sequence_S(ii,jj,ss));
                        end
                        fprintf(fileID,'\r\n');
                    end
                    fprintf(fileID,'];\r\n');
                    fprintf(fileID,'Parametric_Coordinates(:,:,%d)=P_C_L;\r\n',ss);
                end
                

                    % fprintf(fileID,'CP_seq=[3    2     1    3   2   1\r\n');
                    % fprintf(fileID,'        2    3     3    1   1   2];\r\n');


                fprintf(fileID,'end\r\n');

                fprintf(fileID,'\r\n'); 


                fprintf(fileID,'function[N]=Shape_Functions(x,y,z)\r\n');
                fprintf(fileID,'N=[\r\n');
                for ii=1:length(app.N_C)
                        fprintf(fileID,'        %s\r\n',string(app.N_C(ii)));
                end
                fprintf(fileID,'];\r\n');   
                fprintf(fileID,'end\r\n'); 
               

                fprintf(fileID,'function[dNbydx,dNbydy,dNbydz]=Shape_Functions_Derivetives(x,y,z)\r\n');
                fprintf(fileID,'dNbydx=[\r\n');
                for ii=1:length(app.N_C)
                        fprintf(fileID,'        %s\r\n',string(app.dNbydx(ii)));
                end
                fprintf(fileID,'];\r\n');   

                fprintf(fileID,'dNbydy=[\r\n');
                for ii=1:length(app.N_C)
                        fprintf(fileID,'        %s\r\n',string(app.dNbydy(ii)));
                end
                fprintf(fileID,'];\r\n');   

                fprintf(fileID,'dNbydz=[\r\n');
                for ii=1:length(app.N_C)
                        fprintf(fileID,'        %s\r\n',string(app.dNbydz(ii)));
                end
                fprintf(fileID,'];\r\n');   
                fprintf(fileID,'end');

                fclose(fileID);

                S5=sprintf('Results saved in Workspace and %s.m file.',filename);
                app.TextArea.Value{9}=S5;

            end

            
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
             % P=str2num(app.EditField_2.Value);

             PT=table2array(app.UITable.Data);
             P=PT(:,2:4);

             % sym(app.UITable2.Data)

             cla(app.UIAxes);
             
            app.TextArea.Value{1}='';
            
             
             try
                 DT = delaunayTriangulation(P(:,1),P(:,2),P(:,3));
                 [K] = convexHull(DT);
                 SDT=1;
                for i=1:size(P,1)
                     for j=1:size(P,1)
                         if i~=j
                            if norm(P(i,:)-P(j,:))==0
                             SDT=0;
                            end
                         end
                     end
                 end

             catch
                 SDT=0;
             end


            if SDT==0
               S1=sprintf('Invalid Nodal coordinates input! Several nodes with same coordinate possibly!');
               app.TextArea.Value{2}=S1;
               app.CheckButton.BackgroundColor='r';
               hold(app.UIFigure.CurrentAxes,'on');
               scatter3(app.UIAxes,P(:,1),P(:,2),P(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');


               % app.EditField_2.BackgroundColor='r';
               
            else
                S1=sprintf('Seems like a valid Nodal coordinates input! Number of nodes=%d',size(P,1));
                app.TextArea.Value{2}=S1;
                S2=sprintf('Check the plot for nodal distribution.');
                app.TextArea.Value{3}=S2;
                app.CheckButton.BackgroundColor='w';
                % app.EditField_2.BackgroundColor='w';
                % app.EditField.Enable=1;
                
                hold(app.UIFigure.CurrentAxes,'on');
                scatter3(app.UIAxes,P(:,1),P(:,2),P(:,3),30,'MarkerEdgeColor','k','MarkerFaceColor','k');

                if size(DT.Points,1)>10 && app.EdgesCheckBox.Value==0
                    trisurf(K,DT.Points(:,1),DT.Points(:,2),DT.Points(:,3),'EdgeColor','none','FaceColor','c','FaceAlpha',0.1,'Parent', app.UIAxes)
                else
                    trisurf(K,DT.Points(:,1),DT.Points(:,2),DT.Points(:,3),'EdgeColor','k','FaceColor','c','FaceAlpha',0.1,'Parent', app.UIAxes)
                end
                rotate3d(app.UIFigure.CurrentAxes,'on');

                
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
                
            app.N_C_2=[];
            app.P_C_2=[];

            % Execute the main code 
            app.N_C=[];
            app.Dom_C=[];
            app.Type_C='';
            app.Sx_C=[];
            app.Sy_C=[];
            app.Sz_C=[];  
            app.u_C=[];
            app.P_C=[]; 
            app.dNbydx=[];
            app.dNbydy=[];
            app.dNbydz=[];
            app.d2Nbydx2=[];
            app.d2Nbydy2=[];
            app.d2Nbydz2=[];
            app.X_Order=[];
            app.Y_Order=[];
            app.Z_Order=[];
            app.SFm_C=[];
            app.Peels_C=[];
            app.INTP=[];
            app.W=[];
            app.W_L=[];
            app.hIsosurf=[];
            app.hSlice=[];
            app.SNP=[];
            app.SGP=[];
            app.Texts=[];
            app.Texts2=[];
            app.u_Coeff=[];
            app.Der_Order=[];
            app.Texts3=[];
            app.SNP1=[];
            app.SNP2=[];
            app.INTP_Surf=[];
            app.W_Surf=[];
            app.gp_per_Surfs=[];
            app.SGPS=[];
            app.d2Nbydz2C=[];
            app.d2Nbydz2C2=[];
            app.Order_Local=[];
            app.P_C_Surf=[];


            % Clean up text box
            app.TextArea.FontColor='k';
            
            app.TextArea.Value{1}='';
            app.TextArea.Value{2}='';
            app.TextArea.Value{3}='';
            app.TextArea.Value{4}='';
            app.TextArea.Value{5}='';
            app.TextArea.Value{6}='';
            app.TextArea.Value{7}='';
            app.TextArea.Value{8}='';
            app.TextArea.Value{9}='';
            app.TextArea.Value{10}='';
            app.TextArea.Value{11}='';
            app.TextArea.Value{12}='';
            app.TextArea.Value{13}='';
            app.TextArea.Value{14}='';    
            app.TextArea_2.Value='';

            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);

            app.CheckinterelementcompatibilityButton.Enable=0;
            app.HexahedronorBrickelementButton.Value=1;

            ChoiceofelementButtonGroupSelectionChanged(app, 1);


            try
                delete(app.SNP1);
                delete(app.SNP2);
                delete(app.Tri1);
                delete(app.Tri2);
                title(app.UIAxes,sprintf(''));
            catch
            end


            % app.GenerateshapefunctionsButton.Enable=0;
            
%                 app.TextArea.Value{1}='';
% 
%                 P=str2num(app.EditField_2.Value);
%                 
%                 x=sym('x');
%                 y=sym('y');
%                 z=sym('z');
%                
%                 try
%                     uT=table2array(app.UITable2.Data);
%                     uTV=uT(:,2);
%                     u=str2sym(uTV);
% 
%                     % u=str2sym(app.EditField.Value);
%                 catch
%                     u=[];
%                 end
%              
%  
%                 if size(u,2)==size(P,1)
%                     S1=sprintf('Looks like valid Function Input! You may hit "Generate shape functions!" button');
%                     app.TextArea.Value{2}=S1;
%                     % app.GenerateshapefunctionsButton.Enable=1;
%                     app.EditField.BackgroundColor='w';
%                 else
%                     S1=sprintf('Invalid Function Input! Function has %d terms where you have %d nodes',size(u,2),size(P,1));
%                     app.TextArea.Value{2}=S1;
%                     app.EditField.BackgroundColor='r';
%                 end
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)

            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)

            
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
                 value1 = app.EditField_7.Value;
                 value2 = app.EditField_8.Value;
                 view(app.UIAxes,value1,value2); 
        end

        % Callback function
        function ButtonPushed(app, event)
            orient(GUI_Screen,'landscape');
            print(GUI_Screen,'-fillpage', '-dpng','-r800')
        end

        % Callback function
        function ExecuteButtonPushed(app, event)
            
                file = app.EditField_9.Value;
                
                eval(file);
            
            
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            eval(value);
        end

        % Value changed function: NumberofNodesSpinner
        function NumberofNodesSpinnerValueChanged(app, event)

            
            value = app.NumberofNodesSpinner.Value;
            
            if value<4
                value=4;
                app.NumberofNodesSpinner.Value=value;
            end

            Initial_Data=app.UITable.Data;
            Initial_Length=size(Initial_Data,1);

            Tab_tot=table('Size',[value 4],'VariableTypes',{'double','double','double','double'});
            for i=1:value
                Tab_tot(i,1)={i};
            end
            if Initial_Length<value
                for i=1:Initial_Length
                    Tab_tot{i,:}=Initial_Data{i,:};
                end
            elseif Initial_Length>value
                for i=1:value
                    Tab_tot{i,:}=Initial_Data{i,:};
                end                
            end
            
            app.UITable.Data=Tab_tot;


            Initial_Data2=app.UITable2.Data;
            Initial_Length2=size(Initial_Data2,1);


            Tab_tot2=table('Size',[value,2],'VariableTypes',{'string','string'});
            for i=1:value
                String=sprintf('c%d',i);
                Tab_tot2(i,1)={String};
            end
            if Initial_Length2<value
                for i=1:Initial_Length2
                    Tab_tot2{i,:}=Initial_Data2{i,:};
                end
            elseif Initial_Length2>value
                for i=1:value
                    Tab_tot2{i,:}=Initial_Data2{i,:};
                end                
            end
            
            app.UITable2.Data=Tab_tot2;

        end

        % Button pushed function: AutofillButton
        function AutofillButtonPushed(app, event)
            
            LP=app.NumberofNodesSpinner.Value;

            LP3=round((LP+1)^(1/3));

            [u]=Automatic_Function_Generator_L(LP3,LP3,LP3,LP);

            value = app.NumberofNodesSpinner.Value;
            Tab_tot2=table('Size',[value,2],'VariableTypes',{'string','string'});
            for i=1:value
                String=sprintf('c%d',i);
                Tab_tot2(i,1)={String};
                Tab_tot2(i,2)={string(u(i))};
            end
            app.UITable2.Data=Tab_tot2;


            function[Series]=Automatic_Function_Generator_L(MaxDx,MaxDy,MaxDz,Terms)
            
            x=sym('x');
            y=sym('y');
            z=sym('z');
            
            Length=(MaxDx+1)*(MaxDy+1)*(MaxDz+1);
            
            Series_Cal=zeros(Length,6);
            Series_Cal=sym(Series_Cal);
            
            if Terms<Length
                %fprintf('Generating function assuming Serendipity element...')
                Serendipity=1;
            elseif Terms==Length
                %fprintf('Generating function assuming Lagrange element...')
                Serendipity=0;
            end
            
            
            %%
            c=1;
            n=0;
            while c<=Length
                for m=0:n
                    for k=0:m
                        % Coeff=factorial(n)/(factorial(n-m)*factorial(m-k)*factorial(k));
                        if (n-m)<=MaxDx && (m-k)<=MaxDy && k<=MaxDz
                            Exp=x^(n-m)*y^(m-k)*z^k;
                            Series_Cal(c,1)=Exp;
                            
                            Series_Cal(c,2)=max([n-m m-k k]);
                            
                            Series_Cal(c,3)=sum([n-m]);
                            Series_Cal(c,4)=sum([m-k]);
                            Series_Cal(c,5)=sum([k]);
                            Series_Cal(c,6)=sum([n-m m-k k]);
                            c=c+1;
                        end
                        if c>=Length+1
                            break
                        end
                    end
                end
                n=n+1;
                
                if n>100
                    %fprintf('Too many iterations...Breaking the loop')
                    break
                end
            end
            
            %%
            
            % Series_Cal=sortrows(Series_Cal,2);
            
            if Serendipity==1
                
                c=1;
                for i=1:length(Series_Cal)
            
                    LogicX=double(Series_Cal(i,3))>1 && (double(Series_Cal(i,4))>1 || double(Series_Cal(i,5))>1);
                    LogicY=double(Series_Cal(i,4))>1 && (double(Series_Cal(i,3))>1 || double(Series_Cal(i,5))>1);
                    LogicZ=double(Series_Cal(i,5))>1 && (double(Series_Cal(i,3))>1 || double(Series_Cal(i,4))>1);
            
            
                    if LogicX==0 && LogicY==0 && LogicZ==0
                        Series(1,c)=Series_Cal(i,1);
            
                        % SeriesP(c,1:6)=Series_Cal(i,1:6);
            
                        c=c+1;
                    end
            
            
                end
            
            else
                Series=transpose(Series_Cal(:,1));
            end


            if length(Series)==Terms
                %fprintf('Number of terms matches\n');
            else
                %error('Number of terms didnt matchsdfsdfsdf\n');
            end
            
            
            % Series_Cal=sortrows(Series_Cal,2);


            % Series=Series(1:Terms);
            
            end

        end

        % Callback function
        function DerivetiveordermSpinnerValueChanged(app, event)
            value = app.DerivetiveordermSpinner.Value;
            
            app.Der_Order=value;

        end

        % Value changed function: AxisCheckBox
        function AxisCheckBoxValueChanged2(app, event)
            value = app.AxisCheckBox.Value;
            
            if value==1
                axis(app.UIAxes,'on')
            else
                axis(app.UIAxes,'off')
            end

        end

        % Callback function
        function OrderlimitSpinnerValueChanged(app, event)


        end

        % Value changed function: OrderLimitSpinner_2
        function OrderLimitSpinner_2ValueChanged(app, event)
            
            value = round(app.OrderLimitSpinner_2.Value);
            app.OrderLimitSpinner_2.Value=value;


            app.Element_Order.Limits=[1 value];
            app.Element_Order.MajorTicks=[1:value];

            app.PolynomialorderalongxSlider.Limits=[1 value];
            app.PolynomialorderalongySlider.Limits=[1 value];
            app.PolynomialorderalongzSlider.Limits=[1 value];

            app.PolynomialorderalongxSlider.MajorTicks=[1:value];
            app.PolynomialorderalongySlider.MajorTicks=[1:value];
            app.PolynomialorderalongzSlider.MajorTicks=[1:value];

            
        end

        % Callback function
        function GenerateshapefunctionsButton_2Pushed(app, event)
 

            exportapp(app.UIFigure,'appcontent.png')

            % print(app.UIFigure,'-djpeg','-r500','filename')


        end

        % Button pushed function: ImportElementButton
        function ImportElementButtonPushed2(app, event)


            if app.ImportelementDropDown.Value=='Previous Element'            
                Nodes=app.P_C;
            end

            % assignin('base','Interpolation_Function',app.u_C);
            % assignin('base','Node_Coordinates',app.P_C);

            if app.ImportelementDropDown.Value=='  From Workspace'
                try
                    Nodes=evalin('base','Node_Coordinates');
                catch
                    Nodes=[];
                end
            end
                    

            if size(Nodes,1)==0
               S1=sprintf('No nodes to import! If loading from workspace make sure the file name is "Node_Coordinates"');
               app.TextArea.Value{2}=S1;
               % app.CheckButton.BackgroundColor='r';
            else
                value=size(Nodes,1);
                app.NumberofNodesSpinner.Value=value;
                Tab_tot=table('Size',[value 4],'VariableTypes',{'double','double','double','double'});
                for i=1:value
                    Tab_tot(i,1)={i};
                end
                Tab_tot(1:value,2:4)=num2cell(Nodes);
                app.UITable.Data=Tab_tot;
            
                Initial_Data2=app.UITable2.Data;
                Initial_Length2=size(Initial_Data2,1);

                S1=sprintf('Nodes are imported successfully');
                app.TextArea.Value{2}=S1;


            end


            syms x y z
            % u=[1; x; y; z; x*y; y*z; x*z; x^2;y^2;z^2; x*y*z; x^2*y; x^2*z; y^2*x; y^2*z; z^2*x; z^2*y; x^2*y*z; x*y^2*z; x*y*z^2; x^2*y^2*(z+1)];

            if app.ImportelementDropDown.Value=='Previous Element'  
                u=(app.u_C);
            end

            if app.ImportelementDropDown.Value=='  From Workspace'
                try
                    u=evalin('base','Interpolation_Function');
                catch
                    u=[];
                end
            end


            if size(u,1)==0
               S1=sprintf('No functions to import! If loading from workspace make sure the file name is "Interpolation_Function"');
               app.TextArea.Value{3}=S1;
               % app.CheckButton.BackgroundColor='r';
            else
                value=size(u,2);
                Tab_tot2=table('Size',[value,2],'VariableTypes',{'string','string'});
                for i=1:value
                    String=sprintf('c%d',i);
                    Tab_tot2(i,1)={String};
                    Tab_tot2(i,2)={string(u(i))};
                end
                app.UITable2.Data=Tab_tot2;
                app.UITable2.Data=Tab_tot2;
                S1=sprintf('Functions are imported successfully');
                app.TextArea.Value{2}=S1;


            end
            
        end

        % Value changed function: CalculatederivetivesCheckBox
        function CalculatederivetivesCheckBoxValueChanged(app, event)
            value = app.CalculatederivetivesCheckBox.Value;
            
%             if value==1
%                 app.DropDown.Enable=1;
%             else
%                 app.DropDown.Enable=0;
%             end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Value changed function: SurfaceIntegrationpointsCheckBox
        function SurfaceIntegrationpointsCheckBoxValueChanged(app, event)
            value = app.SurfaceIntegrationpointsCheckBox.Value;


               if value==1
                    app.SGPS=gobjects(size(app.INTP_Surf,1));
                    for d=1:size(app.INTP_Surf,1)
                        
                        app.SGPS(d)=scatter3(app.UIAxes,app.INTP_Surf(d,1),app.INTP_Surf(d,2),app.INTP_Surf(d,3),30,'MarkerEdgeColor','g','MarkerFaceColor','g');
                        
                    end
                    
                else
                    delete(app.SGPS);
                end
            
        end

        % Value changed function: WeightsCheckBox_2
        function WeightsCheckBox_2ValueChanged(app, event)
            value = app.WeightsCheckBox_2.Value;
            

            if value==1
                try
                    delete(app.Texts3);
                catch
                    app.Texts3=[];
                end
                for i=1:size(app.W_Surf,1)
                    x=app.INTP_Surf(i,1)+0.02;
                    y=app.INTP_Surf(i,2)+0.02;
                    z=app.INTP_Surf(i,3)+0.02;
                    
                    app.Texts3(i)=text(app.UIAxes,x,y,z,sprintf('%2.3f',app.W_Surf(i)),'Color','g','FontSize',app.FontsizeSpinner.Value);
                end
            else
                try
                   delete(app.Texts3); 
                catch
                   app.Texts3=[];
                end
            end


        end

        % Value changed function: ShapeMatrixCheckBox
        function ShapeMatrixCheckBoxValueChanged(app, event)
            value = app.ShapeMatrixCheckBox.Value;
            
        end

        % Value changed function: ImportelementDropDown
        function ImportelementDropDownValueChanged(app, event)
            value = app.ImportelementDropDown.Value;
            
        end

        % Callback function
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;


%             value = app.NumberofNodesSpinner.Value;
%             
%             if value<4
%                 value=4;
%                 app.NumberofNodesSpinner.Value=value;
%             end
% 

%              Initial_Length=size(app.UITable.Data,1);
%              New_Data=app.UITable.Data{setdiff(1:Initial_Length,selection),2:4};
%              New_Length=Initial_Length-1;
%              Tab_tot=table('Size',[New_Length 4],'VariableTypes',{'double','double','double','double'});
%              for i=1:New_Length
%                  Tab_tot{i,1}=i;
%              end
%              for i=1:New_Length
%                  Tab_tot{i,2:4}=New_Data(i,:);
%              end
%              app.UITable.Data=Tab_tot;


%             
% 
% 
%             Initial_Data2=app.UITable2.Data;
%             Initial_Length2=size(Initial_Data2,1);
% 
% 
%             Tab_tot2=table('Size',[value,2],'VariableTypes',{'string','string'});
%             for i=1:value
%                 String=sprintf('c%d',i);
%                 Tab_tot2(i,1)={String};
%             end
%             if Initial_Length2<value
%                 for i=1:Initial_Length2
%                     Tab_tot2{i,:}=Initial_Data2{i,:};
%                 end
%             elseif Initial_Length2>value
%                 for i=1:value
%                     Tab_tot2{i,:}=Initial_Data2{i,:};
%                 end                
%             end
%             
%             app.UITable2.Data=Tab_tot2;


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed2(app, event)
              
             try
             selection = app.UITable.Selection(1);

             Initial_Length=size(app.UITable.Data,1);
             New_Data=app.UITable.Data{setdiff(1:Initial_Length,selection),2:4};
             New_Length=Initial_Length-1;
             Tab_tot=table('Size',[New_Length 4],'VariableTypes',{'double','double','double','double'});
             for i=1:New_Length
                 Tab_tot{i,1}=i;
             end
             for i=1:New_Length
                 Tab_tot{i,2:4}=New_Data(i,:);
             end
             app.UITable.Data=Tab_tot;
             app.NumberofNodesSpinner.Value=New_Length;
             catch
             end


        end

        % Value changing function: NumberofNodesSpinner
        function NumberofNodesSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
             

            try
             selection = app.UITable2.Selection(1);

             Initial_Length=size(app.UITable2.Data,1);
             New_Data=app.UITable2.Data{setdiff(1:Initial_Length,selection),2};
             value=Initial_Length-1;

            Tab_tot2=table('Size',[value,2],'VariableTypes',{'string','string'});

            for i=1:value
                String=sprintf('c%d',i);
                Tab_tot2(i,1)={String};
            end

            for i=1:value
                Tab_tot2{i,2}=New_Data(i,1);
            end
            
            app.UITable2.Data=Tab_tot2;
            catch
            end


        end

        % Value changed function: ParametriccoordinatesCheckBox
        function ParametriccoordinatesCheckBoxValueChanged(app, event)
            value = app.ParametriccoordinatesCheckBox.Value;


            if app.ParametricaxiscolorDropDown.Value=='  red'
                XCOLOR='r';
            elseif app.ParametricaxiscolorDropDown.Value==' blue'
                XCOLOR='b';
            elseif app.ParametricaxiscolorDropDown.Value=='green'
                XCOLOR='g';
            else
                XCOLOR='k';
            end
                       
            if app.yDropDown.Value=='  red'
                YCOLOR='r';
            elseif app.yDropDown.Value==' blue'
                YCOLOR='b';
            elseif app.yDropDown.Value=='green'
                YCOLOR='g';
            else
                YCOLOR='k';
            end           
            
            if app.zDropDown.Value=='  red'
                ZCOLOR='r';
            elseif app.zDropDown.Value==' blue'
                ZCOLOR='b';
            elseif app.zDropDown.Value=='green'
                ZCOLOR='g';
            else
                ZCOLOR='k';
               
            end         


               if value==1
                    app.SQUIV=gobjects(size(app.INTP_Surf,3));
                    for d=1:size(app.INTP_Surf,1)
                        
                        app.SQUIV(d,1)=quiver3(app.UIAxes,app.INTP_Surf(d,1),app.INTP_Surf(d,2),app.INTP_Surf(d,3),app.INTP_Surf(d,5),app.INTP_Surf(d,6),app.INTP_Surf(d,7),ZCOLOR);

                        ST=app.V_CP_Sequence_S(:,:,app.INTP_Surf(d,4));

                        V1=ST(:,1); V2=ST(:,2);
                        app.SQUIV(d,2)=quiver3(app.UIAxes,app.INTP_Surf(d,1),app.INTP_Surf(d,2),app.INTP_Surf(d,3),V1(1),V1(2),V1(3),XCOLOR);
                        app.SQUIV(d,3)=quiver3(app.UIAxes,app.INTP_Surf(d,1),app.INTP_Surf(d,2),app.INTP_Surf(d,3),V2(1),V2(2),V2(3),YCOLOR);

                        % app.SGPS(d)=scatter3(app.UIAxes,app.INTP_Surf(d,1),app.INTP_Surf(d,2),app.INTP_Surf(d,3),30,'MarkerEdgeColor','g','MarkerFaceColor','g');
                        
                    end
                    
                else
                    delete(app.SQUIV);
                end

            
        end

        % Value changed function: ParametricaxiscolorDropDown
        function ParametricaxiscolorDropDownValueChanged(app, event)
            value = app.ParametricaxiscolorDropDown.Value;
            try
                delete(app.SQUIV);
                ParametriccoordinatesCheckBoxValueChanged(app, 1);
            end
        end

        % Value changed function: yDropDown
        function yDropDownValueChanged(app, event)
            value = app.yDropDown.Value;
            try
                delete(app.SQUIV);
                ParametriccoordinatesCheckBoxValueChanged(app, 1);
            end
        end

        % Value changed function: zDropDown
        function zDropDownValueChanged(app, event)
            value = app.zDropDown.Value;
            try
                delete(app.SQUIV);
                ParametriccoordinatesCheckBoxValueChanged(app, 1);
            end
        end

        % Value changed function: SurfacenoofpreviouselementSpinner
        function SurfacenoofpreviouselementSpinnerValueChanged(app, event)

                % Comparer(app);

                Extra_Plotter(app);

        end

        % Value changed function: SurfacenoofnewelementSpinner
        function SurfacenoofnewelementSpinnerValueChanged(app, event)
            value = app.SurfacenoofnewelementSpinner.Value;

            % Comparer(app);

            Extra_Plotter(app);
            
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            
            if app.Spinner_3.Value>length(app.N_C_2)
                app.Spinner_3.Value=length(app.N_C_2);
            end
            
            value = app.Spinner_3.Value;

            app.SFm_C_2 = value;
            app.Peels_C=app.PeelsSpinner.Value;


            Comparer(app);            
            
            
            % no=app.ResolutionSpinner.Value;
            
%             cla(app.UIAxes);    
%             app.TextArea_2.Value='';
%             
%             Verify_Shape_Functions_2(app);
%             Graphics_Manipulator_2(app);
%             
%             if app.NodeNumbersCheckBox.Value==1
%                 NodeNumbersCheckBoxValueChanged(app);
%             end
%             if app.WeightsCheckBox.Value==1
%                 WeightsCheckBoxValueChanged(app);
%             end


        end

        % Value changed function: RotateDegButton
        function RotateDegButtonValueChanged(app, event)
            value = app.RotateDegButton.Value;
            
            app.Th=app.ToleranceEditField_2.Value;
            
            Comparer(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Enable = 'off';
            app.ChoiceofelementButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoiceofelementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoiceofelementButtonGroupSelectionChanged, true);
            app.ChoiceofelementButtonGroup.Title = '    Choice of element?';
            app.UIAxes2_2 = uiaxes(app.ChoiceofelementButtonGroup);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes2 = uiaxes(app.ChoiceofelementButtonGroup);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.EditField_5 = uieditfield(app.ChoiceofelementButtonGroup, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Enable = 'off';
            app.HexahedronorBrickelementButton = uiradiobutton(app.ChoiceofelementButtonGroup);
            app.HexahedronorBrickelementButton.Text = 'Hexahedron or Brick element';
            app.HexahedronorBrickelementButton.Value = true;
            app.ArbitraryorCustomelementButton = uiradiobutton(app.ChoiceofelementButtonGroup);
            app.ArbitraryorCustomelementButton.Text = 'Arbitrary or Custom element';
            app.CheckinterelementcompatibilityButton = uiradiobutton(app.ChoiceofelementButtonGroup);
            app.CheckinterelementcompatibilityButton.Enable = 'off';
            app.CheckinterelementcompatibilityButton.Text = 'Check interelement compatibility';
            app.TetrahedronelementButton = uiradiobutton(app.ChoiceofelementButtonGroup);
            app.TetrahedronelementButton.Text = 'Tetrahedron element';
            app.PolynomialorderalongxSlider = uislider(app.ChoiceofelementButtonGroup);
            app.PolynomialorderalongxSlider.Limits = [1 8];
            app.PolynomialorderalongxSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolynomialorderalongxSlider.ValueChangedFcn = createCallbackFcn(app, @PolynomialorderalongxSliderValueChanged, true);
            app.PolynomialorderalongxSlider.ValueChangingFcn = createCallbackFcn(app, @PolynomialorderalongxSliderValueChanging, true);
            app.PolynomialorderalongxSlider.MinorTicks = [];
            app.PolynomialorderalongxSlider.Value = 1;
            app.PolynomialorderalongySlider = uislider(app.ChoiceofelementButtonGroup);
            app.PolynomialorderalongySlider.Limits = [1 8];
            app.PolynomialorderalongySlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolynomialorderalongySlider.ValueChangedFcn = createCallbackFcn(app, @PolynomialorderalongySliderValueChanged, true);
            app.PolynomialorderalongySlider.MinorTicks = [];
            app.PolynomialorderalongySlider.Value = 2;
            app.PolynomialorderalongzSlider = uislider(app.ChoiceofelementButtonGroup);
            app.PolynomialorderalongzSlider.Limits = [1 8];
            app.PolynomialorderalongzSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolynomialorderalongzSlider.ValueChangedFcn = createCallbackFcn(app, @PolynomialorderalongzSliderValueChanged, true);
            app.PolynomialorderalongzSlider.MinorTicks = [];
            app.PolynomialorderalongzSlider.Value = 1;
            app.PolynomialDegreealongxLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.PolynomialDegreealongxLabel.Text = 'Polynomial Degree along x';
            app.PolynomialDegreealongyLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.PolynomialDegreealongyLabel.Text = 'Polynomial Degree along y';
            app.PolynomialDegreealongzLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.PolynomialDegreealongzLabel.Text = 'Polynomial Degree along z';
            app.LagrangianFamilyCheckBox = uicheckbox(app.ChoiceofelementButtonGroup);
            app.LagrangianFamilyCheckBox.Text = 'Lagrangian Family?';
            app.nodeexampleButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.nodeexampleButton.ButtonPushedFcn = createCallbackFcn(app, @nodeexampleButtonPushed2, true);
            app.nodeexampleButton.Enable = 'off';
            app.nodeexampleButton.Visible = 'off';
            app.nodeexampleButton.Text = '21 node example';
            app.WritethefunctionyouwanttoplotLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.WritethefunctionyouwanttoplotLabel.Visible = 'off';
            app.WritethefunctionyouwanttoplotLabel.Text = 'Write the function you want to plot';
            app.WritetheNodecoordinatesLabel_2 = uilabel(app.ChoiceofelementButtonGroup);
            app.WritetheNodecoordinatesLabel_2.Visible = 'off';
            app.WritetheNodecoordinatesLabel_2.Text = 'Write the Node coordinates';
            app.ElementorderLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.ElementorderLabel.Visible = 'off';
            app.ElementorderLabel.Text = 'Element order';
            app.LegendreGaussLobattonodesCheckBox = uicheckbox(app.ChoiceofelementButtonGroup);
            app.LegendreGaussLobattonodesCheckBox.Enable = 'off';
            app.LegendreGaussLobattonodesCheckBox.Visible = 'off';
            app.LegendreGaussLobattonodesCheckBox.Text = 'Legendre-Gauss-Lobatto nodes?';
            app.LegendreGaussLobattonodesCheckBox.Value = true;
            app.Element_Order = uislider(app.ChoiceofelementButtonGroup);
            app.Element_Order.Limits = [1 8];
            app.Element_Order.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Element_Order.ValueChangedFcn = createCallbackFcn(app, @Element_OrderValueChanged, true);
            app.Element_Order.ValueChangingFcn = createCallbackFcn(app, @Element_OrderValueChanging, true);
            app.Element_Order.MinorTicks = [];
            app.Element_Order.Visible = 'off';
            app.Element_Order.Value = 1;
            app.SubTetrahedralsDropDown = uidropdown(app.ChoiceofelementButtonGroup);
            app.SubTetrahedralsDropDown.Items = {'01', '08', '64'};
            app.SubTetrahedralsDropDown.Enable = 'off';
            app.SubTetrahedralsDropDown.Visible = 'off';
            app.SubTetrahedralsDropDown.Value = '08';
            app.NumberofSubTetrahedronsLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.NumberofSubTetrahedronsLabel.Visible = 'off';
            app.NumberofSubTetrahedronsLabel.Text = 'Number of Sub-Tetrahedrons?';
            app.NumberofNodesSpinner = uispinner(app.ChoiceofelementButtonGroup);
            app.NumberofNodesSpinner.ValueChangingFcn = createCallbackFcn(app, @NumberofNodesSpinnerValueChanging, true);
            app.NumberofNodesSpinner.Limits = [0 Inf];
            app.NumberofNodesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofNodesSpinnerValueChanged, true);
            app.NumberofNodesSpinner.Visible = 'off';
            app.NumberofNodesSpinnerLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.NumberofNodesSpinnerLabel.Visible = 'off';
            app.NumberofNodesSpinnerLabel.Text = 'Number of Nodes';
            app.OrderLimitSpinner_2 = uispinner(app.ChoiceofelementButtonGroup);
            app.OrderLimitSpinner_2.Limits = [8 Inf];
            app.OrderLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @OrderLimitSpinner_2ValueChanged, true);
            app.OrderLimitSpinner_2.Value = 8;
            app.Order_Limit_Label = uilabel(app.ChoiceofelementButtonGroup);
            app.Order_Limit_Label.Text = 'Order limit';
            app.ImportElementButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.ImportElementButton.ButtonPushedFcn = createCallbackFcn(app, @ImportElementButtonPushed2, true);
            app.ImportElementButton.Visible = 'off';
            app.ImportElementButton.Text = 'Import Element';
            app.InputNodecoordinatesLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.InputNodecoordinatesLabel.Visible = 'off';
            app.InputNodecoordinatesLabel.Text = 'Input Node coordinates';
            app.CheckButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Visible = 'off';
            app.CheckButton.Text = 'Check';
            app.UITable = uitable(app.ChoiceofelementButtonGroup);
            app.UITable.ColumnName = {'Node no'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Visible = 'off';
            app.UITable2 = uitable(app.ChoiceofelementButtonGroup);
            app.UITable2.ColumnName = {'Coeff.'; 'Polynomial term'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true];
            app.UITable2.Visible = 'off';
            app.InputInterpolationfunctionLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.InputInterpolationfunctionLabel.Visible = 'off';
            app.InputInterpolationfunctionLabel.Text = 'Input Interpolation function';
            app.AutofillButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.AutofillButton.ButtonPushedFcn = createCallbackFcn(app, @AutofillButtonPushed, true);
            app.AutofillButton.Visible = 'off';
            app.AutofillButton.Text = 'Autofill';
            app.IntegrationorderSpinner = uispinner(app.ChoiceofelementButtonGroup);
            app.IntegrationorderSpinner.Limits = [1 4];
            app.IntegrationorderSpinner.RoundFractionalValues = 'on';
            app.IntegrationorderSpinner.ValueChangedFcn = createCallbackFcn(app, @IntegrationorderSpinnerValueChanged, true);
            app.IntegrationorderSpinner.Enable = 'off';
            app.IntegrationorderSpinner.Visible = 'off';
            app.IntegrationorderSpinner.Value = 1;
            app.IntegrationorderforreferencetetrahedronLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.IntegrationorderforreferencetetrahedronLabel.Visible = 'off';
            app.IntegrationorderforreferencetetrahedronLabel.Text = 'Integration order for reference tetrahedron';
            app.IntegrationquadratureDropDown = uidropdown(app.ChoiceofelementButtonGroup);
            app.IntegrationquadratureDropDown.Items = {'Gauss Legendre', 'Gauss  Lobatto'};
            app.IntegrationquadratureDropDown.Value = 'Gauss Legendre';
            app.IntegrationquadratureLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.IntegrationquadratureLabel.Text = 'Integration quadrature';
            app.GenerateshapefunctionsButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.GenerateshapefunctionsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateshapefunctionsButtonPushed, true);
            app.GenerateshapefunctionsButton.Text = 'Generate shape functions!';
            app.DonotguessCheckBox = uicheckbox(app.ChoiceofelementButtonGroup);
            app.DonotguessCheckBox.ValueChangedFcn = createCallbackFcn(app, @DonotguessCheckBoxValueChanged, true);
            app.DonotguessCheckBox.Text = 'Do not guess';
            app.CheckcompatibilityButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.CheckcompatibilityButton.ButtonPushedFcn = createCallbackFcn(app, @CheckcompatibilityButtonPushed, true);
            app.CheckcompatibilityButton.Visible = 'off';
            app.CheckcompatibilityButton.Text = 'Check compatibility ';
            app.TextArea = uitextarea(app.ChoiceofelementButtonGroup);
            app.TextArea.Editable = 'off';
            app.Spinner_2 = uispinner(app.ChoiceofelementButtonGroup);
            app.Spinner_2.Limits = [1 1000];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Editable = 'off';
            app.Spinner_2.Visible = 'off';
            app.Spinner_2.Value = 1;
            app.PreviouselementLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.PreviouselementLabel.Visible = 'off';
            app.PreviouselementLabel.Text = 'Previous element';
            app.NewelementLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.NewelementLabel.Visible = 'off';
            app.NewelementLabel.Text = 'New element';
            app.CalculatederivetivesCheckBox = uicheckbox(app.ChoiceofelementButtonGroup);
            app.CalculatederivetivesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatederivetivesCheckBoxValueChanged, true);
            app.CalculatederivetivesCheckBox.Text = 'Calculate derivetives';
            app.ImportelementDropDown = uidropdown(app.ChoiceofelementButtonGroup);
            app.ImportelementDropDown.Items = {'Previous Element', '  From Workspace'};
            app.ImportelementDropDown.ValueChangedFcn = createCallbackFcn(app, @ImportelementDropDownValueChanged, true);
            app.ImportelementDropDown.Visible = 'off';
            app.ImportelementDropDown.Value = 'Previous Element';
            app.DeleteButton = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed2, true);
            app.DeleteButton.Visible = 'off';
            app.DeleteButton.Text = 'Delete';
            app.DeleteButton_2 = uibutton(app.ChoiceofelementButtonGroup, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Visible = 'off';
            app.DeleteButton_2.Text = 'Delete';
            app.ToleranceEditField = uieditfield(app.ChoiceofelementButtonGroup, 'numeric');
            app.ToleranceEditField.Limits = [0 0.001];
            app.ToleranceEditField.Visible = 'off';
            app.ToleranceEditField.Value = 0.0001;
            app.ToleranceLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.ToleranceLabel.Visible = 'off';
            app.ToleranceLabel.Text = 'Tolerance';
            app.SurfacenoofpreviouselementSpinnerLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.SurfacenoofpreviouselementSpinnerLabel.Visible = 'off';
            app.SurfacenoofpreviouselementSpinnerLabel.Text = 'Surface no of previous element';
            app.SurfacenoofpreviouselementSpinner = uispinner(app.ChoiceofelementButtonGroup);
            app.SurfacenoofpreviouselementSpinner.Limits = [1 6];
            app.SurfacenoofpreviouselementSpinner.ValueChangedFcn = createCallbackFcn(app, @SurfacenoofpreviouselementSpinnerValueChanged, true);
            app.SurfacenoofpreviouselementSpinner.Visible = 'off';
            app.SurfacenoofpreviouselementSpinner.Value = 1;
            app.SurfacenoofnewelementSpinnerLabel = uilabel(app.ChoiceofelementButtonGroup);
            app.SurfacenoofnewelementSpinnerLabel.Visible = 'off';
            app.SurfacenoofnewelementSpinnerLabel.Text = 'Surface no of new element';
            app.SurfacenoofnewelementSpinner = uispinner(app.ChoiceofelementButtonGroup);
            app.SurfacenoofnewelementSpinner.Limits = [1 6];
            app.SurfacenoofnewelementSpinner.ValueChangedFcn = createCallbackFcn(app, @SurfacenoofnewelementSpinnerValueChanged, true);
            app.SurfacenoofnewelementSpinner.Visible = 'off';
            app.SurfacenoofnewelementSpinner.Value = 1;
            app.RotateDegButton = uibutton(app.ChoiceofelementButtonGroup, 'state');
            app.RotateDegButton.ValueChangedFcn = createCallbackFcn(app, @RotateDegButtonValueChanged, true);
            app.RotateDegButton.Enable = 'off';
            app.RotateDegButton.Visible = 'off';
            app.RotateDegButton.Text = 'Rotate (Deg)';
            app.ToleranceEditField_2 = uieditfield(app.ChoiceofelementButtonGroup, 'numeric');
            app.ToleranceEditField_2.Visible = 'off';
            app.ToleranceEditField_2.Value = 90;
            app.ShapeGen3DLabel = uilabel(app.UIFigure);
            app.ShapeGen3DLabel.Text = 'ShapeGen3D';
            app.EdgesCheckBox = uicheckbox(app.UIFigure);
            app.EdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EdgesCheckBoxValueChanged, true);
            app.EdgesCheckBox.Enable = 'off';
            app.EdgesCheckBox.Text = 'Edges';
            app.WhatpropertyyouwanttoplotDropDown = uidropdown(app.UIFigure);
            app.WhatpropertyyouwanttoplotDropDown.Items = {'      Shape function, N', '           d^m/dx^m (N)', '           d^m/dy^m (N)', '           d^m/dz^m (N)'};
            app.WhatpropertyyouwanttoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @WhatpropertyyouwanttoplotDropDownValueChanged, true);
            app.WhatpropertyyouwanttoplotDropDown.Enable = 'off';
            app.WhatpropertyyouwanttoplotDropDown.Value = '      Shape function, N';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Capture.PNG';
            app.NodeNumbersCheckBox = uicheckbox(app.UIFigure);
            app.NodeNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @NodeNumbersCheckBoxValueChanged, true);
            app.NodeNumbersCheckBox.Enable = 'off';
            app.NodeNumbersCheckBox.Text = 'Node Numbers';
            app.NodesCheckBox = uicheckbox(app.UIFigure);
            app.NodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NodesCheckBoxValueChanged, true);
            app.NodesCheckBox.Enable = 'off';
            app.NodesCheckBox.Text = 'Nodes';
            app.NodesCheckBox.Value = true;
            app.IntegrationpointsCheckBox = uicheckbox(app.UIFigure);
            app.IntegrationpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegrationpointsCheckBoxValueChanged, true);
            app.IntegrationpointsCheckBox.Enable = 'off';
            app.IntegrationpointsCheckBox.Text = 'Integration points';
            app.WeightsCheckBox = uicheckbox(app.UIFigure);
            app.WeightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @WeightsCheckBoxValueChanged, true);
            app.WeightsCheckBox.Enable = 'off';
            app.WeightsCheckBox.Text = 'Weights';
            app.GetfigureButton = uibutton(app.UIFigure, 'state');
            app.GetfigureButton.ValueChangedFcn = createCallbackFcn(app, @GetfigureButtonValueChanged, true);
            app.GetfigureButton.Enable = 'off';
            app.GetfigureButton.Text = 'Get figure';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 1000];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 1;
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.FundedbytheNationalAeronauticsandSpaceAdministrationLabel = uilabel(app.UIFigure);
            app.FundedbytheNationalAeronauticsandSpaceAdministrationLabel.Text = 'Funded by the National Aeronautics and Space Administration';
            app.Panel = uipanel(app.UIFigure);
            app.SavedataButton = uibutton(app.Panel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Text = 'Save data';
            app.ShapeMatrixCheckBox = uicheckbox(app.Panel);
            app.ShapeMatrixCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShapeMatrixCheckBoxValueChanged, true);
            app.ShapeMatrixCheckBox.Enable = 'off';
            app.ShapeMatrixCheckBox.Text = 'Shape Matrix';
            app.DerivativesofShapematrixCheckBox = uicheckbox(app.Panel);
            app.DerivativesofShapematrixCheckBox.Enable = 'off';
            app.DerivativesofShapematrixCheckBox.Text = 'Derivatives of Shape matrix';
            app.BuildFunctionFileCheckBox = uicheckbox(app.Panel);
            app.BuildFunctionFileCheckBox.Enable = 'off';
            app.BuildFunctionFileCheckBox.Text = 'Build Function File';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 45;
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 45;
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 'datacursormode(app.UIFigure, ''on'')';
            app.CommandWindowLabel = uilabel(app.UIFigure);
            app.CommandWindowLabel.Text = 'Command Window';
            app.AxisCheckBox = uicheckbox(app.UIFigure);
            app.AxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxisCheckBoxValueChanged2, true);
            app.AxisCheckBox.Text = 'Axis';
            app.AxisCheckBox.Value = true;
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Visible = 'off';
            app.PeelsSpinnerLabel = uilabel(app.UIFigure);
            app.PeelsSpinnerLabel.Text = 'Peels';
            app.PeelsSpinner = uispinner(app.UIFigure);
            app.PeelsSpinner.Limits = [2 100];
            app.PeelsSpinner.RoundFractionalValues = 'on';
            app.PeelsSpinner.ValueChangedFcn = createCallbackFcn(app, @PeelsSpinnerValueChanged, true);
            app.PeelsSpinner.Enable = 'off';
            app.PeelsSpinner.Value = 15;
            app.FillSpinnerLabel = uilabel(app.UIFigure);
            app.FillSpinnerLabel.Text = 'Fill';
            app.FillSpinner = uispinner(app.UIFigure);
            app.FillSpinner.Step = 0.05;
            app.FillSpinner.Limits = [0 1];
            app.FillSpinner.ValueChangedFcn = createCallbackFcn(app, @FillSpinnerValueChanged, true);
            app.FillSpinner.Enable = 'off';
            app.FillSpinner.Value = 0.2;
            app.FontsizeSpinnerLabel = uilabel(app.UIFigure);
            app.FontsizeSpinnerLabel.Text = 'Fontsize';
            app.FontsizeSpinner = uispinner(app.UIFigure);
            app.FontsizeSpinner.Limits = [5 30];
            app.FontsizeSpinner.RoundFractionalValues = 'on';
            app.FontsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontsizeSpinnerValueChanged, true);
            app.FontsizeSpinner.Enable = 'off';
            app.FontsizeSpinner.Value = 12;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image2.ImageSource = 'Awakened.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Enable = 'off';
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = '82177813_3242649645752238_4587531779484680192_n.jpg';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {' Cmax', 'Ci=Cj'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Ci=Cj';
            app.SurfaceIntegrationpointsCheckBox = uicheckbox(app.UIFigure);
            app.SurfaceIntegrationpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SurfaceIntegrationpointsCheckBoxValueChanged, true);
            app.SurfaceIntegrationpointsCheckBox.Enable = 'off';
            app.SurfaceIntegrationpointsCheckBox.Text = 'Surface Integration points';
            app.WeightsCheckBox_2 = uicheckbox(app.UIFigure);
            app.WeightsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @WeightsCheckBox_2ValueChanged, true);
            app.WeightsCheckBox_2.Enable = 'off';
            app.WeightsCheckBox_2.Text = 'Weights';
            app.ParametriccoordinatesCheckBox = uicheckbox(app.UIFigure);
            app.ParametriccoordinatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParametriccoordinatesCheckBoxValueChanged, true);
            app.ParametriccoordinatesCheckBox.Enable = 'off';
            app.ParametriccoordinatesCheckBox.Text = 'Parametric coordinates';
            app.ParametricaxiscolorDropDownLabel = uilabel(app.UIFigure);
            app.ParametricaxiscolorDropDownLabel.Text = 'Parametric axis color';
            app.ParametricaxiscolorDropDown = uidropdown(app.UIFigure);
            app.ParametricaxiscolorDropDown.Items = {'  red', ' blue', 'green', 'black'};
            app.ParametricaxiscolorDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametricaxiscolorDropDownValueChanged, true);
            app.ParametricaxiscolorDropDown.Enable = 'off';
            app.ParametricaxiscolorDropDown.Value = '  red';
            app.yDropDown = uidropdown(app.UIFigure);
            app.yDropDown.Items = {'  red', ' blue', 'green', 'black'};
            app.yDropDown.ValueChangedFcn = createCallbackFcn(app, @yDropDownValueChanged, true);
            app.yDropDown.Enable = 'off';
            app.yDropDown.Value = ' blue';
            app.zDropDown = uidropdown(app.UIFigure);
            app.zDropDown.Items = {'  red', ' blue', 'green', 'black'};
            app.zDropDown.ValueChangedFcn = createCallbackFcn(app, @zDropDownValueChanged, true);
            app.zDropDown.Enable = 'off';
            app.zDropDown.Value = 'green';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.Compatible_Cq = uilabel(app.UIFigure);
            app.Compatible_Cq.Visible = 'off';
            app.Compatible_Cq.Text = '  Compatible';
            app.WhatpropertyyouwanttoplotDropDown_2 = uidropdown(app.UIFigure);
            app.WhatpropertyyouwanttoplotDropDown_2.Items = {'Shape function, N of previous el.'};
            app.WhatpropertyyouwanttoplotDropDown_2.Enable = 'off';
            app.WhatpropertyyouwanttoplotDropDown_2.Visible = 'off';
            app.WhatpropertyyouwanttoplotDropDown_2.Value = 'Shape function, N of previous el.';
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Limits = [1 1000];
            app.Spinner_3.RoundFractionalValues = 'on';
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_3.Enable = 'off';
            app.Spinner_3.Visible = 'off';
            app.Spinner_3.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Element_Formulation_Max13_4_8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end